classdef GenerateAnimate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        DropDown                        matlab.ui.control.DropDown
        LoadparametersButton            matlab.ui.control.Button
        GeneratevideoButton             matlab.ui.control.Button
        SaveparametersButton            matlab.ui.control.Button
        SavepictureButton               matlab.ui.control.Button
        DAnimationFigureButton          matlab.ui.control.Button
        ExperimentaldataPanel           matlab.ui.container.Panel
        CheckBox                        matlab.ui.control.CheckBox
        ExperimentalmarkersLabel        matlab.ui.control.Label
        ListBox_3                       matlab.ui.control.ListBox
        AngleSliderLabel                matlab.ui.control.Label
        AngleSlider                     matlab.ui.control.Slider
        KinematicsPanel                 matlab.ui.container.Panel
        CheckBox_2                      matlab.ui.control.CheckBox
        OsteoarticularsegmentmodelLabel  matlab.ui.control.Label
        CheckBox_9                      matlab.ui.control.CheckBox
        OsteoarticularbonemodelLabel    matlab.ui.control.Label
        CheckBox_3                      matlab.ui.control.CheckBox
        ModelmarkersLabel               matlab.ui.control.Label
        CheckBox_7                      matlab.ui.control.CheckBox
        CentersofmassLabel              matlab.ui.control.Label
        CheckBox_10                     matlab.ui.control.CheckBox
        GlobalcenterofmassLabel         matlab.ui.control.Label
        CheckBox_12                     matlab.ui.control.CheckBox
        BaseofsupportLabel              matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        CheckBox_14                     matlab.ui.control.CheckBox
        AnatomicallandmarksLabel        matlab.ui.control.Label
        CheckBox_15                     matlab.ui.control.CheckBox
        NamesLabel                      matlab.ui.control.Label
        DynamicsPanel                   matlab.ui.container.Panel
        CheckBox_4                      matlab.ui.control.CheckBox
        ExternalforcesexperimentsLabel  matlab.ui.control.Label
        CheckBox_11                     matlab.ui.control.CheckBox
        ForceplatesLabel                matlab.ui.control.Label
        CheckBox_8                      matlab.ui.control.CheckBox
        ExternalforcespredictionLabel   matlab.ui.control.Label
        CheckBox_13                     matlab.ui.control.CheckBox
        ForcepredictionpointsLabel      matlab.ui.control.Label
        MusclesPanel                    matlab.ui.container.Panel
        CheckBox_5                      matlab.ui.control.CheckBox
        MuscleforcesLabel               matlab.ui.control.Label
        ListBox_2                       matlab.ui.control.ListBox
        Button_2                        matlab.ui.control.StateButton
        Button_3                        matlab.ui.control.StateButton
        Button                          matlab.ui.control.StateButton
        Label                           matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        EditField                       matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
    end


    properties (Access = private)
        Parameters % Description
        AnimateParameters
        num_f
        time
        Anim
        num_ext
        extension
        init
        AnalysisParameters
        Human_model
        OsteoModel
    end

    methods (Access = private)
    
        function ActuatePlotModel(app)
            cla(app.UIAxes);
            app.AnimateParameters.Mode = 'GenerateAnimate';
            app.AnimateParameters.PictureFrame = app.num_f;
            app.AnimateParameters.ax = app.UIAxes;
            PlotAnimation(app.Parameters, app.AnimateParameters)
            if app.init
                xl = xlim(app.UIAxes);
                app.AnimateParameters.xlim = xl;
                yl = ylim(app.UIAxes);
                app.AnimateParameters.ylim = yl;
                zl = zlim(app.UIAxes);
                app.AnimateParameters.zlim = zl;
                app.init = 0;
            end
        end
        
        function ActuateFilename(app)
            app.AnimateParameters.filename = app.DropDown.Value;
            if exist([app.AnimateParameters.filename(1:end-app.num_ext) '/ExperimentalData.mat'],'file')
                load([app.AnimateParameters.filename(1:end-app.num_ext) '/ExperimentalData.mat']);
                app.time = ExperimentalData.Time;
                app.Slider.Limits = [0,app.time(end)];
                app.EditField.Limits = [0,app.time(end)];
            end
        end
        
        function ActuateApp(app)
            app.CheckBox_2.Value = app.AnimateParameters.seg_anim;
            app.CheckBox_5.Value = app.AnimateParameters.muscles_anim;
            app.CheckBox_3.Value = app.AnimateParameters.mod_marker_anim;
            app.CheckBox.Value = app.AnimateParameters.exp_marker_anim;
            app.CheckBox_4.Value = app.AnimateParameters.external_forces_anim;
            app.CheckBox_8.Value = app.AnimateParameters.external_forces_pred;
            app.CheckBox_9.Value = app.AnimateParameters.bone_anim;
            app.CheckBox_7.Value = app.AnimateParameters.mass_centers_anim;
            app.CheckBox_10.Value = app.AnimateParameters.Global_mass_center_anim;
            app.CheckBox_13.Value = app.AnimateParameters.Force_Prediction_points;

            set(app.UIAxes,'view',app.AnimateParameters.view);
            if ~app.init    
                set(app.UIAxes,'xlim',app.AnimateParameters.xlim);
                set(app.UIAxes,'ylim',app.AnimateParameters.ylim);
                set(app.UIAxes,'zlim',app.AnimateParameters.zlim);
            end
            
            
        end
        
        function ActuateParameters(app)
            app.AnimateParameters.view = get(app.UIAxes,'view');
            app.AnimateParameters.xlim = get(app.UIAxes,'xlim');
            app.AnimateParameters.ylim = get(app.UIAxes,'ylim');
            app.AnimateParameters.zlim = get(app.UIAxes,'zlim');
            app.AnimateParameters.Muscles=eval(['app.ListBox_2.Value']);
            temp=eval(['app.DropDown.Value']);
            if strcmp(temp,'No c3d')
                app.AnimateParameters.Noc3d=1;
            else
                app.AnimateParameters.Noc3d=0;
            end
            % Actualiser le fichier AnimateParameters à partir de l'application            
        end        
    
        function ActuateEnableButton(app)
            % XSens
            if isequal(app.extension,'*.mvnx')
                app.CheckBox.Enable = false;
                app.CheckBox.Value = false;
%                 app.CheckBox_9.Enable = false;
%                 app.CheckBox_9.Value = false;
                app.CheckBox_3.Enable = false;
                app.CheckBox_3.Value = false;
                app.CheckBox_5.Enable = false;
                app.CheckBox_5.Value = false;
            else
                app.CheckBox.Enable = true;
%                 app.CheckBox_9.Enable = true;
                app.CheckBox_3.Enable = true;
                app.CheckBox_5.Enable = true;
            end
            % Muscles
            if ~exist([app.AnimateParameters.filename(1:end-app.num_ext) '/MuscleForcesComputationResults.mat'], 'file')
                app.CheckBox_5.Enable = false;
                app.CheckBox_5.Value = false;
            else
                app.CheckBox_5.Enable = true;               
            end
            if exist([app.AnimateParameters.filename(1:end-app.num_ext) '/ExternalForcesComputationResults.mat'], 'file')
                load([app.AnimateParameters.filename(1:end-app.num_ext) '/ExternalForcesComputationResults.mat']);
                if ~isfield(ExternalForcesComputationResults, 'ExternalForcesExperiments')
                    app.CheckBox_4.Enable = false;
                    app.CheckBox_4.Value = false;
                else
                    app.CheckBox_4.Enable = true;
                end
                if ~isfield(ExternalForcesComputationResults, 'ExternalForcesPrediction')
                    app.CheckBox_8.Enable = false;
                    app.CheckBox_8.Value = false;
                    app.CheckBox_13.Enable = false;
                    app.CheckBox_13.Value = false;
                else
                    app.CheckBox_8.Enable = true;
                end
            else
                app.CheckBox_4.Enable = false;
                app.CheckBox_4.Value = false;
                app.CheckBox_8.Enable = false;
                app.CheckBox_8.Value = false;
            end
            % Force plates
            if ~(isequal(app.AnalysisParameters.ExternalForces.Method, @DataInC3D) || ...
                    isequal(app.AnalysisParameters.ExternalForces.Method, @PF_IRSST)) ...
                    || app.AnalysisParameters.ID.InputData ~= 1
                app.CheckBox_11.Enable = false;
                app.CheckBox_11.Value = false;
            else
                app.CheckBox_11.Enable = true;                
            end
            % Base of support
            if exist([app.AnimateParameters.filename(1:end-app.num_ext) '/InverseKinematicsResults.mat'], 'file')
                load([app.AnimateParameters.filename(1:end-app.num_ext) '/InverseKinematicsResults.mat']);
                if ~isfield(InverseKinematicsResults, 'BoS')
                    app.CheckBox_12.Enable = false;
                    app.CheckBox_12.Value = false;                
                else
                    app.CheckBox_12.Enable = true;
            end            
            end
            
            
            % No c3d
            temp=eval(['app.DropDown.Value']);
            if strcmp(temp,'No c3d')
                eval(['app.CheckBox.Visible = ''off'';']);
                eval(['app.ExperimentalmarkersLabel.Visible = ''off'';']);
                eval(['app.DAnimationFigureButton.Visible=''off'';']);
                
                eval(['app.ListBox_3.Visible = ''on'';']);
                eval(['app.AngleSlider.Visible = ''on'';']);
                eval(['app.AngleSliderLabel.Visible = ''on'';']);
                app.AnimateParameters.Noc3d=1;

            else
                eval(['app.CheckBox.Visible = ''on'';']);
                eval(['app.ExperimentalmarkersLabel.Visible = ''on'';']);
                eval(['app.DAnimationFigureButton.Visible=''on'';']);

                eval(['app.ListBox_3.Visible = ''off'';']);
                eval(['app.AngleSlider.Visible = ''off'';']);
                eval(['app.AngleSliderLabel.Visible = ''off'';']);

                app.AnimateParameters.Noc3d=0;
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            hold(app.UIAxes,'on');
            axis(app.UIAxes,'equal');
            % Afficher la liste des c3d
            load('AnalysisParameters.mat');
            app.AnalysisParameters = AnalysisParameters;
            list_files_c3d=dir(app.AnalysisParameters.General.Extension);
            app.DropDown.Items = {'No c3d',list_files_c3d.name};
            load('ModelParameters.mat'); app.Parameters = ModelParameters;
            app.extension = app.AnalysisParameters.General.Extension;
            app.num_ext = numel(app.extension)-1;
            app.ActuateFilename;
            load('BiomechanicalModel.mat');
            app.OsteoModel=BiomechanicalModel.OsteoArticularModel;
            cpt=1;
            for k=find([BiomechanicalModel.OsteoArticularModel.Visual])
                app.Human_model(cpt).name = BiomechanicalModel.OsteoArticularModel(k).name;
                cpt=cpt+1;
            end
            name_sol_mvt={};
            for k=1:size(BiomechanicalModel.OsteoArticularModel,2)
                if ~isinf(BiomechanicalModel.OsteoArticularModel(k).limit_sup)
                    name_sol_mvt = [name_sol_mvt , {BiomechanicalModel.OsteoArticularModel(k).name}];
                end
            end

            % Valeurs initiales
            app.AnimateParameters.seg_anim = 0;
            app.AnimateParameters.muscles_anim = 0;
            app.AnimateParameters.mod_marker_anim = 0;
            app.AnimateParameters.exp_marker_anim = 0;
            app.AnimateParameters.external_forces_anim = 0;
            app.AnimateParameters.external_forces_pred = 0;
            app.AnimateParameters.bone_anim = 0;
            app.AnimateParameters.mass_centers_anim = 0;
            app.AnimateParameters.Global_mass_center_anim = 0;
            app.AnimateParameters.forceplate = 0;
            app.AnimateParameters.BoS = 0;
            app.AnimateParameters.Force_Prediction_points = 0;
            app.AnimateParameters.view = [0 1];
            app.AnimateParameters.Segment = [];
            app.AnimateParameters.AnatLandmark = 0;
            app.AnimateParameters.AnatLandmark_names = 0;
            app.AnimateParameters.Muscles = [];
            app.AnimateParameters.Noc3d=1;
            app.AnimateParameters.angle=[];
            app.AnimateParameters.sol_anim=[];
            app.init = 1;
%             app.AnimateParameters.xlim = [-0.55 0.55];
%             app.AnimateParameters.ylim = [-1.2 0.9];
%             app.AnimateParameters.zlim = [0 2.3];
            app.ActuateApp;
            % Frame que l'on regarde au début
            app.EditField.Value = 0;
            app.Slider.Value = 0;
            app.num_f = 1;
            % Locked buttons
            app.ActuateEnableButton;
            % Tracé
            app.Anim.ModelChoice = 0;
            app.ActuatePlotModel;
            eval(['app.CheckBox_15.Visible=''off'';']);
            eval(['app.NamesLabel.Visible = ''off'';']);
            eval(['app.ListBox.Visible = ''off'';']);
            eval(['app.ListBox.Items={app.Human_model.name};']);
            eval(['app.ListBox.Value={app.Human_model.name};']);
            
            if isfield(BiomechanicalModel,'Muscles') && ~isempty(BiomechanicalModel.Muscles)
                eval(['app.ListBox_2.Visible = ''on'';']);
                eval(['app.ListBox_2.Items={BiomechanicalModel.Muscles.name};']);
                eval(['app.ListBox_2.Value={};']);
            else
                eval(['app.ListBox_2.Visible = ''off'';']);
                eval(['app.ListBox_2.Value={};']);
            end


            eval(['app.ListBox_3.Visible = ''on'';']);
            eval(['app.ListBox_3.Items=name_sol_mvt;']);
            eval(['app.ListBox_3.Value={};']);
            
            eval(['app.AngleSlider.Visible = ''on'';']);
            eval(['app.AngleSliderLabel.Visible = ''on'';']);
            eval(['app.AngleSlider.Limits=[-180 180];']);

        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            if app.Button.Value
                rotate3d(app.UIAxes,'on');
                app.Button_2.Value = false;
                app.Button_2ValueChanged;
                app.Button_3.Value = false;
                app.Button_3ValueChanged;
            else
                rotate3d(app.UIAxes,'off');
            end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            if app.Button_2.Value
                zoom(app.UIAxes,'on');
                app.Button.Value = false;
                app.ButtonValueChanged;
                app.Button_3.Value = false;
                app.Button_3ValueChanged;
            else
                zoom(app.UIAxes,'off');
            end
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            if app.Button_3.Value
                pan(app.UIAxes,'on');
                app.Button.Value = false;
                app.ButtonValueChanged;
                app.Button_2.Value = false;
                app.Button_2ValueChanged;
            else
                pan(app.UIAxes,'off');
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.ActuateParameters;
            app.ActuateFilename;
            app.ActuateEnableButton; % Locked buttons
            app.ActuatePlotModel;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.EditField.Value = round(app.EditField.Value*(1/app.time(2)))/(1/app.time(2));
            app.num_f = round(1+app.EditField.Value*(1/app.time(2)));
            app.Slider.Value = app.EditField.Value;
            app.ActuatePlotModel;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Value = round(app.Slider.Value*(1/app.time(2)))/(1/app.time(2));
            app.num_f = round(1 + app.Slider.Value*(1/app.time(2)));
            app.EditField.Value = app.Slider.Value;
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            if app.CheckBox_2.Value
                app.AnimateParameters.seg_anim = 1;
            else
                app.AnimateParameters.seg_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            if app.CheckBox_5.Value
                app.AnimateParameters.muscles_anim = 1;
            else
                app.AnimateParameters.muscles_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            if app.CheckBox_3.Value
                app.AnimateParameters.mod_marker_anim = 1;
            else
                app.AnimateParameters.mod_marker_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Button pushed function: GeneratevideoButton
        function GeneratevideoButtonPushed(app, event)
            app.ActuateParameters;
            AnimateParameters = app.AnimateParameters;
            save('AnimateParameters','AnimateParameters');
            app.AnimateParameters.Mode = 'Figure';
            PlotAnimation(app.Parameters, app.AnimateParameters);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if app.CheckBox.Value
                app.AnimateParameters.exp_marker_anim = 1;
            else
                app.AnimateParameters.exp_marker_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            if app.CheckBox_4.Value
                app.AnimateParameters.external_forces_anim = 1;
            else
                app.AnimateParameters.external_forces_anim = 0;
            end
            app.ActuatePlotModel;            
        end

        % Button pushed function: LoadparametersButton
        function LoadparametersButtonPushed(app, event)
            [FileNameParameters,PathNameParameters] = uigetfile('AnalysisParameters.mat','Select a Parameters file');
            OldAnimateParameters = load([PathNameParameters FileNameParameters]);
            OldAnimateParameters = OldAnimateParameters.AnimateParameters;
            % Sauvegarde
            app.AnimateParameters = OldAnimateParameters;
            app.ActuateFilename;
            app.ActuateApp;
            app.ActuatePlotModel;            
        end

        % Button pushed function: SaveparametersButton
        function SaveparametersButtonPushed(app, event)
            app.ActuateParameters;
            AnimateParameters = app.AnimateParameters;
            save('AnimateParameters','AnimateParameters');
        end

        % Button pushed function: SavepictureButton
        function SavepictureButtonPushed(app, event)
            app.ActuateParameters;
            AnimateParameters = app.AnimateParameters;
            save('AnimateParameters','AnimateParameters');
            app.AnimateParameters.Mode = 'Picture';
            app.AnimateParameters.PictureFrame = app.num_f;
            PlotAnimation(app.Parameters, app.AnimateParameters);
        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            if app.CheckBox_8.Value
                app.AnimateParameters.external_forces_pred = 1;
            else
                app.AnimateParameters.external_forces_pred = 0;
            end
            app.ActuatePlotModel;
        end

        % Button pushed function: DAnimationFigureButton
        function DAnimationFigureButtonPushed(app, event)
            app.ActuateParameters;
            AnimateParameters = app.AnimateParameters;
            save('AnimateParameters','AnimateParameters');
            app.AnimateParameters.Mode = 'cFigure';
            PlotAnimation(app.Parameters, app.AnimateParameters);
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            if app.CheckBox_7.Value
                app.AnimateParameters.mass_centers_anim = 1;
            else
                app.AnimateParameters.mass_centers_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            if app.CheckBox_10.Value
                app.AnimateParameters.Global_mass_center_anim = 1;
            else
                app.AnimateParameters.Global_mass_center_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            if app.CheckBox_9.Value
                app.AnimateParameters.bone_anim = 1;
            else
                app.AnimateParameters.bone_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_11
        function CheckBox_11ValueChanged(app, event)
            if app.CheckBox_11.Value
                app.AnimateParameters.forceplate = 1;
            else
                app.AnimateParameters.forceplate = 0;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_12
        function CheckBox_12ValueChanged(app, event)
            if app.CheckBox_12.Value
                app.AnimateParameters.BoS = 1;
            else
                app.AnimateParameters.BoS = 0;
            end
            app.ActuatePlotModel;            
        end

        % Value changed function: CheckBox_13
        function CheckBox_13ValueChanged(app, event)
            if app.CheckBox_13.Value
                app.AnimateParameters.Force_Prediction_points = 1;
            else
                app.AnimateParameters.Force_Prediction_points = 0;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_14
        function CheckBox_14ValueChanged(app, event)
            if app.CheckBox_14.Value
                app.AnimateParameters.AnatLandmark = 1;
                
                eval(['app.ListBox.Visible = ''on'';']);
                eval(['app.CheckBox_15.Visible = ''on'';']);
                eval(['app.NamesLabel.Visible = ''on'';']);
                app.AnimateParameters.Segment=eval(['app.ListBox.Value']);
            else
                app.AnimateParameters.AnatLandmark = 0;
                eval(['app.ListBox.Value={app.Human_model.name};']);
                eval(['app.ListBox.Visible = ''off'';']);
                eval(['app.NamesLabel.Visible = ''off'';']);
                eval(['app.CheckBox_15.Visible = ''off'';']);

            end
            app.ActuatePlotModel;

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.AnimateParameters.Segment=eval(['app.ListBox.Value']);
            app.ActuatePlotModel;
            
        end

        % Value changed function: CheckBox_15
        function CheckBox_15ValueChanged(app, event)
            if app.CheckBox_15.Value
               app.AnimateParameters.AnatLandmark_names = 1;
            else
               app.AnimateParameters.AnatLandmark_names = 0;
            end
            app.ActuatePlotModel;

        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            app.AnimateParameters.Muscles=eval(['app.ListBox_2.Value']);
            app.ActuatePlotModel;
            
        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            [~, indsol]=intersect({app.OsteoModel.name},eval(['app.ListBox_3.Value']));
                             
            uppbound=app.OsteoModel(indsol).limit_sup*180/pi;
            lowbound=app.OsteoModel(indsol).limit_inf*180/pi;
            
            
            eval(['app.AngleSlider.Limits=[lowbound uppbound];']);
            [t,ind]=intersect(app.AnimateParameters.sol_anim,indsol);
            if ~t
                app.AnimateParameters.sol_anim=[app.AnimateParameters.sol_anim,indsol];
                app.AnimateParameters.angle=[app.AnimateParameters.angle,eval(['app.AngleSlider.Value'])];
            else
                app.AnimateParameters.angle(ind)=eval(['app.AngleSlider.Value']);
            end

            app.ActuatePlotModel;

                       
        end

        % Value changing function: AngleSlider
        function AngleSliderValueChanging(app, event)
            changingValue = event.Value;
            [~, indsol]=intersect({app.OsteoModel.name},eval(['app.ListBox_3.Value']));
            [t,ind]=intersect(app.AnimateParameters.sol_anim,indsol);
            if t
                app.AnimateParameters.angle(ind)=changingValue;
            else
                app.AnimateParameters.sol_anim=[app.AnimateParameters.sol_anim,indsol];
                app.AnimateParameters.angle=[app.AnimateParameters.angle,changingValue];
            end

            app.ActuatePlotModel;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.Panel = uipanel(app.UIFigure);
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.LoadparametersButton = uibutton(app.Panel, 'push');
            app.LoadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparametersButtonPushed, true);
            app.LoadparametersButton.Text = 'Load parameters';
            app.GeneratevideoButton = uibutton(app.Panel, 'push');
            app.GeneratevideoButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratevideoButtonPushed, true);
            app.GeneratevideoButton.Text = 'Generate video';
            app.SaveparametersButton = uibutton(app.Panel, 'push');
            app.SaveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparametersButtonPushed, true);
            app.SaveparametersButton.Text = 'Save parameters';
            app.SavepictureButton = uibutton(app.Panel, 'push');
            app.SavepictureButton.ButtonPushedFcn = createCallbackFcn(app, @SavepictureButtonPushed, true);
            app.SavepictureButton.Text = 'Save picture';
            app.DAnimationFigureButton = uibutton(app.Panel, 'push');
            app.DAnimationFigureButton.ButtonPushedFcn = createCallbackFcn(app, @DAnimationFigureButtonPushed, true);
            app.DAnimationFigureButton.Text = '3D Animation Figure';
            app.ExperimentaldataPanel = uipanel(app.Panel);
            app.ExperimentaldataPanel.Title = ' Experimental data';
            app.CheckBox = uicheckbox(app.ExperimentaldataPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.ExperimentalmarkersLabel = uilabel(app.ExperimentaldataPanel);
            app.ExperimentalmarkersLabel.Text = 'Experimental markers';
            app.ListBox_3 = uilistbox(app.ExperimentaldataPanel);
            app.ListBox_3.Items = {'Point 1', 'Point 2'};
            app.ListBox_3.Multiselect = 'on';
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.Value = {'Point 1'};
            app.AngleSliderLabel = uilabel(app.ExperimentaldataPanel);
            app.AngleSliderLabel.Text = 'Angle';
            app.AngleSlider = uislider(app.ExperimentaldataPanel);
            app.AngleSlider.ValueChangingFcn = createCallbackFcn(app, @AngleSliderValueChanging, true);
            app.KinematicsPanel = uipanel(app.Panel);
            app.KinematicsPanel.Title = ' Kinematics';
            app.CheckBox_2 = uicheckbox(app.KinematicsPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.OsteoarticularsegmentmodelLabel = uilabel(app.KinematicsPanel);
            app.OsteoarticularsegmentmodelLabel.Text = 'Osteo-articular segment model';
            app.CheckBox_9 = uicheckbox(app.KinematicsPanel);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '';
            app.OsteoarticularbonemodelLabel = uilabel(app.KinematicsPanel);
            app.OsteoarticularbonemodelLabel.Text = 'Osteo-articular bone model';
            app.CheckBox_3 = uicheckbox(app.KinematicsPanel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.ModelmarkersLabel = uilabel(app.KinematicsPanel);
            app.ModelmarkersLabel.Text = 'Model markers';
            app.CheckBox_7 = uicheckbox(app.KinematicsPanel);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '';
            app.CentersofmassLabel = uilabel(app.KinematicsPanel);
            app.CentersofmassLabel.Text = 'Centers of mass';
            app.CheckBox_10 = uicheckbox(app.KinematicsPanel);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '';
            app.GlobalcenterofmassLabel = uilabel(app.KinematicsPanel);
            app.GlobalcenterofmassLabel.Text = 'Global center of mass';
            app.CheckBox_12 = uicheckbox(app.KinematicsPanel);
            app.CheckBox_12.ValueChangedFcn = createCallbackFcn(app, @CheckBox_12ValueChanged, true);
            app.CheckBox_12.Text = '';
            app.BaseofsupportLabel = uilabel(app.KinematicsPanel);
            app.BaseofsupportLabel.Text = 'Base of support';
            app.ListBox = uilistbox(app.KinematicsPanel);
            app.ListBox.Items = {'Point 1', 'Point 2'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Point 1'};
            app.CheckBox_14 = uicheckbox(app.KinematicsPanel);
            app.CheckBox_14.ValueChangedFcn = createCallbackFcn(app, @CheckBox_14ValueChanged, true);
            app.CheckBox_14.Text = '';
            app.AnatomicallandmarksLabel = uilabel(app.KinematicsPanel);
            app.AnatomicallandmarksLabel.Text = 'Anatomical landmarks';
            app.CheckBox_15 = uicheckbox(app.KinematicsPanel);
            app.CheckBox_15.ValueChangedFcn = createCallbackFcn(app, @CheckBox_15ValueChanged, true);
            app.CheckBox_15.Text = '';
            app.NamesLabel = uilabel(app.KinematicsPanel);
            app.NamesLabel.Text = 'Names';
            app.DynamicsPanel = uipanel(app.Panel);
            app.DynamicsPanel.Title = ' Dynamics';
            app.CheckBox_4 = uicheckbox(app.DynamicsPanel);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.ExternalforcesexperimentsLabel = uilabel(app.DynamicsPanel);
            app.ExternalforcesexperimentsLabel.Text = 'External forces (experiments)';
            app.CheckBox_11 = uicheckbox(app.DynamicsPanel);
            app.CheckBox_11.ValueChangedFcn = createCallbackFcn(app, @CheckBox_11ValueChanged, true);
            app.CheckBox_11.Text = '';
            app.ForceplatesLabel = uilabel(app.DynamicsPanel);
            app.ForceplatesLabel.Text = 'Force plates';
            app.CheckBox_8 = uicheckbox(app.DynamicsPanel);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '';
            app.ExternalforcespredictionLabel = uilabel(app.DynamicsPanel);
            app.ExternalforcespredictionLabel.Text = 'External forces (prediction)';
            app.CheckBox_13 = uicheckbox(app.DynamicsPanel);
            app.CheckBox_13.ValueChangedFcn = createCallbackFcn(app, @CheckBox_13ValueChanged, true);
            app.CheckBox_13.Text = '';
            app.ForcepredictionpointsLabel = uilabel(app.DynamicsPanel);
            app.ForcepredictionpointsLabel.Text = 'Force prediction points';
            app.MusclesPanel = uipanel(app.Panel);
            app.MusclesPanel.Title = ' Muscles';
            app.CheckBox_5 = uicheckbox(app.MusclesPanel);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            app.MuscleforcesLabel = uilabel(app.MusclesPanel);
            app.MuscleforcesLabel.Text = 'Muscle forces';
            app.ListBox_2 = uilistbox(app.Panel);
            app.ListBox_2.Items = {'Point 1', 'Point 2'};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = {'Point 1'};
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Icon = 'Zoom.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Icon = 'Pan.png';
            app.Button_3.Text = '';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'Rotate.png';
            app.Button.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GenerateAnimate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end