classdef GenerateParameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GenerateparametersButton       matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        LoadparametersButton_2         matlab.ui.control.Button
        MassEditField                  matlab.ui.control.NumericEditField
        MasskgEditFieldLabel           matlab.ui.control.Label
        SizeEditField                  matlab.ui.control.NumericEditField
        SizemEditFieldLabel            matlab.ui.control.Label
        OsteoarticularTab              matlab.ui.container.Tab
        RootDropDown                   matlab.ui.control.DropDown
        RootDropDownLabel              matlab.ui.control.Label
        LeftarmPanel                   matlab.ui.container.Panel
        LeftArm                        matlab.ui.control.DropDown
        DropDown_4Label                matlab.ui.control.Label
        RightarmPanel                  matlab.ui.container.Panel
        RightArm                       matlab.ui.control.DropDown
        Label_3                        matlab.ui.control.Label
        LeftlegPanel                   matlab.ui.container.Panel
        LeftLeg                        matlab.ui.control.DropDown
        Label_2                        matlab.ui.control.Label
        RightlegPanel                  matlab.ui.container.Panel
        RightLeg                       matlab.ui.control.DropDown
        Label_5                        matlab.ui.control.Label
        TrunkPanel                     matlab.ui.container.Panel
        Head                           matlab.ui.control.DropDown
        HeadDropDownLabel              matlab.ui.control.Label
        Uppertrunk                     matlab.ui.control.DropDown
        UppertrunkDropDownLabel        matlab.ui.control.Label
        PelvisLowertrunk               matlab.ui.control.DropDown
        PelvisLowertrunkDropDownLabel  matlab.ui.control.Label
        MarkersTab                     matlab.ui.container.Tab
        ScapulaSide                    matlab.ui.control.DropDown
        HeightcmEditField              matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel         matlab.ui.control.Label
        ScapulalocatorCheckBox         matlab.ui.control.CheckBox
        MarkersonhandsDropDown         matlab.ui.control.DropDown
        MarkersonhandsDropDownLabel    matlab.ui.control.Label
        MarkersList                    matlab.ui.container.Panel
        Button56                       matlab.ui.control.StateButton
        Button55                       matlab.ui.control.StateButton
        Button54                       matlab.ui.control.StateButton
        Button53                       matlab.ui.control.StateButton
        Button52                       matlab.ui.control.StateButton
        Button51                       matlab.ui.control.StateButton
        Button50                       matlab.ui.control.StateButton
        Button49                       matlab.ui.control.StateButton
        Button48                       matlab.ui.control.StateButton
        Button47                       matlab.ui.control.StateButton
        Button46                       matlab.ui.control.StateButton
        Button45                       matlab.ui.control.StateButton
        Button44                       matlab.ui.control.StateButton
        Button43                       matlab.ui.control.StateButton
        Button42                       matlab.ui.control.StateButton
        Button41                       matlab.ui.control.StateButton
        Button40                       matlab.ui.control.StateButton
        Button39                       matlab.ui.control.StateButton
        Button38                       matlab.ui.control.StateButton
        Button37                       matlab.ui.control.StateButton
        Button36                       matlab.ui.control.StateButton
        Button35                       matlab.ui.control.StateButton
        Button34                       matlab.ui.control.StateButton
        Button33                       matlab.ui.control.StateButton
        Button32                       matlab.ui.control.StateButton
        Button31                       matlab.ui.control.StateButton
        Button30                       matlab.ui.control.StateButton
        Button29                       matlab.ui.control.StateButton
        Button28                       matlab.ui.control.StateButton
        Button27                       matlab.ui.control.StateButton
        Button26                       matlab.ui.control.StateButton
        Button25                       matlab.ui.control.StateButton
        Button24                       matlab.ui.control.StateButton
        Button23                       matlab.ui.control.StateButton
        Button22                       matlab.ui.control.StateButton
        Button21                       matlab.ui.control.StateButton
        Button20                       matlab.ui.control.StateButton
        Button19                       matlab.ui.control.StateButton
        Button18                       matlab.ui.control.StateButton
        Button17                       matlab.ui.control.StateButton
        Button16                       matlab.ui.control.StateButton
        Button15                       matlab.ui.control.StateButton
        Button14                       matlab.ui.control.StateButton
        Button13                       matlab.ui.control.StateButton
        Button12                       matlab.ui.control.StateButton
        Button11                       matlab.ui.control.StateButton
        Button10                       matlab.ui.control.StateButton
        Button9                        matlab.ui.control.StateButton
        Button8                        matlab.ui.control.StateButton
        Button7                        matlab.ui.control.StateButton
        Button6                        matlab.ui.control.StateButton
        Button5                        matlab.ui.control.StateButton
        Button4                        matlab.ui.control.StateButton
        Button3                        matlab.ui.control.StateButton
        Button2                        matlab.ui.control.StateButton
        Button1                        matlab.ui.control.StateButton
        Markers                        matlab.ui.control.DropDown
        Label_4                        matlab.ui.control.Label
        MusclesTab                     matlab.ui.container.Tab
        DeleteButton                   matlab.ui.control.Button
        AddButton                      matlab.ui.control.Button
        DropDown_20                    matlab.ui.control.DropDown
        SideLabel_10                   matlab.ui.control.Label
        DropDown_19                    matlab.ui.control.DropDown
        DropDown_18                    matlab.ui.control.DropDown
        SideLabel_9                    matlab.ui.control.Label
        DropDown_17                    matlab.ui.control.DropDown
        DropDown_16                    matlab.ui.control.DropDown
        SideLabel_8                    matlab.ui.control.Label
        DropDown_15                    matlab.ui.control.DropDown
        DropDown_14                    matlab.ui.control.DropDown
        SideLabel_7                    matlab.ui.control.Label
        DropDown_13                    matlab.ui.control.DropDown
        DropDown_12                    matlab.ui.control.DropDown
        SideLabel_6                    matlab.ui.control.Label
        DropDown_11                    matlab.ui.control.DropDown
        DropDown_10                    matlab.ui.control.DropDown
        SideLabel_5                    matlab.ui.control.Label
        DropDown_9                     matlab.ui.control.DropDown
        DropDown_8                     matlab.ui.control.DropDown
        SideLabel_4                    matlab.ui.control.Label
        DropDown_7                     matlab.ui.control.DropDown
        DropDown_6                     matlab.ui.control.DropDown
        SideLabel_3                    matlab.ui.control.Label
        DropDown_5                     matlab.ui.control.DropDown
        DropDown_4                     matlab.ui.control.DropDown
        SideLabel_2                    matlab.ui.control.Label
        DropDown_3                     matlab.ui.control.DropDown
        SideLabel_1                    matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        DropDown_1                     matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Parameters % paramètres du modèle
        NewMarkers % set de marqueurs modifié
        Markers0 % set de marqueurs de base
        PlotFigure
        num_m
        Human_model
    end

    methods (Access = private)
    
        function actualizePlotModel(app)
            cla(app.UIAxes);
            AnimateParameters.Mode = 'GenerateParameters';
            AnimateParameters.seg_anim = 1;
            AnimateParameters.muscles_anim = 1;
            AnimateParameters.mod_marker_anim = 1;
            AnimateParameters.PictureFrame = 1;
            AnimateParameters.ax = app.UIAxes; warning off;
            [app.Human_model,app.NewMarkers,EnableModel] = ...
                PlotAnimation(app.Parameters, AnimateParameters); 
            warning on;
            app.ActuateEnableModel(EnableModel);
        end
        
        function actualizeListMarkers(app)
            app.Markers0 = app.NewMarkers;
            for i = 1 : numel(app.Markers0)
                eval(['app.Button' num2str(i) '.Visible = ''on'';'])
                eval(['app.Button' num2str(i) '.Text = app.Markers0(i).name;'])
                if app.Markers0(i).exist
                    eval(['app.Button' num2str(i) '.Enable = ''on'';'])
                    eval(['app.Button' num2str(i) '.Value = true;'])
                else
                    eval(['app.Button' num2str(i) '.Enable = ''off'';'])
                    eval(['app.Button' num2str(i) '.Value = false;'])
                end
            end
            for i = numel(app.Markers0)+1 : 52
                eval(['app.Button' num2str(i) '.Visible = ''off'';'])
            end
        end
        
        function ActuateApp(app,OldParameters)
            %% General
            app.SizeEditField.Value = OldParameters.Size; app.SizeEditFieldValueChanged();
            app.MassEditField.Value = OldParameters.Mass; app.MassEditFieldValueChanged();
            % Osteoarticular
            app.PelvisLowertrunk.Value = func2str(OldParameters.PelvisLowerTrunk); app.PelvisLowertrunkValueChanged();
            app.Uppertrunk.Value = func2str(OldParameters.UpperTrunk); app.UppertrunkValueChanged();
            app.Head.Value = func2str(OldParameters.Head); app.HeadValueChanged();
            app.RightLeg.Value = func2str(OldParameters.RightLeg); app.RightLegValueChanged();
            app.LeftLeg.Value = func2str(OldParameters.LeftLeg); app.LeftLegValueChanged();
            app.RightArm.Value = func2str(OldParameters.RightArm); app.RightArmValueChanged();
            app.LeftArm.Value = func2str(OldParameters.LeftArm); app.LeftArmValueChanged();
            app.RootDropDown.Value = OldParameters.Root;
            % Markers
            app.Markers.Value = func2str(OldParameters.Markers);
            app.MarkersValueChanged();
            [~,I]=intersect({app.Markers0.name}',OldParameters.MarkersRemoved);
            app.NewMarkers(I) = [];
            for i=I'
                eval(['app.Button' num2str(i) '.Value = false;'])                
            end
            app.Button1ValueChanged();
            % Muscles
            app.num_m = numel(OldParameters.Muscles);
            for i=1:app.num_m
                eval(['app.DropDown_' num2str(2*i-1) '.Visible = ''on'';'])
                eval(['app.DropDown_' num2str(2*i) '.Visible = ''on'';'])
                eval(['app.SideLabel_' num2str(i) '.Visible = ''on'';'])
                eval(['app.DropDown_' num2str(2*i-1) '.Value= func2str(OldParameters.Muscles{i});'])
                eval(['app.DropDown_' num2str(2*i) '.Value= OldParameters.MusclesOptions{i};'])
            end
            for i = (app.num_m+1):10
                eval(['app.DropDown_' num2str(2*i-1) '.Visible = ''off'';'])
                eval(['app.DropDown_' num2str(2*i) '.Visible = ''off'';'])
                eval(['app.SideLabel_' num2str(i) '.Visible = ''off'';'])
            end
            app.DropDown_1ValueChanged;
    
            
            if isfield(OldParameters,"Scapulalocator")
                app.Parameters.Scapulalocator.active = OldParameters.Scapulalocator.active;
                app.Parameters.Scapulalocator.height = OldParameters.Scapulalocator.height;
                app.Parameters.Scapulalocator.side = OldParameters.Scapulalocator.side;
                if app.Parameters.Scapulalocator.active
                    app.ScapulalocatorCheckBox.Value=1;
                    app.HeightcmEditFieldLabel.Visible="on"; 
                end
            
                if ~isempty(find(strcmp(app.Parameters.Scapulalocator.side,'R'),1))
                    app.HeightcmEditField.Visible="on";
                    app.HeightcmEditField.Value = app.Parameters.Scapulalocator.height(1);
                end
                
                if ~isempty(find(strcmp(app.Parameters.Scapulalocator.side,'L'),1))
                    app.HeightcmEditField_2.Visible="on";
                    app.HeightcmEditField_2.Value = app.Parameters.Scapulalocator.height(1);
                end
            end
                   
            % Visuel
            app.PlotFigure = 1;
            app.actualizePlotModel();

            
        end
        
        function ActuateEnableModel(app,EnableModel)
            if EnableModel(1)
                app.Uppertrunk.Value = 'NoModel';
                app.Parameters.UpperTrunk = str2func(app.Uppertrunk.Value);
                app.Uppertrunk.Enable = 'off';
            else
                app.Uppertrunk.Enable = 'on';
            end
            if EnableModel(2)
                app.Head.Value = 'NoModel';
                app.Parameters.Head = str2func(app.Head.Value);
                app.Head.Enable = 'off';
            else
                app.Head.Enable = 'on';
            end
            if EnableModel(3)
                app.RightLeg.Value = 'NoModel';
                app.Parameters.RightLeg = str2func(app.RightLeg.Value);
                app.RightLeg.Enable = 'off';
            else
                app.RightLeg.Enable = 'on';
            end
            if EnableModel(4)
                app.LeftLeg.Value = 'NoModel';
                app.Parameters.LeftLeg = str2func(app.LeftLeg.Value);
                app.LeftLeg.Enable = 'off';
            else
                app.LeftLeg.Enable = 'on';
            end
            if EnableModel(5)
                app.RightArm.Value = 'NoModel';
                app.Parameters.RightArm = str2func(app.RightArm.Value);
                app.RightArm.Enable = 'off';
            else
                app.RightArm.Enable = 'on';
            end
            if EnableModel(6)
                app.LeftArm.Value = 'NoModel';
                app.Parameters.LeftArm = str2func(app.LeftArm.Value);
                app.LeftArm.Enable = 'off';
            else
                app.LeftArm.Enable = 'on';
            end   
        end
        
        function ActualizeRoot(app)
            app.RootDropDown.Items = {app.Human_model.name};
            if ~numel(intersect(app.RootDropDown.Value, {app.Human_model.name}))
                app.RootDropDown.Value = app.Human_model([app.Human_model.name]==0).name;
            end
            RootDropDownValueChanged(app);
        end
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            hold(app.UIAxes,'on');
            axis(app.UIAxes,'equal');
            xlim(app.UIAxes,[-0.55 0.55]);
            ylim(app.UIAxes,[-1.2 0.9]);
            zlim(app.UIAxes,[-0.55 0.55]);
            % Initialisation de la liste des modèles ostéo
            path_models = fileparts(which('ModelGeneration'));
            list_files = dir([path_models '/Osteoarticular/PelvisLowerTrunk/*.m']); list_files = cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false);
            app.PelvisLowertrunk.Items = list_files; app.PelvisLowertrunk.ItemsData = list_files;
            list_files = dir([path_models '/Osteoarticular/UpperTrunk/*.m']); list_files = ['NoModel' cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false)];
            app.Uppertrunk.Items = list_files; app.Uppertrunk.ItemsData = list_files;
            list_files = dir([path_models '/Osteoarticular/Head/*.m']); list_files = ['NoModel' cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false)];
            app.Head.Items = list_files; app.Head.ItemsData = list_files;
            list_files = dir([path_models '/Osteoarticular/Leg/*.m']); list_files = ['NoModel' cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false)];
            app.RightLeg.Items = list_files; app.RightLeg.ItemsData = list_files;
            app.LeftLeg.Items = list_files; app.LeftLeg.ItemsData = list_files;
            list_files = dir([path_models '/Osteoarticular/Arm/*.m']); list_files = ['NoModel' cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false)];
            app.RightArm.Items = list_files; app.RightArm.ItemsData = list_files;
            app.LeftArm.Items = list_files; app.LeftArm.ItemsData = list_files;
            % Initialisation de la liste des marqueurs
            list_files = dir([path_models '/Markers/*.m']); list_files = cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false);
            app.Markers.Items = list_files; app.Markers.ItemsData = list_files;
            % Initialisation de la liste des muscles
            list_files = dir([path_models '/Muscles/*.m']); list_files = cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false);
            for i=1:10
                eval(['app.DropDown_' num2str(2*i-1) '.Items = list_files;'])
                eval(['app.DropDown_' num2str(2*i-1) '.ItemsData = list_files;'])
                eval(['app.DropDown_' num2str(2*i) '.Items = {''Right'' ''Left''};'])
                eval(['app.DropDown_' num2str(2*i) '.ItemsData = {''Right'' ''Left''};'])
            end
            % Nb de marqueurs sur la main
            app.MarkersonhandsDropDown.Items = {'1';'2';'3'}; app.MarkersonhandsDropDown.ItemsData = {'1';'2';'3'};
            % Paramètres initiaux
            app.Parameters.Size = 1.80;
            app.Parameters.Mass = 80;
            app.Parameters.PelvisLowerTrunk = @PelvisLowerTrunk;
            app.Parameters.UpperTrunk = @UpperTrunkClavicle;
            app.Parameters.Head = @Skull;
            app.Parameters.LeftLeg = @Leg;
            app.Parameters.RightLeg = @Leg;
            app.Parameters.LeftArm = @Arm;
            app.Parameters.RightArm = @Arm;
            app.Parameters.Root = 'PelvisSacrum';
            app.Parameters.Markers = @Marker_set2;
            app.Parameters.MarkersOptions = 2;
            app.Parameters.Muscles = {};
            app.Parameters.MusclesOptions = {};
            app.Parameters.MarkersRemoved = {};
            app.PlotFigure = 1;
            app.ActuateApp(app.Parameters)
            app.Markers0 = app.NewMarkers;
            app.actualizeListMarkers();
            
            app.ScapulalocatorCheckBox.Value=0;
            app.HeightcmEditField.Visible="off";
            app.HeightcmEditFieldLabel.Visible="off";  
            app.ScapulaSide.Visible="off";  
            app.Parameters.Scapulalocator.active = 0;
            app.Parameters.Scapulalocator.height = 0;
            app.Parameters.Scapulalocator.side = {'R'};

         
        end

        % Value changed function: Uppertrunk
        function UppertrunkValueChanged(app, event)
            app.Parameters.UpperTrunk = str2func(app.Uppertrunk.Value);
            app.Parameters.MarkersRemoved = {};
            if app.PlotFigure
                app.actualizePlotModel();
                app.actualizeListMarkers();
            end
            app.ActualizeRoot();
        end

        % Value changed function: Head
        function HeadValueChanged(app, event)
            app.Parameters.Head = str2func(app.Head.Value);
            app.Parameters.MarkersRemoved = {};
            if app.PlotFigure
                app.actualizePlotModel();
                app.actualizeListMarkers();
            end
            app.ActualizeRoot();
        end

        % Value changed function: PelvisLowertrunk
        function PelvisLowertrunkValueChanged(app, event)
            app.Parameters.PelvisLowerTrunk = str2func(app.PelvisLowertrunk.Value);
            app.Parameters.MarkersRemoved = {};
            if app.PlotFigure
                app.actualizePlotModel();
                app.actualizeListMarkers();
            end
            app.ActualizeRoot();
        end

        % Value changed function: RightArm
        function RightArmValueChanged(app, event)
            app.Parameters.RightArm = str2func(app.RightArm.Value);
            app.Parameters.MarkersRemoved = {};
            if app.PlotFigure
                app.actualizePlotModel();
                app.actualizeListMarkers();
            end
            app.ActualizeRoot();
        end

        % Value changed function: LeftArm
        function LeftArmValueChanged(app, event)
            app.Parameters.LeftArm = str2func(app.LeftArm.Value);
            app.Parameters.MarkersRemoved = {};
            if app.PlotFigure
                app.actualizePlotModel();
                app.actualizeListMarkers();
            end
            app.ActualizeRoot();
        end

        % Value changed function: RightLeg
        function RightLegValueChanged(app, event)
            app.Parameters.RightLeg = str2func(app.RightLeg.Value);
            app.Parameters.MarkersRemoved = {};
            if app.PlotFigure
                app.actualizePlotModel(); 
                app.actualizeListMarkers();
            end
            app.ActualizeRoot();
        end

        % Value changed function: LeftLeg
        function LeftLegValueChanged(app, event)
            app.Parameters.LeftLeg = str2func(app.LeftLeg.Value);
            app.Parameters.MarkersRemoved = {};
            if app.PlotFigure
                app.actualizePlotModel();
                app.actualizeListMarkers();
            end
            app.ActualizeRoot();
        end

        % Value changed function: Markers
        function MarkersValueChanged(app, event)
            app.Parameters.Markers = str2func(app.Markers.Value);
            app.Parameters.MarkersRemoved = {};
            if strcmp(app.Markers.Value,'Marker_set2') || strcmp(app.Markers.Value,'Marker_set3')
                app.MarkersonhandsDropDown.Visible = 'on';
                app.MarkersonhandsDropDownLabel.Visible = 'on';
                app.Parameters.MarkersOptions = str2double(app.MarkersonhandsDropDown.Value);
            else
                app.MarkersonhandsDropDown.Visible = 'off';
                app.MarkersonhandsDropDownLabel.Visible = 'off';
            end
            app.Parameters.MarkersRemoved = {};
            app.actualizePlotModel();
            app.Markers0 = app.NewMarkers;
            app.actualizeListMarkers();
        end

        % Value changed function: Button1, Button10, Button11, Button12, 
        % ...and 48 other components
        function Button1ValueChanged(app, event)
            app.Parameters.MarkersRemoved = {};
            for i = 1:numel(app.Markers0)
                if app.Markers0(i).exist
                   eval(['value = app.Button' num2str(i) '.Value;']);
                    if ~value
                        app.Parameters.MarkersRemoved = [app.Parameters.MarkersRemoved;app.Markers0(i).name];
                    end
                end
            end
            if app.PlotFigure
                app.actualizePlotModel();
            end
        end

        % Value changed function: SizeEditField
        function SizeEditFieldValueChanged(app, event)
            app.Parameters.Size = app.SizeEditField.Value;
            if app.PlotFigure
                app.actualizePlotModel();
            end
        end

        % Value changed function: MassEditField
        function MassEditFieldValueChanged(app, event)
            app.Parameters.Mass = app.MassEditField.Value;
            if app.PlotFigure
                app.actualizePlotModel();
            end
        end

        % Button pushed function: GenerateparametersButton
        function GenerateparametersButtonPushed(app, event)
            ModelParameters = app.Parameters;
            save('ModelParameters','ModelParameters');
            Analysis;
            delete(app);
        end

        % Value changed function: MarkersonhandsDropDown
        function MarkersonhandsDropDownValueChanged(app, event)
            app.Parameters.MarkersOptions = str2double(app.MarkersonhandsDropDown.Value);
            app.Parameters.MarkersRemoved = {};
            if app.PlotFigure
                app.actualizePlotModel();
            end
            app.Markers0 = app.NewMarkers;
            if app.PlotFigure
                app.actualizeListMarkers();
            end
        end

        % Button pushed function: LoadparametersButton_2
        function LoadparametersButton_2Pushed(app, event)
            [FileNameParameters,PathNameParameters] = uigetfile('ModelParameters.mat','Select a Parameters file');
            app.PlotFigure = 0;
            OldParameters = load([PathNameParameters FileNameParameters]);
            OldParameters = OldParameters.ModelParameters;
            app.ActuateApp(OldParameters);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if app.num_m < 10
                app.num_m = app.num_m+1;
                eval(['app.DropDown_' num2str(2*app.num_m-1) '.Visible = ''on'';'])
                eval(['app.DropDown_' num2str(2*app.num_m) '.Visible = ''on'';'])
                eval(['app.SideLabel_' num2str(app.num_m) '.Visible = ''on'';'])
            end
            app.DropDown_1ValueChanged;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if app.num_m
                eval(['app.DropDown_' num2str(2*app.num_m-1) '.Visible = ''off'';'])
                eval(['app.DropDown_' num2str(2*app.num_m) '.Visible = ''off'';'])
                eval(['app.SideLabel_' num2str(app.num_m) '.Visible = ''off'';'])
                app.num_m = app.num_m-1;
            end 
            app.DropDown_1ValueChanged;
        end

        % Value changed function: DropDown_1, DropDown_10, DropDown_11, 
        % ...and 17 other components
        function DropDown_1ValueChanged(app, event)
            app.Parameters.Muscles = {};
            app.Parameters.MusclesOptions = {};
            for i=1:app.num_m
                eval(['app.Parameters.Muscles = [app.Parameters.Muscles {str2func(app.DropDown_' num2str(2*i-1) '.Value)}];'])
                eval(['app.Parameters.MusclesOptions = [app.Parameters.MusclesOptions {app.DropDown_' num2str(2*i) '.Value}];'])
            end
            if app.PlotFigure
                app.actualizePlotModel();
            end            
        end

        % Value changed function: RootDropDown
        function RootDropDownValueChanged(app, event)
            app.Parameters.Root = app.RootDropDown.Value;            
        end

        % Value changed function: ScapulalocatorCheckBox
        function ScapulalocatorCheckBoxValueChanged(app, event)
            value = app.ScapulalocatorCheckBox.Value;
            if value
                app.ScapulaSide.Visible="on";  
                app.HeightcmEditFieldLabel.Visible="on";
                app.HeightcmEditField.Visible="on";
                app.Parameters.Scapulalocator.active = 1;
            else
                app.ScapulaSide.Visible="off";  
                app.HeightcmEditFieldLabel.Visible="off";
                app.HeightcmEditField.Visible="off";
                app.Parameters.Scapulalocator.active = 0;
            end
            app.actualizePlotModel();
            app.actualizeListMarkers();

        end

        % Value changed function: HeightcmEditField
        function HeightcmEditFieldValueChanged(app, event)
            app.Parameters.Scapulalocator.height = app.HeightcmEditField.Value;
            app.actualizePlotModel();

        end

        % Callback function
        function RightsideCheckBoxValueChanged(app, event)
         
        end

        % Callback function
        function LeftsideCheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function HeightcmEditField_2ValueChanged(app, event)
            
        end

        % Value changed function: ScapulaSide
        function ScapulaSideValueChanged(app, event)
            value = app.ScapulaSide.Value;
            if strcmp(value,"Right")
                app.Parameters.Scapulalocator.side={'R'};
            elseif strcmp(value,"Left")
                app.Parameters.Scapulalocator.side={'L'};
            else
                app.Parameters.Scapulalocator.side={'RL'};
            end
            app.actualizePlotModel();
            app.actualizeListMarkers();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.SizemEditFieldLabel = uilabel(app.GeneralTab);
            app.SizemEditFieldLabel.Text = 'Size (m)';
            app.SizeEditField = uieditfield(app.GeneralTab, 'numeric');
            app.SizeEditField.Limits = [0 Inf];
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @SizeEditFieldValueChanged, true);
            app.SizeEditField.Value = 1.8;
            app.MasskgEditFieldLabel = uilabel(app.GeneralTab);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MassEditField = uieditfield(app.GeneralTab, 'numeric');
            app.MassEditField.Limits = [0 Inf];
            app.MassEditField.ValueChangedFcn = createCallbackFcn(app, @MassEditFieldValueChanged, true);
            app.MassEditField.Value = 80;
            app.LoadparametersButton_2 = uibutton(app.GeneralTab, 'push');
            app.LoadparametersButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadparametersButton_2Pushed, true);
            app.LoadparametersButton_2.Text = 'Load parameters';
            app.OsteoarticularTab = uitab(app.TabGroup);
            app.OsteoarticularTab.Title = 'Osteoarticular';
            app.TrunkPanel = uipanel(app.OsteoarticularTab);
            app.TrunkPanel.Title = 'Trunk';
            app.PelvisLowertrunkDropDownLabel = uilabel(app.TrunkPanel);
            app.PelvisLowertrunkDropDownLabel.Text = 'Pelvis / Lower trunk';
            app.PelvisLowertrunk = uidropdown(app.TrunkPanel);
            app.PelvisLowertrunk.Items = {'Options 1', 'Options 2'};
            app.PelvisLowertrunk.ValueChangedFcn = createCallbackFcn(app, @PelvisLowertrunkValueChanged, true);
            app.PelvisLowertrunk.Value = 'Options 2';
            app.UppertrunkDropDownLabel = uilabel(app.TrunkPanel);
            app.UppertrunkDropDownLabel.Text = 'Upper trunk';
            app.Uppertrunk = uidropdown(app.TrunkPanel);
            app.Uppertrunk.Items = {'Options 1', 'Options 2'};
            app.Uppertrunk.ValueChangedFcn = createCallbackFcn(app, @UppertrunkValueChanged, true);
            app.Uppertrunk.Value = 'Options 2';
            app.HeadDropDownLabel = uilabel(app.TrunkPanel);
            app.HeadDropDownLabel.Text = 'Head';
            app.Head = uidropdown(app.TrunkPanel);
            app.Head.Items = {'Options 1', 'Options 2'};
            app.Head.ValueChangedFcn = createCallbackFcn(app, @HeadValueChanged, true);
            app.Head.Value = 'Options 2';
            app.RightlegPanel = uipanel(app.OsteoarticularTab);
            app.RightlegPanel.Title = 'Right leg';
            app.Label_5 = uilabel(app.RightlegPanel);
            app.Label_5.Text = '';
            app.RightLeg = uidropdown(app.RightlegPanel);
            app.RightLeg.Items = {'Options 1', 'Options 2'};
            app.RightLeg.ValueChangedFcn = createCallbackFcn(app, @RightLegValueChanged, true);
            app.RightLeg.Value = 'Options 2';
            app.LeftlegPanel = uipanel(app.OsteoarticularTab);
            app.LeftlegPanel.Title = 'Left leg';
            app.Label_2 = uilabel(app.LeftlegPanel);
            app.Label_2.Text = '';
            app.LeftLeg = uidropdown(app.LeftlegPanel);
            app.LeftLeg.Items = {'Options 1', 'Options 2'};
            app.LeftLeg.ValueChangedFcn = createCallbackFcn(app, @LeftLegValueChanged, true);
            app.LeftLeg.Value = 'Options 2';
            app.RightarmPanel = uipanel(app.OsteoarticularTab);
            app.RightarmPanel.Title = 'Right arm';
            app.Label_3 = uilabel(app.RightarmPanel);
            app.Label_3.Text = '';
            app.RightArm = uidropdown(app.RightarmPanel);
            app.RightArm.Items = {'Options 1', 'Options 2'};
            app.RightArm.ValueChangedFcn = createCallbackFcn(app, @RightArmValueChanged, true);
            app.RightArm.Value = 'Options 2';
            app.LeftarmPanel = uipanel(app.OsteoarticularTab);
            app.LeftarmPanel.Title = 'Left arm';
            app.DropDown_4Label = uilabel(app.LeftarmPanel);
            app.DropDown_4Label.Text = '';
            app.LeftArm = uidropdown(app.LeftarmPanel);
            app.LeftArm.Items = {'Options 1', 'Options 2'};
            app.LeftArm.ValueChangedFcn = createCallbackFcn(app, @LeftArmValueChanged, true);
            app.LeftArm.Value = 'Options 2';
            app.RootDropDownLabel = uilabel(app.OsteoarticularTab);
            app.RootDropDownLabel.Text = 'Root';
            app.RootDropDown = uidropdown(app.OsteoarticularTab);
            app.RootDropDown.ValueChangedFcn = createCallbackFcn(app, @RootDropDownValueChanged, true);
            app.MarkersTab = uitab(app.TabGroup);
            app.MarkersTab.Title = 'Markers';
            app.Label_4 = uilabel(app.MarkersTab);
            app.Label_4.Text = '';
            app.Markers = uidropdown(app.MarkersTab);
            app.Markers.Items = {'Options 1', 'Options 2'};
            app.Markers.ValueChangedFcn = createCallbackFcn(app, @MarkersValueChanged, true);
            app.Markers.Value = 'Options 1';
            app.MarkersList = uipanel(app.MarkersTab);
            app.Button1 = uibutton(app.MarkersList, 'state');
            app.Button1.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button1.Text = 'Button1';
            app.Button2 = uibutton(app.MarkersList, 'state');
            app.Button2.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.MarkersList, 'state');
            app.Button3.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.MarkersList, 'state');
            app.Button4.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.MarkersList, 'state');
            app.Button5.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button5.Text = 'Button5';
            app.Button6 = uibutton(app.MarkersList, 'state');
            app.Button6.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button6.Text = 'Button6';
            app.Button7 = uibutton(app.MarkersList, 'state');
            app.Button7.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button7.Text = 'Button7';
            app.Button8 = uibutton(app.MarkersList, 'state');
            app.Button8.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button8.Text = 'Button8';
            app.Button9 = uibutton(app.MarkersList, 'state');
            app.Button9.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button9.Text = 'Button9';
            app.Button10 = uibutton(app.MarkersList, 'state');
            app.Button10.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button10.Text = 'Button10';
            app.Button11 = uibutton(app.MarkersList, 'state');
            app.Button11.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button11.Text = 'Button11';
            app.Button12 = uibutton(app.MarkersList, 'state');
            app.Button12.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button12.Text = 'Button12';
            app.Button13 = uibutton(app.MarkersList, 'state');
            app.Button13.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button13.Text = 'Button13';
            app.Button14 = uibutton(app.MarkersList, 'state');
            app.Button14.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button14.Text = 'Button14';
            app.Button15 = uibutton(app.MarkersList, 'state');
            app.Button15.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button15.Text = 'Button15';
            app.Button16 = uibutton(app.MarkersList, 'state');
            app.Button16.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button16.Text = 'Button16';
            app.Button17 = uibutton(app.MarkersList, 'state');
            app.Button17.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button17.Text = 'Button17';
            app.Button18 = uibutton(app.MarkersList, 'state');
            app.Button18.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button18.Text = 'Button18';
            app.Button19 = uibutton(app.MarkersList, 'state');
            app.Button19.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button19.Text = 'Button19';
            app.Button20 = uibutton(app.MarkersList, 'state');
            app.Button20.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button20.Text = 'Button20';
            app.Button21 = uibutton(app.MarkersList, 'state');
            app.Button21.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button21.Text = 'Button21';
            app.Button22 = uibutton(app.MarkersList, 'state');
            app.Button22.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button22.Text = 'Button22';
            app.Button23 = uibutton(app.MarkersList, 'state');
            app.Button23.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button23.Text = 'Button23';
            app.Button24 = uibutton(app.MarkersList, 'state');
            app.Button24.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button24.Text = 'Button24';
            app.Button25 = uibutton(app.MarkersList, 'state');
            app.Button25.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button25.Text = 'Button25';
            app.Button26 = uibutton(app.MarkersList, 'state');
            app.Button26.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button26.Text = 'Button26';
            app.Button27 = uibutton(app.MarkersList, 'state');
            app.Button27.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button27.Text = 'Button27';
            app.Button28 = uibutton(app.MarkersList, 'state');
            app.Button28.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button28.Text = 'Button28';
            app.Button29 = uibutton(app.MarkersList, 'state');
            app.Button29.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button29.Text = 'Button29';
            app.Button30 = uibutton(app.MarkersList, 'state');
            app.Button30.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button30.Text = 'Button30';
            app.Button31 = uibutton(app.MarkersList, 'state');
            app.Button31.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button31.Text = 'Button31';
            app.Button32 = uibutton(app.MarkersList, 'state');
            app.Button32.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button32.Text = 'Button32';
            app.Button33 = uibutton(app.MarkersList, 'state');
            app.Button33.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button33.Text = 'Button33';
            app.Button34 = uibutton(app.MarkersList, 'state');
            app.Button34.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button34.Text = 'Button34';
            app.Button35 = uibutton(app.MarkersList, 'state');
            app.Button35.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button35.Text = 'Button35';
            app.Button36 = uibutton(app.MarkersList, 'state');
            app.Button36.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button36.Text = 'Button36';
            app.Button37 = uibutton(app.MarkersList, 'state');
            app.Button37.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button37.Text = 'Button37';
            app.Button38 = uibutton(app.MarkersList, 'state');
            app.Button38.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button38.Text = 'Button38';
            app.Button39 = uibutton(app.MarkersList, 'state');
            app.Button39.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button39.Text = 'Button39';
            app.Button40 = uibutton(app.MarkersList, 'state');
            app.Button40.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button40.Text = 'Button40';
            app.Button41 = uibutton(app.MarkersList, 'state');
            app.Button41.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button41.Text = 'Button41';
            app.Button42 = uibutton(app.MarkersList, 'state');
            app.Button42.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button42.Text = 'Button42';
            app.Button43 = uibutton(app.MarkersList, 'state');
            app.Button43.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button43.Text = 'Button43';
            app.Button44 = uibutton(app.MarkersList, 'state');
            app.Button44.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button44.Text = 'Button44';
            app.Button45 = uibutton(app.MarkersList, 'state');
            app.Button45.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button45.Text = 'Button45';
            app.Button46 = uibutton(app.MarkersList, 'state');
            app.Button46.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button46.Text = 'Button46';
            app.Button47 = uibutton(app.MarkersList, 'state');
            app.Button47.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button47.Text = 'Button47';
            app.Button48 = uibutton(app.MarkersList, 'state');
            app.Button48.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button48.Text = 'Button48';
            app.Button49 = uibutton(app.MarkersList, 'state');
            app.Button49.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button49.Text = 'Button49';
            app.Button50 = uibutton(app.MarkersList, 'state');
            app.Button50.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button50.Text = 'Button50';
            app.Button51 = uibutton(app.MarkersList, 'state');
            app.Button51.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button51.Text = 'Button51';
            app.Button52 = uibutton(app.MarkersList, 'state');
            app.Button52.ValueChangedFcn = createCallbackFcn(app, @Button1ValueChanged, true);
            app.Button52.Text = 'Button52';
            app.Button53 = uibutton(app.MarkersList, 'state');
            app.Button53.Visible = 'off';
            app.Button53.Text = 'Button53';
            app.Button54 = uibutton(app.MarkersList, 'state');
            app.Button54.Visible = 'off';
            app.Button54.Text = 'Button54';
            app.Button55 = uibutton(app.MarkersList, 'state');
            app.Button55.Visible = 'off';
            app.Button55.Text = 'Button55';
            app.Button56 = uibutton(app.MarkersList, 'state');
            app.Button56.Visible = 'off';
            app.Button56.Text = 'Button56';
            app.MarkersonhandsDropDownLabel = uilabel(app.MarkersTab);
            app.MarkersonhandsDropDownLabel.Text = 'Markers on hands';
            app.MarkersonhandsDropDown = uidropdown(app.MarkersTab);
            app.MarkersonhandsDropDown.Items = {'1', '2', '3'};
            app.MarkersonhandsDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkersonhandsDropDownValueChanged, true);
            app.MarkersonhandsDropDown.Value = '1';
            app.ScapulalocatorCheckBox = uicheckbox(app.MarkersTab);
            app.ScapulalocatorCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScapulalocatorCheckBoxValueChanged, true);
            app.ScapulalocatorCheckBox.Text = 'Scapula locator';
            app.HeightcmEditFieldLabel = uilabel(app.MarkersTab);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.HeightcmEditField = uieditfield(app.MarkersTab, 'numeric');
            app.HeightcmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightcmEditFieldValueChanged, true);
            app.ScapulaSide = uidropdown(app.MarkersTab);
            app.ScapulaSide.Items = {'Right', 'Left', 'Right and Left'};
            app.ScapulaSide.ValueChangedFcn = createCallbackFcn(app, @ScapulaSideValueChanged, true);
            app.ScapulaSide.Value = 'Right';
            app.MusclesTab = uitab(app.TabGroup);
            app.MusclesTab.Title = 'Muscles';
            app.DropDown_1 = uidropdown(app.MusclesTab);
            app.DropDown_1.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_2 = uidropdown(app.MusclesTab);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.SideLabel_1 = uilabel(app.MusclesTab);
            app.SideLabel_1.Text = 'Side';
            app.DropDown_3 = uidropdown(app.MusclesTab);
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.SideLabel_2 = uilabel(app.MusclesTab);
            app.SideLabel_2.Text = 'Side';
            app.DropDown_4 = uidropdown(app.MusclesTab);
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_5 = uidropdown(app.MusclesTab);
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.SideLabel_3 = uilabel(app.MusclesTab);
            app.SideLabel_3.Text = 'Side';
            app.DropDown_6 = uidropdown(app.MusclesTab);
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_7 = uidropdown(app.MusclesTab);
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.SideLabel_4 = uilabel(app.MusclesTab);
            app.SideLabel_4.Text = 'Side';
            app.DropDown_8 = uidropdown(app.MusclesTab);
            app.DropDown_8.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_9 = uidropdown(app.MusclesTab);
            app.DropDown_9.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.SideLabel_5 = uilabel(app.MusclesTab);
            app.SideLabel_5.Text = 'Side';
            app.DropDown_10 = uidropdown(app.MusclesTab);
            app.DropDown_10.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_11 = uidropdown(app.MusclesTab);
            app.DropDown_11.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.SideLabel_6 = uilabel(app.MusclesTab);
            app.SideLabel_6.Text = 'Side';
            app.DropDown_12 = uidropdown(app.MusclesTab);
            app.DropDown_12.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_13 = uidropdown(app.MusclesTab);
            app.DropDown_13.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.SideLabel_7 = uilabel(app.MusclesTab);
            app.SideLabel_7.Text = 'Side';
            app.DropDown_14 = uidropdown(app.MusclesTab);
            app.DropDown_14.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_15 = uidropdown(app.MusclesTab);
            app.DropDown_15.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.SideLabel_8 = uilabel(app.MusclesTab);
            app.SideLabel_8.Text = 'Side';
            app.DropDown_16 = uidropdown(app.MusclesTab);
            app.DropDown_16.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_17 = uidropdown(app.MusclesTab);
            app.DropDown_17.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.SideLabel_9 = uilabel(app.MusclesTab);
            app.SideLabel_9.Text = 'Side';
            app.DropDown_18 = uidropdown(app.MusclesTab);
            app.DropDown_18.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_19 = uidropdown(app.MusclesTab);
            app.DropDown_19.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.SideLabel_10 = uilabel(app.MusclesTab);
            app.SideLabel_10.Text = 'Side';
            app.DropDown_20 = uidropdown(app.MusclesTab);
            app.DropDown_20.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.AddButton = uibutton(app.MusclesTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.MusclesTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.GenerateparametersButton = uibutton(app.UIFigure, 'push');
            app.GenerateparametersButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateparametersButtonPushed, true);
            app.GenerateparametersButton.Text = 'Generate parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GenerateParameters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end