classdef Modelling < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ModellingUIFigure             matlab.ui.Figure
        Button                        matlab.ui.control.StateButton
        Button_3                      matlab.ui.control.StateButton
        Button_2                      matlab.ui.control.StateButton
        Panel                         matlab.ui.container.Panel
        AngleSliderLabel_2            matlab.ui.control.Label
        ModelPanel                    matlab.ui.container.Panel
        ListBox_4                     matlab.ui.control.ListBox
        NamesLabel                    matlab.ui.control.Label
        CheckBox_15                   matlab.ui.control.CheckBox
        AnatomicallandmarksLabel      matlab.ui.control.Label
        CheckBox_14                   matlab.ui.control.CheckBox
        ListBox                       matlab.ui.control.ListBox
        GlobalcenterofmassLabel       matlab.ui.control.Label
        CheckBox_10                   matlab.ui.control.CheckBox
        CentersofmassLabel            matlab.ui.control.Label
        CheckBox_7                    matlab.ui.control.CheckBox
        ModelmarkersLabel             matlab.ui.control.Label
        CheckBox_3                    matlab.ui.control.CheckBox
        OsteoarticularbonemodelLabel  matlab.ui.control.Label
        CheckBox_9                    matlab.ui.control.CheckBox
        OsteoarticularsegmentmodelLabel  matlab.ui.control.Label
        CheckBox_2                    matlab.ui.control.CheckBox
        AngleSlider                   matlab.ui.control.Slider
        AngleSliderLabel              matlab.ui.control.Label
        ListBox_3                     matlab.ui.control.ListBox
        MusclesPanel                  matlab.ui.container.Panel
        MomentarmsButton              matlab.ui.control.Button
        ListBox_2                     matlab.ui.control.ListBox
        SavepictureButton             matlab.ui.control.Button
        SaveparametersButton          matlab.ui.control.Button
        LoadparametersButton          matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Parameters % Description
        ModellingParameters
        init
        Human_model
        OsteoModel
        corrtable
    end
    
    methods (Access = private)
        
        function ActuatePlotModel(app)
            cla(app.UIAxes);
            app.ModellingParameters.Mode = 'Modelling';
            app.ModellingParameters.PictureFrame = 1;
            app.ModellingParameters.ax = app.UIAxes;
            PlotAnimation(app.Parameters, app.ModellingParameters)
            if app.init
                xl = xlim(app.UIAxes);
                app.ModellingParameters.xlim = xl;
                yl = ylim(app.UIAxes);
                app.ModellingParameters.ylim = yl;
                zl = zlim(app.UIAxes);
                app.ModellingParameters.zlim = zl;
                app.init = 0;
            end
        end
        
        function ActuateApp(app)
            app.CheckBox_2.Value = app.ModellingParameters.seg_anim;
            app.CheckBox_3.Value = app.ModellingParameters.mod_marker_anim;
            app.CheckBox_9.Value = app.ModellingParameters.bone_anim;
            app.CheckBox_7.Value = app.ModellingParameters.mass_centers_anim;
            app.CheckBox_10.Value = app.ModellingParameters.Global_mass_center_anim;
            
            set(app.UIAxes,'view',app.ModellingParameters.view);
            if ~app.init
                set(app.UIAxes,'xlim',app.ModellingParameters.xlim);
                set(app.UIAxes,'ylim',app.ModellingParameters.ylim);
                set(app.UIAxes,'zlim',app.ModellingParameters.zlim);
            end
            
            
        end
        
        function ActuateParameters(app)
            app.ModellingParameters.view = get(app.UIAxes,'view');
            app.ModellingParameters.xlim = get(app.UIAxes,'xlim');
            app.ModellingParameters.ylim = get(app.UIAxes,'ylim');
            app.ModellingParameters.zlim = get(app.UIAxes,'zlim');
            app.ModellingParameters.Muscles=eval(['app.ListBox_2.Value']);
            % Actualiser le fichier ModellingParameters à partir de l'application
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            hold(app.UIAxes,'on');
            axis(app.UIAxes,'equal');
            load('ModelParameters.mat'); app.Parameters = ModelParameters;
            load('BiomechanicalModel.mat');
            app.OsteoModel=BiomechanicalModel.OsteoArticularModel;
            cpt=1;
            app.corrtable = [];
            for k=find([BiomechanicalModel.OsteoArticularModel.Visual])
                app.Human_model(cpt).name = BiomechanicalModel.OsteoArticularModel(k).name;
                app.Human_model(cpt).anat_position = BiomechanicalModel.OsteoArticularModel(k).anat_position;
                cpt=cpt+1;
                app.corrtable = [app.corrtable  k];
            end
            name_sol_mvt={};
            numb_sol_mvt = [];
            list_solid = 2:size(BiomechanicalModel.OsteoArticularModel,2);
            if isfield(BiomechanicalModel,'ClosedLoopData')
                for jj=1:size(BiomechanicalModel.ClosedLoopData,2)
                    if isfield(BiomechanicalModel,'Generalized_Coordinates')
                        vec=zeros(size(BiomechanicalModel.Generalized_Coordinates.q_red));
                        vec(BiomechanicalModel.ClosedLoopData(jj).drivedqv) = 1;
                        nvindice = BiomechanicalModel.Generalized_Coordinates.q_map*vec;
                       list_solid = setdiff(list_solid, find(nvindice));
                    else
                        list_solid = setdiff(list_solid, BiomechanicalModel.ClosedLoopData(jj).drivedqv);
                    end
                end
            end
            for k=list_solid
                if ~isinf(BiomechanicalModel.OsteoArticularModel(k).limit_sup) && (BiomechanicalModel.OsteoArticularModel(k).limit_sup~=BiomechanicalModel.OsteoArticularModel(k).limit_inf)
                    if ~isempty(BiomechanicalModel.OsteoArticularModel(k).FunctionalAngle)
                        name_sol_mvt = [name_sol_mvt , {BiomechanicalModel.OsteoArticularModel(k).FunctionalAngle}];
                        numb_sol_mvt = [numb_sol_mvt k];
                    else
                        name_sol_mvt = [name_sol_mvt , {BiomechanicalModel.OsteoArticularModel(k).name}];
                        numb_sol_mvt = [numb_sol_mvt k];
                    end
                end
            end
            
            % Valeurs initiales
            app.ModellingParameters.Noc3d = 1;
            app.ModellingParameters.seg_anim = 0;
            app.ModellingParameters.muscles_anim = 0;
            app.ModellingParameters.mod_marker_anim = 0;
            app.ModellingParameters.bone_anim = 0;
            app.ModellingParameters.mass_centers_anim = 0;
            app.ModellingParameters.Global_mass_center_anim = 0;
            app.ModellingParameters.view = [0 1];
            app.ModellingParameters.Segment = [];
            app.ModellingParameters.AnatLandmark = 0;
            app.ModellingParameters.AnatLandmark_names = 0;
            app.ModellingParameters.Muscles = [];
            app.ModellingParameters.angle=[];
            app.ModellingParameters.sol_anim=[];
            app.init = 1;
            %             app.ModellingParameters.xlim = [-0.55 0.55];
            %             app.ModellingParameters.ylim = [-1.2 0.9];
            %             app.ModellingParameters.zlim = [0 2.3];
            app.ActuateApp;
            % Tracé
            app.ActuatePlotModel;
            eval(['app.CheckBox_15.Visible=''off'';']);
            eval(['app.NamesLabel.Visible = ''off'';']);
            eval(['app.ListBox.Visible = ''off'';']);
            eval(['app.ListBox.Items={app.Human_model.name};']);
            eval(['app.ListBox.ItemsData=1:size(app.Human_model,2);']);
            eval(['app.ListBox.Value=1;']);
            
            if isfield(BiomechanicalModel,'Muscles') && ~isempty(BiomechanicalModel.Muscles)
                eval(['app.ListBox_2.Visible = ''on'';']);
                eval(['app.ListBox_2.Items={BiomechanicalModel.Muscles.name};']);
                eval(['app.ListBox_2.Value={};']);
            else
                eval(['app.ListBox_2.Visible = ''off'';']);
                eval(['app.ListBox_2.Value={};']);
            end
            
            eval(['app.ListBox_4.Visible = ''off'';']);
            
            
            eval(['app.ListBox_3.Visible = ''on'';']);
            eval(['app.ListBox_3.Items=name_sol_mvt;']);
            eval(['app.ListBox_3.Value=name_sol_mvt(1);']);
            eval(['app.ListBox_3.ItemsData=numb_sol_mvt;']);
            
            [~, indsol]=intersect({app.OsteoModel.name},name_sol_mvt(1));
            
            uppbound=app.OsteoModel(indsol).limit_sup*180/pi;
            lowbound=app.OsteoModel(indsol).limit_inf*180/pi;
            
            eval(['app.AngleSlider.Visible = ''on'';']);
            eval(['app.AngleSliderLabel.Visible = ''on'';']);
            eval(['app.AngleSlider.Limits=[lowbound uppbound];']);
            
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            if app.Button.Value
                rotate3d(app.UIAxes,'on');
                app.Button_2.Value = false;
                app.Button_2ValueChanged;
                app.Button_3.Value = false;
                app.Button_3ValueChanged;
            else
                rotate3d(app.UIAxes,'off');
            end
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            if app.Button_2.Value
                zoom(app.UIAxes,'on');
                app.Button.Value = false;
                app.ButtonValueChanged;
                app.Button_3.Value = false;
                app.Button_3ValueChanged;
            else
                zoom(app.UIAxes,'off');
            end
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            if app.Button_3.Value
                pan(app.UIAxes,'on');
                app.Button.Value = false;
                app.ButtonValueChanged;
                app.Button_2.Value = false;
                app.Button_2ValueChanged;
            else
                pan(app.UIAxes,'off');
            end
        end

        % Callback function
        function DropDownValueChanged(app, event)
            app.ActuateParameters;
            app.ActuateFilename;
            app.ActuateEnableButton; % Locked buttons
            app.ActuatePlotModel;
        end

        % Callback function
        function EditFieldValueChanged(app, event)
         
        end

        % Callback function
        function SliderValueChanged(app, event)
           
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            if app.CheckBox_2.Value
                app.ModellingParameters.seg_anim = 1;
            else
                app.ModellingParameters.seg_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Callback function
        function CheckBox_5ValueChanged(app, event)
            
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            if app.CheckBox_3.Value
                app.ModellingParameters.mod_marker_anim = 1;
            else
                app.ModellingParameters.mod_marker_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Callback function
        function GeneratevideoButtonPushed(app, event)
            app.ActuateParameters;
            ModellingParameters = app.ModellingParameters;
            save('ModellingParameters','ModellingParameters');
            app.ModellingParameters.Mode = 'Figure';
            PlotAnimation(app.Parameters, app.ModellingParameters);
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            
        end

        % Callback function
        function CheckBox_4ValueChanged(app, event)
         
        end

        % Button pushed function: LoadparametersButton
        function LoadparametersButtonPushed(app, event)
            [FileNameParameters,PathNameParameters] = uigetfile('ModellingParameters.mat','Select a Parameters file');
            OldModellingParameters = load([PathNameParameters FileNameParameters]);
            OldModellingParameters = OldModellingParameters.ModellingParameters;
            % Sauvegarde
            app.ModellingParameters = OldModellingParameters;
            app.ActuateApp;
            app.ActuatePlotModel;
        end

        % Button pushed function: SaveparametersButton
        function SaveparametersButtonPushed(app, event)
            app.ActuateParameters;
            ModellingParameters = app.ModellingParameters;
            save('ModellingParameters','ModellingParameters');
        end

        % Button pushed function: SavepictureButton
        function SavepictureButtonPushed(app, event)
            app.ActuateParameters;
            ModellingParameters = app.ModellingParameters;
            save('ModellingParameters','ModellingParameters');
            app.ModellingParameters.Mode = 'Picture';
            PlotAnimation(app.Parameters, app.ModellingParameters);
        end

        % Callback function
        function CheckBox_8ValueChanged(app, event)
            
        end

        % Callback function
        function DAnimationFigureButtonPushed(app, event)
            app.ActuateParameters;
            ModellingParameters = app.ModellingParameters;
            save('ModellingParameters','ModellingParameters');
            app.ModellingParameters.Mode = 'cFigure';
            PlotAnimation(app.Parameters, app.ModellingParameters);
        end

        % Value changed function: CheckBox_7
        function CheckBox_7ValueChanged(app, event)
            value = app.CheckBox_7.Value;
            if app.CheckBox_7.Value
                app.ModellingParameters.mass_centers_anim = 1;
            else
                app.ModellingParameters.mass_centers_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            if app.CheckBox_10.Value
                app.ModellingParameters.Global_mass_center_anim = 1;
            else
                app.ModellingParameters.Global_mass_center_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            if app.CheckBox_9.Value
                app.ModellingParameters.bone_anim = 1;
            else
                app.ModellingParameters.bone_anim = 0;
            end
            app.ActuatePlotModel;
        end

        % Callback function
        function CheckBox_11ValueChanged(app, event)
            
        end

        % Callback function
        function CheckBox_13ValueChanged(app, event)
            
        end

        % Value changed function: CheckBox_14
        function CheckBox_14ValueChanged(app, event)
            if app.CheckBox_14.Value
                app.ModellingParameters.AnatLandmark = 1;
                
                app.ListBox.Visible = 'on';
                app.CheckBox_15.Visible = 'on';
                app.NamesLabel.Visible = 'on';
                app.ListBox.Value=1;
                
                app.ModellingParameters.Segment=app.corrtable(app.ListBox.Value);
                
                seg = app.ListBox.Value;
                app.ListBox_4.Visible = 'on';
                app.ListBox_4.Items = [app.Human_model(seg).anat_position(:,1)];
                app.ListBox_4.ItemsData = 1:length(app.Human_model(seg).anat_position(:,1));
                app.ListBox_4.Value=1:length(app.Human_model(seg).anat_position(:,1));
                
                app.ModellingParameters.AnatLandmarknum = app.ListBox_4.Value;
                
            else
                app.ModellingParameters.AnatLandmark = 0;
                eval(['app.ListBox.Value={app.Human_model.name};']);
                eval(['app.ListBox.Visible = ''off'';']);
                eval(['app.NamesLabel.Visible = ''off'';']);
                eval(['app.CheckBox_15.Visible = ''off'';']);
                app.ListBox_4.Visible = 'off';
                
                
            end
            app.ActuatePlotModel;
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.ModellingParameters.Segment=app.corrtable(app.ListBox.Value);
            
            seg = app.ListBox.Value;
            app.ListBox_4.Visible = 'on';
            if ~isempty(app.Human_model(seg).anat_position)
                app.ListBox_4.Items = [app.Human_model(seg).anat_position(:,1)];
                app.ListBox_4.ItemsData = 1:length(app.Human_model(seg).anat_position(:,1));
                app.ListBox_4.Value=1:length(app.Human_model(seg).anat_position(:,1));
            else

                app.ListBox_4.Items = [""];
                app.ListBox_4.ItemsData = 0;
                app.ListBox_4.Value=0;
            end
                
            app.ModellingParameters.AnatLandmarknum = app.ListBox_4.Value;


            app.ActuatePlotModel;
            
        end

        % Value changed function: CheckBox_15
        function CheckBox_15ValueChanged(app, event)
            if app.CheckBox_15.Value
               app.ModellingParameters.AnatLandmark_names = 1;
            else
               app.ModellingParameters.AnatLandmark_names = 0;
            end
            app.ActuatePlotModel;

        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            app.ModellingParameters.Muscles=eval(['app.ListBox_2.Value']);
            app.ActuatePlotModel;
            
        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            indsol=app.ListBox_3.Value;
            
            uppbound=app.OsteoModel(indsol).limit_sup*180/pi;
            lowbound=app.OsteoModel(indsol).limit_inf*180/pi;
            
            
            eval(['app.AngleSlider.Limits=[lowbound uppbound];']);
            [t,ind]=intersect(app.ModellingParameters.sol_anim,indsol);
            if ~t
                app.ModellingParameters.sol_anim=[app.ModellingParameters.sol_anim,indsol];
                app.ModellingParameters.angle=[app.ModellingParameters.angle,eval(['app.AngleSlider.Value'])];
            else
                app.ModellingParameters.angle(ind)=eval(['app.AngleSlider.Value']);
            end
            
            app.ActuatePlotModel;
            
            
        end

        % Value changing function: AngleSlider
        function AngleSliderValueChanging(app, event)
            changingValue = event.Value;
            indsol=app.ListBox_3.Value;
            if intersect(app.ModellingParameters.sol_anim,indsol)
                app.ModellingParameters.angle(app.ModellingParameters.sol_anim==indsol)=changingValue;
            else
                app.ModellingParameters.sol_anim=[app.ModellingParameters.sol_anim,indsol];
                app.ModellingParameters.angle=[app.ModellingParameters.angle,changingValue];
            end

            app.ActuatePlotModel;


        end

        % Button pushed function: MomentarmsButton
        function MomentarmsButtonPushed(app, event)
            for k =1 : length(app.ModellingParameters.Muscles)
                MomentArmsDisplay(app.ModellingParameters.Muscles{k})
            end
        end

        % Value changed function: ListBox_4
        function ListBox_4ValueChanged(app, event)
            app.ModellingParameters.AnatLandmarknum = app.ListBox_4.Value;
            
            app.ActuatePlotModel;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ModellingUIFigure = uifigure('Visible', 'off');
            app.ModellingUIFigure.Name = 'Modelling';
            app.UIAxes = uiaxes(app.ModellingUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.Panel = uipanel(app.ModellingUIFigure);
            app.LoadparametersButton = uibutton(app.Panel, 'push');
            app.LoadparametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadparametersButtonPushed, true);
            app.LoadparametersButton.Text = 'Load parameters';
            app.SaveparametersButton = uibutton(app.Panel, 'push');
            app.SaveparametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveparametersButtonPushed, true);
            app.SaveparametersButton.Text = 'Save parameters';
            app.SavepictureButton = uibutton(app.Panel, 'push');
            app.SavepictureButton.ButtonPushedFcn = createCallbackFcn(app, @SavepictureButtonPushed, true);
            app.SavepictureButton.Text = 'Save picture';
            app.MusclesPanel = uipanel(app.Panel);
            app.MusclesPanel.Title = ' Muscles';
            app.ListBox_2 = uilistbox(app.MusclesPanel);
            app.ListBox_2.Items = {'Point 1', 'Point 2'};
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = {'Point 1'};
            app.MomentarmsButton = uibutton(app.MusclesPanel, 'push');
            app.MomentarmsButton.ButtonPushedFcn = createCallbackFcn(app, @MomentarmsButtonPushed, true);
            app.MomentarmsButton.Text = 'Moment arms';
            app.ListBox_3 = uilistbox(app.Panel);
            app.ListBox_3.Items = {'Point 1', 'Point 2'};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.Value = 'Point 1';
            app.AngleSliderLabel = uilabel(app.Panel);
            app.AngleSliderLabel.Text = 'Angle';
            app.AngleSlider = uislider(app.Panel);
            app.AngleSlider.ValueChangingFcn = createCallbackFcn(app, @AngleSliderValueChanging, true);
            app.ModelPanel = uipanel(app.Panel);
            app.ModelPanel.Title = 'Model';
            app.CheckBox_2 = uicheckbox(app.ModelPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.OsteoarticularsegmentmodelLabel = uilabel(app.ModelPanel);
            app.OsteoarticularsegmentmodelLabel.Text = 'Osteo-articular segment model';
            app.CheckBox_9 = uicheckbox(app.ModelPanel);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '';
            app.OsteoarticularbonemodelLabel = uilabel(app.ModelPanel);
            app.OsteoarticularbonemodelLabel.Text = 'Osteo-articular bone model';
            app.CheckBox_3 = uicheckbox(app.ModelPanel);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.ModelmarkersLabel = uilabel(app.ModelPanel);
            app.ModelmarkersLabel.Text = 'Model markers';
            app.CheckBox_7 = uicheckbox(app.ModelPanel);
            app.CheckBox_7.ValueChangedFcn = createCallbackFcn(app, @CheckBox_7ValueChanged, true);
            app.CheckBox_7.Text = '';
            app.CentersofmassLabel = uilabel(app.ModelPanel);
            app.CentersofmassLabel.Text = 'Centers of mass';
            app.CheckBox_10 = uicheckbox(app.ModelPanel);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '';
            app.GlobalcenterofmassLabel = uilabel(app.ModelPanel);
            app.GlobalcenterofmassLabel.Text = 'Global center of mass';
            app.ListBox = uilistbox(app.ModelPanel);
            app.ListBox.Items = {'Point 1', 'Point 2'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Point 1';
            app.CheckBox_14 = uicheckbox(app.ModelPanel);
            app.CheckBox_14.ValueChangedFcn = createCallbackFcn(app, @CheckBox_14ValueChanged, true);
            app.CheckBox_14.Text = '';
            app.AnatomicallandmarksLabel = uilabel(app.ModelPanel);
            app.AnatomicallandmarksLabel.Text = 'Anatomical landmarks';
            app.CheckBox_15 = uicheckbox(app.ModelPanel);
            app.CheckBox_15.ValueChangedFcn = createCallbackFcn(app, @CheckBox_15ValueChanged, true);
            app.CheckBox_15.Text = '';
            app.NamesLabel = uilabel(app.ModelPanel);
            app.NamesLabel.Text = 'Names';
            app.ListBox_4 = uilistbox(app.ModelPanel);
            app.ListBox_4.Items = {'Point 1', 'Point 2'};
            app.ListBox_4.Multiselect = 'on';
            app.ListBox_4.ValueChangedFcn = createCallbackFcn(app, @ListBox_4ValueChanged, true);
            app.ListBox_4.Value = {'Point 1'};
            app.AngleSliderLabel_2 = uilabel(app.Panel);
            app.AngleSliderLabel_2.Text = 'Joint';
            app.Button_2 = uibutton(app.ModellingUIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Icon = 'Zoom.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.ModellingUIFigure, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Icon = 'Pan.png';
            app.Button_3.Text = '';
            app.Button = uibutton(app.ModellingUIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'Rotate.png';
            app.Button.Text = '';
            % Show the figure after all components are created
            app.ModellingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Modelling

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ModellingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ModellingUIFigure)
        end
    end
end