classdef MusclePathCreation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MusclePathGeneration            matlab.ui.Figure
        OutputsPanel                    matlab.ui.container.Panel
        UITable2_2                      matlab.ui.control.Table
        UITable2_3                      matlab.ui.control.Table
        ComputationofmusculotendonlengthoptimizationLabel  matlab.ui.control.Label
        ComputationofmomentarmoptimizationLabel  matlab.ui.control.Label
        RootmeansquareerrormLabel_2     matlab.ui.control.Label
        RootmeansquareerrormLabel       matlab.ui.control.Label
        ImageAxes_2                     matlab.ui.control.UIAxes
        ImageAxes                       matlab.ui.control.UIAxes
        InputsPanel                     matlab.ui.container.Panel
        SolidpathListBox                matlab.ui.control.ListBox
        SolidpathListBoxLabel           matlab.ui.control.Label
        NumberofpointsEditField         matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel    matlab.ui.control.Label
        CylindersradiimLabel            matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        RadiuscylindermEditField_5      matlab.ui.control.NumericEditField
        RadiuscylindermEditField_5Label  matlab.ui.control.Label
        RadiuscylindermEditField_4      matlab.ui.control.NumericEditField
        RadiuscylindermEditField_4Label  matlab.ui.control.Label
        RadiuscylindermEditField_3      matlab.ui.control.NumericEditField
        RadiuscylindermEditField_3Label  matlab.ui.control.Label
        RadiuscylindermEditField_2      matlab.ui.control.NumericEditField
        RadiuscylindermEditField_2Label  matlab.ui.control.Label
        RadiuscylindermEditField_1      matlab.ui.control.NumericEditField
        RadiuscylindermEditField_1Label  matlab.ui.control.Label
        MusculotendonlengthdataListBox  matlab.ui.control.ListBox
        MusculotendonlengthdataLabel    matlab.ui.control.Label
        MomentarmdataListBox            matlab.ui.control.ListBox
        MomentarmdataListBoxLabel       matlab.ui.control.Label
        MusculotendonlengthoptimizationButton  matlab.ui.control.Button
        MomentarmoptimizationButton     matlab.ui.control.Button
        GeneratescriptButton            matlab.ui.control.Button
        AddmusclepathtobiomechanicalmodelButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        OsteoModel
        Biom % Description
        radius
        cylinders
        num_muscle
        NvBiom
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, num_muscle)
            for k=1:5
                eval(['app.RadiuscylindermEditField_' num2str(k) 'Label.Visible=''off'';']);
                eval(['app.RadiuscylindermEditField_' num2str(k) '.Visible=''off'';']);
            end
            app.UITable2_2.Visible='off';
            app.UITable2_3.Visible='off';
            app.ComputationofmomentarmoptimizationLabel.Visible = 'off';
            app.ComputationofmusculotendonlengthoptimizationLabel.Visible = 'off';
            app.RootmeansquareerrormLabel.Visible='off';
            app.RootmeansquareerrormLabel_2.Visible='off';
            app.NumberofpointsEditField.Value=20;
            app.num_muscle=num_muscle;
            
            load('BiomechanicalModel.mat');
            
            % Find Muscle number
            HumanModel=BiomechanicalModel.OsteoArticularModel;
            Muscles=BiomechanicalModel.Muscles;
            app.NvBiom = BiomechanicalModel;
            app.OsteoModel = HumanModel;
            app.Biom = BiomechanicalModel;
            
            Origin = HumanModel(Muscles(num_muscle).num_solid(1)).anat_position{Muscles(num_muscle).num_markers(1),2} + HumanModel(Muscles(num_muscle).num_solid(1)).c;
            Insertion = HumanModel(Muscles(num_muscle).num_solid(end)).anat_position{Muscles(num_muscle).num_markers(end),2} + HumanModel(Muscles(num_muscle).num_solid(end)).c;
            app.UITable2.Data = [Origin' ; Insertion'];
            
            [sp1,sp2]=find_solid_path(HumanModel,Muscles(num_muscle).num_solid(1),Muscles(num_muscle).num_solid(end));
            visu=find([HumanModel.Visual]);
            
            if length(sp1)~=1 && length(sp2)~=1
                common_ancestor = intersect(sp1,sp2);
                involved_solids = setdiff(unique([sp1,sp2]), common_ancestor);
            else
                involved_solids =unique([sp1,sp2]);
            end
            
            cylinders = intersect(involved_solids,visu);
            app.radius=zeros(length(cylinders),1);
            for k=1:length(cylinders)
                eval(['app.RadiuscylindermEditField_' num2str(k) 'Label.Visible=''on'';']);
                eval(['app.RadiuscylindermEditField_' num2str(k) '.Visible=''on'';']);
                eval(['app.RadiuscylindermEditField_' num2str(k) 'Label.Text= HumanModel(cylinders(k)).name ;']);
                if isfield(HumanModel,'density')
                    temp1=cylinders(k);
                    
                    if HumanModel(temp1).child
                        seglength=norm(HumanModel(HumanModel(temp1).child).b);
                        temp3=temp1;
                        while ~seglength && temp3
                            temp3=HumanModel(temp3).child;
                            seglength=norm(HumanModel(HumanModel(temp3).child).b);
                        end
                        if ~temp3
                            temp3=HumanModel(temp3).mother;
                            [~,numnode]=intersect( HumanModel(temp3).anat_position(:,1),[HumanModel(temp3).name '_EndNode']);
                            seglength = norm(HumanModel(temp3).anat_position{numnode,2});
                        end
                        
                    else
                        [~,numnode]=intersect( HumanModel(temp1).anat_position(:,1),[HumanModel(temp1).name '_EndNode']);
                        seglength = norm(HumanModel(temp1).anat_position{numnode,2});
                    end
                    
                    app.radius(k) = sqrt(HumanModel(temp1).m/(pi*seglength*HumanModel(temp1).density*1000));
                    eval(['app.RadiuscylindermEditField_' num2str(k) '.Value=' num2str(app.radius(k)) ';']);
                else
                    eval(['app.RadiuscylindermEditField_' num2str(k) '.Value= 0.20 ;']);
                end
            end
            
            folder = what('Moment arm literature');
            list_files = dir(folder.path);
            list_files = list_files(4:end);
            list_files = cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false);
            app.MomentarmdataListBox.Items = list_files;
            app.MomentarmdataListBox.ItemsData = list_files;
            app.MomentarmdataListBox.Value = list_files{1};
            
            folder = what('Musculotendon length literature');
            list_files = dir(folder.path);
            list_files = list_files(4:end);
            list_files = cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false);
            app.MusculotendonlengthdataListBox.Items = list_files;
            app.MusculotendonlengthdataListBox.ItemsData = list_files;
            app.MusculotendonlengthdataListBox.Value = list_files{1};

            
            app.SolidpathListBox.Visible = 'on';
            indvisu= find([HumanModel.Visual] ==1);
            app.SolidpathListBox.Items={HumanModel(indvisu).name};
            app.SolidpathListBox.ItemsData=indvisu;
            app.SolidpathListBox.Value=cylinders;
            
            app.cylinders = cylinders;
            
        end

        % Button pushed function: MomentarmoptimizationButton
        function MomentarmoptimizationButtonPushed(app, event)
            app.ComputationofmomentarmoptimizationLabel.Visible = 'on';
            
            
            fullFileName = which('momentarm.gif');
            [I , map]=imread(fullFileName);
            app.ImageAxes.Visible = 'on';
            
            for tours=1:2
                for frameIndex=1:size(I,4)
                     imshow(I(:,:,:,frameIndex),map,'Parent',app.ImageAxes);
                     pause(0.01*(frameIndex+10*(tours -1)))
                end
            end
            
            imshow(I(:,:,:,1),map,'Parent',app.ImageAxes);
            pause(0.0001)
            
            name_func = app.MomentarmdataListBox.Value;
            MomentArmfunction = str2func(name_func);
 
            for k=1:length(app.radius)
                eval(['app.radius(' num2str(k) ') = app.RadiuscylindermEditField_' num2str(k) '.Value;']);
            end
            
            
            if length(app.radius) >2
                 rradius =  app.radius(1);
                 for k = 2:length(app.radius)-1
                   rradius = [rradius app.radius(k) app.radius(k)];
                 end
                 rradius = [rradius app.radius(end)];
            else
                rradius =  app.radius;
            end
            
            
            [RMSE,NvBiomechanicalModel]=MomentArmOptimization(app.num_muscle,app.Biom,MomentArmfunction,app.NumberofpointsEditField.Value,rradius,app.cylinders);
            for frameIndex=1:size(I,4)
                 imshow(I(:,:,:,frameIndex),map,'Parent',app.ImageAxes);
                 pause(0.1)
            end
            
            app.ComputationofmomentarmoptimizationLabel.Visible = 'off';
            app.ImageAxes.Visible = 'off';
            delete(app.ImageAxes.Children);

            app.RootmeansquareerrormLabel.Visible = 'on';
            app.RootmeansquareerrormLabel.Text = ['Root mean square error(m, %) : ' num2str(RMSE.rms,'%3.4f') '('  num2str(RMSE.rmsr, '%3.4f') ')'];
            
            
            app.UITable2_3.Data = [];
            app.UITable2_3.RowName = [];
            app.UITable2_3.Visible = 'on';
            for vp = 2:length(NvBiomechanicalModel.Muscles(app.num_muscle).num_solid)-1
                VPPosition = NvBiomechanicalModel.OsteoArticularModel(NvBiomechanicalModel.Muscles(app.num_muscle).num_solid(vp)).anat_position{NvBiomechanicalModel.Muscles(app.num_muscle).num_markers(vp),2} + NvBiomechanicalModel.OsteoArticularModel(NvBiomechanicalModel.Muscles(app.num_muscle).num_solid(vp)).c;
                app.UITable2_3.Data = [app.UITable2_3.Data ; VPPosition'];
                app.UITable2_3.RowName = [app.UITable2_3.RowName',NvBiomechanicalModel.OsteoArticularModel(NvBiomechanicalModel.Muscles(app.num_muscle).num_solid(vp)).anat_position(NvBiomechanicalModel.Muscles(app.num_muscle).num_markers(vp),1) ];
            end
            
            app.NvBiom = NvBiomechanicalModel;
            
        end

        % Button pushed function: 
        % AddmusclepathtobiomechanicalmodelButton
        function AddmusclepathtobiomechanicalmodelButtonPushed(app, event)
            name_mus = app.Biom.Muscles(app.num_muscle).name;
            name_mus = name_mus(2:end);
            solid_interet=app.NvBiom.Muscles(app.num_muscle).num_solid;
            markers_interet = app.NvBiom.Muscles(app.num_muscle).num_markers;
            
            %Left side

            
            Mirror=[1 0 0; 0 1 0; 0 0 -1]; 
            
            [~,num_Lmuscle]=intersect({app.Biom.Muscles.name},['L',name_mus]);
            
            if ~isempty(num_Lmuscle)
            
            solid_Linteret = solid_interet + (app.Biom.Muscles(num_Lmuscle).num_solid(1) - solid_interet(1));
            
            app.NvBiom.Muscles(num_Lmuscle).path=[];  
            app.NvBiom.Muscles(num_Lmuscle).num_solid = solid_interet';  
            app.NvBiom.Muscles(num_Lmuscle).num_markers = markers_interet';  
            app.NvBiom.Muscles(num_Lmuscle).path{1} = app.Biom.OsteoArticularModel(solid_interet(1)).anat_position{markers_interet(1),1}; 
            posO = app.NvBiom.OsteoArticularModel(solid_interet(1)).anat_position{markers_interet(1), 2};
            app.NvBiom.OsteoArticularModel(solid_Linteret(1)).anat_position{markers_interet(1),2}= Mirror*posO ;  
             for count=2:length(solid_interet)-1
                name_joint = app.NvBiom.OsteoArticularModel(solid_Linteret(count)).name;
                posVP = app.NvBiom.OsteoArticularModel(solid_interet(count)).anat_position{markers_interet(count), 2};
                VP_name= [name_joint,'_',name_mus,'_VP',num2str(count-1)];
                app.NvBiom.Muscles(num_Lmuscle).path{end+1}= VP_name;
                app.NvBiom.OsteoArticularModel(solid_Linteret(count)).anat_position{markers_interet(count), 1}=   VP_name;
                app.NvBiom.OsteoArticularModel(solid_Linteret(count)).anat_position{markers_interet(count), 2}= Mirror*posVP;
             end
            app.NvBiom.Muscles(num_Lmuscle).path{end+1} =app.Biom.OsteoArticularModel(solid_Linteret(end)).anat_position{markers_interet(end),1};  
            posI = app.NvBiom.OsteoArticularModel(solid_interet(end)).anat_position{markers_interet(end), 2};
            app.NvBiom.OsteoArticularModel(solid_Linteret(end)).anat_position{markers_interet(end),2}= Mirror*posI ;  
            
            
            end
            
            BiomechanicalModel = app.NvBiom;


            save('BiomechanicalModel.mat','BiomechanicalModel');
        end

        % Button pushed function: GeneratescriptButton
        function GeneratescriptButtonPushed(app, event)
            solid_interet=app.NvBiom.Muscles(app.num_muscle).num_solid;
            markers_interet = app.NvBiom.Muscles(app.num_muscle).num_markers;
            load('ModelParameters.mat') ;
            fileID = fopen('AddMusclesPaths.m','a');
            
            name_mus = app.Biom.Muscles(app.num_muscle).name;
            name_mus = name_mus(2:end);
            fprintf(fileID,'%% Adding %s muscle \n \n ', name_mus );
            
            fprintf(fileID,'load(''ModelParameters.mat'')  \n');
            fprintf(fileID,'load(''BiomechanicalModel.mat'')\n');
            
            fprintf(fileID,'kcalib = ModelParameters.Size/1.80;  \n' );
            
            fprintf(fileID,'%% Right side \n ' );
            
            fprintf(fileID,'Mirror=[1 0 0; 0 1 0; 0 0 1]; \n' );
            
            fprintf(fileID,'num_muscle = %d; \n', app.num_muscle );
            text_solid_interet = 'solid_interet = [';
            text_markers_interet = 'markers_interet = [ ';
            for count=1:length(solid_interet)
                text_solid_interet = [text_solid_interet, '%d '];
                text_markers_interet = [text_markers_interet, '%d '];
            end
            text_solid_interet = [text_solid_interet, ']; \n' ];
            text_markers_interet = [text_markers_interet, ']; \n'];
            
            fprintf(fileID,text_solid_interet,solid_interet);
            fprintf(fileID,text_markers_interet,markers_interet);
            
            fprintf(fileID,'BiomechanicalModel.Muscles(num_muscle).path=[];  \n');
            fprintf(fileID,'BiomechanicalModel.Muscles(num_muscle).num_solid = solid_interet'';  \n');
            fprintf(fileID,'BiomechanicalModel.Muscles(num_muscle).num_markers = markers_interet'';  \n');
            
            fprintf(fileID, 'BiomechanicalModel.Muscles(num_muscle).path{1} = ''%s'';  \n',app.Biom.OsteoArticularModel(solid_interet(1)).anat_position{markers_interet(1), 1});
            posO = app.NvBiom.OsteoArticularModel(solid_interet(1)).anat_position{markers_interet(1), 2}/(ModelParameters.Size/1.80);
            fprintf(fileID, 'BiomechanicalModel.OsteoArticularModel(solid_interet(1)).anat_position{markers_interet(1),2}= kcalib*Mirror*[%6.4f ; %6.4f ; %6.4f] ;  \n',posO(1), posO(2), posO(3));
            
            for count=2:length(solid_interet)-1
                name_joint = app.NvBiom.OsteoArticularModel(solid_interet(count)).name;
                posVP = app.NvBiom.OsteoArticularModel(solid_interet(count)).anat_position{markers_interet(count), 2}/(ModelParameters.Size/1.80);
                VP_name= [name_joint,'_',name_mus,'_VP',num2str(count-1)];
                fprintf(fileID, 'BiomechanicalModel.Muscles(num_muscle).path{end+1}= ''%s'' ; \n',VP_name);
                fprintf(fileID, 'BiomechanicalModel.OsteoArticularModel(solid_interet(%d)).anat_position{markers_interet(%d), 1}= ''%s''  ;\n',count, count, VP_name);
                fprintf(fileID, 'BiomechanicalModel.OsteoArticularModel(solid_interet(%d)).anat_position{markers_interet(%d), 2}= kcalib*Mirror*[%6.4f ; %6.4f ; %6.4f]; \n',count, count, posVP(1),posVP(2),posVP(3) );
            end
            
            fprintf(fileID, 'BiomechanicalModel.Muscles(num_muscle).path{end+1} = ''%s'';  \n',app.Biom.OsteoArticularModel(solid_interet(end)).anat_position{markers_interet(end), 1});
            posI = app.NvBiom.OsteoArticularModel(solid_interet(end)).anat_position{markers_interet(end), 2}/(ModelParameters.Size/1.80);
            fprintf(fileID, 'BiomechanicalModel.OsteoArticularModel(solid_interet(end)).anat_position{markers_interet(end),2}= kcalib*Mirror*[%6.4f ; %6.4f ; %6.4f] ;  \n',posI(1), posI(2), posI(3));
            
            fprintf(fileID,'%% Left side \n ' );
            
            
            [~,num_Lmuscle]=intersect({app.Biom.Muscles.name},['L',name_mus]);
            
            if ~isempty(num_Lmuscle)
            
            solid_Linteret = solid_interet + (app.Biom.Muscles(num_Lmuscle).num_solid(1) - solid_interet(1));
            
            
            fprintf(fileID,'Mirror=[1 0 0; 0 1 0; 0 0 -1]; \n' );
            fprintf(fileID,'num_muscle = %d; \n', num_Lmuscle );
            text_solid_interet = 'solid_interet = [';
            text_markers_interet = 'markers_interet = [ ';
            for count=1:length(solid_interet)
                text_solid_interet = [text_solid_interet, '%d '];
                text_markers_interet = [text_markers_interet, '%d '];
            end
            text_solid_interet = [text_solid_interet, ']; \n' ];
            text_markers_interet = [text_markers_interet, ']; \n'];
            
            fprintf(fileID,text_solid_interet,solid_Linteret);
            fprintf(fileID,text_markers_interet,markers_interet);
            
            fprintf(fileID,'BiomechanicalModel.Muscles(num_muscle).path=[];  \n');
            fprintf(fileID,'BiomechanicalModel.Muscles(num_muscle).num_solid = solid_interet'';  \n');
            fprintf(fileID,'BiomechanicalModel.Muscles(num_muscle).num_markers = markers_interet'';  \n');
            
            fprintf(fileID, 'BiomechanicalModel.Muscles(num_muscle).path{1} = ''%s'';  \n',app.NvBiom.OsteoArticularModel(solid_Linteret(1)).anat_position{markers_interet(1), 1});
            posO = app.NvBiom.OsteoArticularModel(solid_interet(1)).anat_position{markers_interet(1), 2}/(ModelParameters.Size/1.80);
            fprintf(fileID, 'BiomechanicalModel.OsteoArticularModel(solid_interet(1)).anat_position{markers_interet(1),2}= kcalib*Mirror*[%6.4f ; %6.4f ; %6.4f] ;  \n',posO(1), posO(2), posO(3));
            
            for count=2:length(solid_interet)-1
                name_joint = app.NvBiom.OsteoArticularModel(solid_Linteret(count)).name;
                posVP = app.NvBiom.OsteoArticularModel(solid_interet(count)).anat_position{markers_interet(count), 2}/(ModelParameters.Size/1.80);
                VP_name= [name_joint,'_',name_mus,'_VP',num2str(count-1)];
                fprintf(fileID, 'BiomechanicalModel.Muscles(num_muscle).path{end+1}= ''%s'' ; \n',VP_name);
                fprintf(fileID, 'BiomechanicalModel.OsteoArticularModel(solid_interet(%d)).anat_position{markers_interet(%d), 1}= ''%s''  ;\n',count, count, VP_name);
                fprintf(fileID, 'BiomechanicalModel.OsteoArticularModel(solid_interet(%d)).anat_position{markers_interet(%d), 2}= kcalib*Mirror*[%6.4f ; %6.4f ; %6.4f]; \n',count, count, posVP(1),posVP(2),posVP(3) );
            end
            
            fprintf(fileID, 'BiomechanicalModel.Muscles(num_muscle).path{end+1} = ''%s'';  \n',app.NvBiom.OsteoArticularModel(solid_Linteret(end)).anat_position{markers_interet(end), 1});
            posI = app.NvBiom.OsteoArticularModel(solid_interet(end)).anat_position{markers_interet(end), 2}/(ModelParameters.Size/1.80);
            fprintf(fileID, 'BiomechanicalModel.OsteoArticularModel(solid_interet(end)).anat_position{markers_interet(end),2}= kcalib*Mirror*[%6.4f ; %6.4f ; %6.4f] ;  \n',posI(1), posI(2), posI(3));
            
            end


            fprintf(fileID,'save(''BiomechanicalModel.mat'',''BiomechanicalModel'') \n');
            
            
            fclose(fileID);
            
            
        end

        % Button pushed function: 
        % MusculotendonlengthoptimizationButton
        function MusculotendonlengthoptimizationButtonPushed(app, event)
            app.ComputationofmusculotendonlengthoptimizationLabel.Visible = 'on';

            fullFileName = which('musculotendonlength.gif');
            [I , map]=imread(fullFileName);
            app.ImageAxes_2.Visible = 'on';
            
            for tours=1:4
                for frameIndex=1:size(I,4)
                     imshow(I(:,:,:,frameIndex),map,'Parent',app.ImageAxes_2);
                     pause(0.01*(frameIndex+10*(tours -1)))
                end
            end
            
            imshow(I(:,:,:,2),map,'Parent',app.ImageAxes);
            pause(0.0001)
            
            
            name_func = app.MusculotendonlengthdataListBox.Value;
            Musculotendonlengthfunction = str2func(name_func);
            
            [RMSElmt,NvBiomechanicalModel]=MusculotendonLengthOptimization(app.num_muscle,app.NvBiom,Musculotendonlengthfunction,app.NumberofpointsEditField.Value);
            app.NvBiom = NvBiomechanicalModel;
            
            for frameIndex=1:size(I,4)
                 imshow(I(:,:,:,frameIndex),map,'Parent',app.ImageAxes);
                 pause(0.1)
            end
            
            app.ComputationofmomentarmoptimizationLabel.Visible = 'off';
            app.ImageAxes_2.Visible = 'off';
            delete(app.ImageAxes_2.Children);
            
            app.RootmeansquareerrormLabel_2.Visible = 'on';
            app.RootmeansquareerrormLabel_2.Text = ['Root mean square error(m, %) : ' num2str(RMSElmt.rms,'%3.4f') '('  num2str(RMSElmt.rmsr, '%3.4f') ')'];
            
            app.ComputationofmusculotendonlengthoptimizationLabel.Visible = 'off';

            
            app.UITable2_2.Data = [];
            app.UITable2_2.Visible = 'on';  
            HumanModel = app.NvBiom.OsteoArticularModel;
            Muscles = app.NvBiom.Muscles;
            
            Origin = HumanModel(Muscles(app.num_muscle).num_solid(1)).anat_position{Muscles(app.num_muscle).num_markers(1),2} + HumanModel(Muscles(app.num_muscle).num_solid(1)).c;
            Insertion = HumanModel(Muscles(app.num_muscle).num_solid(end)).anat_position{Muscles(app.num_muscle).num_markers(end),2} + HumanModel(Muscles(app.num_muscle).num_solid(end)).c;
            app.UITable2_2.Data = [Origin' ; Insertion'];


        end

        % Value changed function: SolidpathListBox
        function SolidpathListBoxValueChanged(app, event)
            app.cylinders = app.SolidpathListBox.Value;
            
            cylinders = app.cylinders;
            HumanModel = app.OsteoModel;
            app.radius=zeros(length(cylinders),1);
            
            
            for k=1:5
                eval(['app.RadiuscylindermEditField_' num2str(k) 'Label.Visible=''off'';']);
                eval(['app.RadiuscylindermEditField_' num2str(k) '.Visible=''off'';']);
            end
            
            
            for k=1:length(cylinders)
                eval(['app.RadiuscylindermEditField_' num2str(k) 'Label.Visible=''on'';']);
                eval(['app.RadiuscylindermEditField_' num2str(k) '.Visible=''on'';']);
                eval(['app.RadiuscylindermEditField_' num2str(k) 'Label.Text= HumanModel(cylinders(k)).name ;']);
                if isfield(HumanModel,'density')
                    temp1=cylinders(k);
                    
                    if HumanModel(temp1).child
                        seglength=norm(HumanModel(HumanModel(temp1).child).b);
                        temp3=temp1;
                        while ~seglength && temp3
                            temp3=HumanModel(temp3).child;
                            seglength=norm(HumanModel(HumanModel(temp3).child).b);
                        end
                        if ~temp3
                            temp3=HumanModel(temp3).mother;
                            [~,numnode]=intersect( HumanModel(temp3).anat_position(:,1),[HumanModel(temp3).name '_EndNode']);
                            seglength = norm(HumanModel(temp3).anat_position{numnode,2});
                        end
                        
                    else
                        [~,numnode]=intersect( HumanModel(temp1).anat_position(:,1),[HumanModel(temp1).name '_EndNode']);
                        seglength = norm(HumanModel(temp1).anat_position{numnode,2});
                    end
                    
                    app.radius(k) = sqrt(HumanModel(temp1).m/(pi*seglength*HumanModel(temp1).density*1000));
                    eval(['app.RadiuscylindermEditField_' num2str(k) '.Value=' num2str(app.radius(k)) ';']);
                else
                    eval(['app.RadiuscylindermEditField_' num2str(k) '.Value= 0.20 ;']);
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MusclePathGeneration = uifigure('Visible', 'off');
            app.MusclePathGeneration.Name = 'UI Figure';
            app.AddmusclepathtobiomechanicalmodelButton = uibutton(app.MusclePathGeneration, 'push');
            app.AddmusclepathtobiomechanicalmodelButton.ButtonPushedFcn = createCallbackFcn(app, @AddmusclepathtobiomechanicalmodelButtonPushed, true);
            app.AddmusclepathtobiomechanicalmodelButton.Text = {'Add muscle path to '; 'biomechanical model'};
            app.GeneratescriptButton = uibutton(app.MusclePathGeneration, 'push');
            app.GeneratescriptButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratescriptButtonPushed, true);
            app.GeneratescriptButton.Text = 'Generate script';
            app.InputsPanel = uipanel(app.MusclePathGeneration);
            app.InputsPanel.Title = 'Inputs';
            app.MomentarmoptimizationButton = uibutton(app.InputsPanel, 'push');
            app.MomentarmoptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @MomentarmoptimizationButtonPushed, true);
            app.MomentarmoptimizationButton.Text = 'Moment arm optimization';
            app.MusculotendonlengthoptimizationButton = uibutton(app.InputsPanel, 'push');
            app.MusculotendonlengthoptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @MusculotendonlengthoptimizationButtonPushed, true);
            app.MusculotendonlengthoptimizationButton.Text = {'Musculotendon length '; 'optimization'};
            app.MomentarmdataListBoxLabel = uilabel(app.InputsPanel);
            app.MomentarmdataListBoxLabel.Text = 'Moment arm data';
            app.MomentarmdataListBox = uilistbox(app.InputsPanel);
            app.MomentarmdataListBox.Value = {};
            app.MusculotendonlengthdataLabel = uilabel(app.InputsPanel);
            app.MusculotendonlengthdataLabel.Text = {'Musculotendon '; 'length data'};
            app.MusculotendonlengthdataListBox = uilistbox(app.InputsPanel);
            app.MusculotendonlengthdataListBox.Value = {};
            app.RadiuscylindermEditField_1Label = uilabel(app.InputsPanel);
            app.RadiuscylindermEditField_1Label.Text = 'Radius cylinder(m)';
            app.RadiuscylindermEditField_1 = uieditfield(app.InputsPanel, 'numeric');
            app.RadiuscylindermEditField_2Label = uilabel(app.InputsPanel);
            app.RadiuscylindermEditField_2Label.Text = 'Radius cylinder(m)';
            app.RadiuscylindermEditField_2 = uieditfield(app.InputsPanel, 'numeric');
            app.RadiuscylindermEditField_3Label = uilabel(app.InputsPanel);
            app.RadiuscylindermEditField_3Label.Text = 'Radius cylinder(m)';
            app.RadiuscylindermEditField_3 = uieditfield(app.InputsPanel, 'numeric');
            app.RadiuscylindermEditField_4Label = uilabel(app.InputsPanel);
            app.RadiuscylindermEditField_4Label.Text = 'Radius cylinder(m)';
            app.RadiuscylindermEditField_4 = uieditfield(app.InputsPanel, 'numeric');
            app.RadiuscylindermEditField_5Label = uilabel(app.InputsPanel);
            app.RadiuscylindermEditField_5Label.Text = 'Radius cylinder(m)';
            app.RadiuscylindermEditField_5 = uieditfield(app.InputsPanel, 'numeric');
            app.UITable2 = uitable(app.InputsPanel);
            app.UITable2.ColumnName = {'x (m)'; 'y (m)'; 'z (m)'};
            app.UITable2.RowName = {'Origin point'; 'Insertion point'; ''; ''};
            app.UITable2.ColumnEditable = true;
            app.CylindersradiimLabel = uilabel(app.InputsPanel);
            app.CylindersradiimLabel.Text = 'Cylinders radii (m)';
            app.NumberofpointsEditFieldLabel = uilabel(app.InputsPanel);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.InputsPanel, 'numeric');
            app.SolidpathListBoxLabel = uilabel(app.InputsPanel);
            app.SolidpathListBoxLabel.Text = 'Solid path';
            app.SolidpathListBox = uilistbox(app.InputsPanel);
            app.SolidpathListBox.Multiselect = 'on';
            app.SolidpathListBox.ValueChangedFcn = createCallbackFcn(app, @SolidpathListBoxValueChanged, true);
            app.SolidpathListBox.Value = {};
            app.OutputsPanel = uipanel(app.MusclePathGeneration);
            app.OutputsPanel.Title = 'Outputs';
            app.ImageAxes = uiaxes(app.OutputsPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.ImageAxes.Visible = 'off';
            app.ImageAxes_2 = uiaxes(app.OutputsPanel);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            app.ImageAxes_2.Visible = 'off';
            app.RootmeansquareerrormLabel = uilabel(app.OutputsPanel);
            app.RootmeansquareerrormLabel.Text = 'Root mean square error(m, %) : ';
            app.RootmeansquareerrormLabel_2 = uilabel(app.OutputsPanel);
            app.RootmeansquareerrormLabel_2.Text = 'Root mean square error(m, %) : ';
            app.ComputationofmomentarmoptimizationLabel = uilabel(app.OutputsPanel);
            app.ComputationofmomentarmoptimizationLabel.Text = {'Computation of moment '; 'arm optimization ...'};
            app.ComputationofmusculotendonlengthoptimizationLabel = uilabel(app.OutputsPanel);
            app.ComputationofmusculotendonlengthoptimizationLabel.Text = {'Computation of musculotendon '; 'length optimization ...'};
            app.UITable2_3 = uitable(app.OutputsPanel);
            app.UITable2_3.ColumnName = {'x (m)'; 'y (m)'; 'z (m)'};
            app.UITable2_3.RowName = {'VP1'; ''};
            app.UITable2_3.ColumnEditable = true;
            app.UITable2_2 = uitable(app.OutputsPanel);
            app.UITable2_2.ColumnName = {'x (m)'; 'y (m)'; 'z (m)'};
            app.UITable2_2.RowName = {'Origin point'; 'Insertion point'; ''; ''};
            app.UITable2_2.ColumnEditable = true;
            % Show the figure after all components are created
            app.MusclePathGeneration.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MusclePathCreation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MusclePathGeneration)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MusclePathGeneration)
        end
    end
end