classdef OptionsCalibID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InertialcalibrationUIFigure  matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        ActivestepCheckBox           matlab.ui.control.CheckBox
        OKButton                     matlab.ui.control.Button
        DropDown                     matlab.ui.control.DropDown
        MethodLabel                  matlab.ui.control.Label
        SymmetryPanel                matlab.ui.container.Panel
        LinkedtoLabel_1              matlab.ui.control.Label
        DropDown_1_1                 matlab.ui.control.DropDown
        Label                        matlab.ui.control.Label
        DropDown_1_2                 matlab.ui.control.DropDown
        DropDown_2_1                 matlab.ui.control.DropDown
        LinkedtoLabel_2              matlab.ui.control.Label
        DropDown_5Label              matlab.ui.control.Label
        DropDown_2_2                 matlab.ui.control.DropDown
        DropDown_3_1                 matlab.ui.control.DropDown
        LinkedtoLabel_3              matlab.ui.control.Label
        DropDown_7Label              matlab.ui.control.Label
        DropDown_3_2                 matlab.ui.control.DropDown
        DropDown_4_1                 matlab.ui.control.DropDown
        LinkedtoLabel_4              matlab.ui.control.Label
        DropDown_9Label              matlab.ui.control.Label
        DropDown_4_2                 matlab.ui.control.DropDown
        DropDown_5_1                 matlab.ui.control.DropDown
        LinkedtoLabel_5              matlab.ui.control.Label
        DropDown_11Label             matlab.ui.control.Label
        DropDown_5_2                 matlab.ui.control.DropDown
        DropDown_6_1                 matlab.ui.control.DropDown
        LinkedtoLabel_6              matlab.ui.control.Label
        DropDown_13Label             matlab.ui.control.Label
        DropDown_6_2                 matlab.ui.control.DropDown
        DropDown_7_1                 matlab.ui.control.DropDown
        LinkedtoLabel_7              matlab.ui.control.Label
        DropDown_15Label             matlab.ui.control.Label
        DropDown_7_2                 matlab.ui.control.DropDown
        DropDown_8_1                 matlab.ui.control.DropDown
        LinkedtoLabel_8              matlab.ui.control.Label
        DropDown_17Label             matlab.ui.control.Label
        DropDown_8_2                 matlab.ui.control.DropDown
        DropDown_9_1                 matlab.ui.control.DropDown
        LinkedtoLabel_9              matlab.ui.control.Label
        DropDown_19Label             matlab.ui.control.Label
        DropDown_9_2                 matlab.ui.control.DropDown
        AddButton                    matlab.ui.control.Button
        DeleteButton                 matlab.ui.control.Button
        NumberofframesLabel          matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        CallingApp
        Human_model % Description
        Markers_set
        AnalysisParameters
        cpt_s
    end

    methods (Access = private)
    
        function ActuateApp(app,OldAnalysisParameters)
            % Active
            app.ActivestepCheckBox.Value = OldAnalysisParameters.CalibID.Active;
            % Method
            app.DropDown.Value = OldAnalysisParameters.CalibID.Method;
            % Nb de frame
            app.EditField.Value = OldAnalysisParameters.CalibID.Frames.NbFrames;
            % Symmetry
            for i=1:size(OldAnalysisParameters.CalibID.Symmetry,1)
               app.cpt_s = app.cpt_s + 1;
               eval(['app.DropDown_' num2str(i) '_1.Visible = ''on'';']);
               eval(['app.DropDown_' num2str(i) '_1.Value = app.Human_model(OldAnalysisParameters.CalibID.Symmetry(' num2str(i) ',1)).name;']);
               eval(['app.LinkedtoLabel_' num2str(i) '.Visible = ''on'';']);
               eval(['app.DropDown_' num2str(i) '_2.Visible = ''on'';']);
               eval(['app.DropDown_' num2str(i) '_2.Value = app.Human_model(OldAnalysisParameters.CalibID.Symmetry(' num2str(i) ',2)).name;']);
            end
            for i=(app.cpt_s+1) : 9
                eval(['app.DropDown_' num2str(i) '_1.Visible = ''off'';']);
                eval(['app.LinkedtoLabel_' num2str(i) '.Visible = ''off'';']);
                eval(['app.DropDown_' num2str(i) '_2.Visible = ''off'';']);
            end
            % Active
            app.ActivestepCheckBoxValueChanged;
        end
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            % Active
            OldAnalysisParameters.CalibID.Active = app.ActivestepCheckBox.Value;
            % Method
            OldAnalysisParameters.CalibID.Method = app.DropDown.Value;
            % Nb de frame
            OldAnalysisParameters.CalibID.Frames.NbFrames = app.EditField.Value;
            % Symmetry
            OldAnalysisParameters.CalibID.Symmetry = [];
            for i=1:app.cpt_s
                [~,~,num_solid1]=eval(['intersect(app.DropDown_' num2str(i) '_1.Value,{app.Human_model.name});']);
                [~,~,num_solid2]=eval(['intersect(app.DropDown_' num2str(i) '_2.Value,{app.Human_model.name});']);
                OldAnalysisParameters.CalibID.Symmetry(i,:) = [num_solid1, num_solid2];
            end
            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Human_model, Markers_set, OldAnalysisParameters)
            app.CallingApp = mainapp;
            app.Human_model = Human_model;
            app.Markers_set = Markers_set;
            % Method
            app.DropDown.Items = {'Stadium solid'};
            % Symmetry
            list_solid = find([app.Human_model.Visual]);
            app.cpt_s = 0;
            for i=1:9
                eval(['app.DropDown_' num2str(i) '_1.Items = {Human_model(list_solid).name};'])
                eval(['app.DropDown_' num2str(i) '_2.Items = {Human_model(list_solid).name};'])
            end
            % Actualisation des paramètres
            ActuateApp(app,OldAnalysisParameters);
            app.AnalysisParameters = OldAnalysisParameters;
        end

        % Close request function: InertialcalibrationUIFigure
        function InertialcalibrationUIFigureCloseRequest(app, event)
            EnableButton5(app.CallingApp);
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveParameters(app.CallingApp,app.AnalysisParameters,app.Human_model,app.Markers_set);
            app.InertialcalibrationUIFigureCloseRequest;
        end

        % Value changed function: ActivestepCheckBox
        function ActivestepCheckBoxValueChanged(app, event)
          if app.ActivestepCheckBox.Value
               app.MethodLabel.Visible = 'on'; % actif
               app.DropDown.Visible = 'on';
               app.NumberofframesLabel.Visible = 'on';
               app.EditField.Visible = 'on';
               app.SymmetryPanel.Visible = 'on';
           else
               app.MethodLabel.Visible = 'off'; % inactif
               app.DropDown.Visible = 'off';
               app.NumberofframesLabel.Visible = 'off';
               app.EditField.Visible = 'off';
               app.SymmetryPanel.Visible = 'off';
           end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if app.cpt_s < 9
                app.cpt_s = app.cpt_s +1;
                eval(['app.DropDown_' num2str(app.cpt_s) '_1.Visible = ''on'';']);
                eval(['app.LinkedtoLabel_' num2str(app.cpt_s) '.Visible = ''on'';']);
                eval(['app.DropDown_' num2str(app.cpt_s) '_2.Visible = ''on'';']);
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if app.cpt_s
                eval(['app.DropDown_' num2str(app.cpt_s) '_1.Visible = ''off'';']);
                eval(['app.LinkedtoLabel_' num2str(app.cpt_s) '.Visible = ''off'';']);
                eval(['app.DropDown_' num2str(app.cpt_s) '_2.Visible = ''off'';']);
                app.cpt_s = app.cpt_s -1;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InertialcalibrationUIFigure = uifigure;
            app.InertialcalibrationUIFigure.Name = 'Inertial calibration';
            app.InertialcalibrationUIFigure.CloseRequestFcn = createCallbackFcn(app, @InertialcalibrationUIFigureCloseRequest, true);
            app.Panel = uipanel(app.InertialcalibrationUIFigure);
            app.ActivestepCheckBox = uicheckbox(app.Panel);
            app.ActivestepCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivestepCheckBoxValueChanged, true);
            app.ActivestepCheckBox.Text = '   Active step';
            app.ActivestepCheckBox.Value = true;
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.DropDown = uidropdown(app.InertialcalibrationUIFigure);
            app.MethodLabel = uilabel(app.InertialcalibrationUIFigure);
            app.MethodLabel.Text = 'Method';
            app.SymmetryPanel = uipanel(app.InertialcalibrationUIFigure);
            app.SymmetryPanel.Title = ' Symmetry';
            app.LinkedtoLabel_1 = uilabel(app.SymmetryPanel);
            app.LinkedtoLabel_1.Text = 'Linked to';
            app.DropDown_1_1 = uidropdown(app.SymmetryPanel);
            app.Label = uilabel(app.SymmetryPanel);
            app.Label.Text = '';
            app.DropDown_1_2 = uidropdown(app.SymmetryPanel);
            app.DropDown_2_1 = uidropdown(app.SymmetryPanel);
            app.LinkedtoLabel_2 = uilabel(app.SymmetryPanel);
            app.LinkedtoLabel_2.Text = 'Linked to';
            app.DropDown_5Label = uilabel(app.SymmetryPanel);
            app.DropDown_5Label.Text = '';
            app.DropDown_2_2 = uidropdown(app.SymmetryPanel);
            app.DropDown_3_1 = uidropdown(app.SymmetryPanel);
            app.LinkedtoLabel_3 = uilabel(app.SymmetryPanel);
            app.LinkedtoLabel_3.Text = 'Linked to';
            app.DropDown_7Label = uilabel(app.SymmetryPanel);
            app.DropDown_7Label.Text = '';
            app.DropDown_3_2 = uidropdown(app.SymmetryPanel);
            app.DropDown_4_1 = uidropdown(app.SymmetryPanel);
            app.LinkedtoLabel_4 = uilabel(app.SymmetryPanel);
            app.LinkedtoLabel_4.Text = 'Linked to';
            app.DropDown_9Label = uilabel(app.SymmetryPanel);
            app.DropDown_9Label.Text = '';
            app.DropDown_4_2 = uidropdown(app.SymmetryPanel);
            app.DropDown_5_1 = uidropdown(app.SymmetryPanel);
            app.LinkedtoLabel_5 = uilabel(app.SymmetryPanel);
            app.LinkedtoLabel_5.Text = 'Linked to';
            app.DropDown_11Label = uilabel(app.SymmetryPanel);
            app.DropDown_11Label.Text = '';
            app.DropDown_5_2 = uidropdown(app.SymmetryPanel);
            app.DropDown_6_1 = uidropdown(app.SymmetryPanel);
            app.LinkedtoLabel_6 = uilabel(app.SymmetryPanel);
            app.LinkedtoLabel_6.Text = 'Linked to';
            app.DropDown_13Label = uilabel(app.SymmetryPanel);
            app.DropDown_13Label.Text = '';
            app.DropDown_6_2 = uidropdown(app.SymmetryPanel);
            app.DropDown_7_1 = uidropdown(app.SymmetryPanel);
            app.LinkedtoLabel_7 = uilabel(app.SymmetryPanel);
            app.LinkedtoLabel_7.Text = 'Linked to';
            app.DropDown_15Label = uilabel(app.SymmetryPanel);
            app.DropDown_15Label.Text = '';
            app.DropDown_7_2 = uidropdown(app.SymmetryPanel);
            app.DropDown_8_1 = uidropdown(app.SymmetryPanel);
            app.LinkedtoLabel_8 = uilabel(app.SymmetryPanel);
            app.LinkedtoLabel_8.Text = 'Linked to';
            app.DropDown_17Label = uilabel(app.SymmetryPanel);
            app.DropDown_17Label.Text = '';
            app.DropDown_8_2 = uidropdown(app.SymmetryPanel);
            app.DropDown_9_1 = uidropdown(app.SymmetryPanel);
            app.LinkedtoLabel_9 = uilabel(app.SymmetryPanel);
            app.LinkedtoLabel_9.Text = 'Linked to';
            app.DropDown_19Label = uilabel(app.SymmetryPanel);
            app.DropDown_19Label.Text = '';
            app.DropDown_9_2 = uidropdown(app.SymmetryPanel);
            app.AddButton = uibutton(app.SymmetryPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteButton = uibutton(app.SymmetryPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.NumberofframesLabel = uilabel(app.InertialcalibrationUIFigure);
            app.NumberofframesLabel.Text = 'Number of frames';
            app.EditField = uieditfield(app.InertialcalibrationUIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = OptionsCalibID(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InertialcalibrationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InertialcalibrationUIFigure)
        end
    end
end