classdef OptionsCalibIK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeometricalcalibrationUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FramesusedTab                   matlab.ui.container.Tab
        FileswithstaticposesLabel       matlab.ui.control.Label
        SameascalibrationCheckBox       matlab.ui.control.CheckBox
        ScapulalocatorfilesforcalibrationLabel  matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        ListBoxLabel                    matlab.ui.control.Label
        NumberofframesEditField         matlab.ui.control.NumericEditField
        NumberofframesEditFieldLabel    matlab.ui.control.Label
        SelectionmethodofframesDropDown  matlab.ui.control.DropDown
        SelectionmethodofframesDropDownLabel  matlab.ui.control.Label
        BodylengthTab                   matlab.ui.container.Tab
        DeleteButton_13                 matlab.ui.control.Button
        DropDown_12_2                   matlab.ui.control.DropDown
        DropDown_25Label                matlab.ui.control.Label
        LinkedtoLabel_12                matlab.ui.control.Label
        DropDown_12_1                   matlab.ui.control.DropDown
        DropDown_11_2                   matlab.ui.control.DropDown
        DropDown_23Label                matlab.ui.control.Label
        LinkedtoLabel_11                matlab.ui.control.Label
        DropDown_11_1                   matlab.ui.control.DropDown
        DropDown_10_2                   matlab.ui.control.DropDown
        DropDown_21Label                matlab.ui.control.Label
        LinkedtoLabel_10                matlab.ui.control.Label
        DropDown_10_1                   matlab.ui.control.DropDown
        DropDown_9_2                    matlab.ui.control.DropDown
        DropDown_19Label                matlab.ui.control.Label
        LinkedtoLabel_9                 matlab.ui.control.Label
        DropDown_9_1                    matlab.ui.control.DropDown
        DropDown_8_2                    matlab.ui.control.DropDown
        DropDown_17Label                matlab.ui.control.Label
        LinkedtoLabel_8                 matlab.ui.control.Label
        DropDown_8_1                    matlab.ui.control.DropDown
        DropDown_7_2                    matlab.ui.control.DropDown
        DropDown_15Label                matlab.ui.control.Label
        LinkedtoLabel_7                 matlab.ui.control.Label
        DropDown_7_1                    matlab.ui.control.DropDown
        DropDown_6_2                    matlab.ui.control.DropDown
        DropDown_13Label                matlab.ui.control.Label
        LinkedtoLabel_6                 matlab.ui.control.Label
        DropDown_6_1                    matlab.ui.control.DropDown
        DropDown_5_2                    matlab.ui.control.DropDown
        DropDown_11Label                matlab.ui.control.Label
        LinkedtoLabel_5                 matlab.ui.control.Label
        DropDown_5_1                    matlab.ui.control.DropDown
        DropDown_4_2                    matlab.ui.control.DropDown
        DropDown_9Label                 matlab.ui.control.Label
        LinkedtoLabel_4                 matlab.ui.control.Label
        DropDown_4_1                    matlab.ui.control.DropDown
        DropDown_3_2                    matlab.ui.control.DropDown
        DropDown_7Label                 matlab.ui.control.Label
        LinkedtoLabel_3                 matlab.ui.control.Label
        DropDown_3_1                    matlab.ui.control.DropDown
        DropDown_2_2                    matlab.ui.control.DropDown
        DropDown_5Label                 matlab.ui.control.Label
        LinkedtoLabel_2                 matlab.ui.control.Label
        DropDown_2_1                    matlab.ui.control.DropDown
        DropDown_1_1                    matlab.ui.control.DropDown
        AddButton                       matlab.ui.control.Button
        LinkedtoLabel_1                 matlab.ui.control.Label
        DropDown_1_2                    matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        MarkerpositionTab               matlab.ui.container.Tab
        ZLabel_3                        matlab.ui.control.Label
        YLabel_3                        matlab.ui.control.Label
        XLabel_3                        matlab.ui.control.Label
        MarkerLabel_60                  matlab.ui.control.Label
        CheckBox_180                    matlab.ui.control.CheckBox
        CheckBox_179                    matlab.ui.control.CheckBox
        CheckBox_178                    matlab.ui.control.CheckBox
        MarkerLabel_59                  matlab.ui.control.Label
        CheckBox_177                    matlab.ui.control.CheckBox
        CheckBox_176                    matlab.ui.control.CheckBox
        CheckBox_175                    matlab.ui.control.CheckBox
        MarkerLabel_58                  matlab.ui.control.Label
        CheckBox_174                    matlab.ui.control.CheckBox
        CheckBox_173                    matlab.ui.control.CheckBox
        CheckBox_172                    matlab.ui.control.CheckBox
        MarkerLabel_57                  matlab.ui.control.Label
        CheckBox_171                    matlab.ui.control.CheckBox
        CheckBox_170                    matlab.ui.control.CheckBox
        CheckBox_169                    matlab.ui.control.CheckBox
        MarkerLabel_56                  matlab.ui.control.Label
        CheckBox_168                    matlab.ui.control.CheckBox
        CheckBox_167                    matlab.ui.control.CheckBox
        CheckBox_166                    matlab.ui.control.CheckBox
        MarkerLabel_55                  matlab.ui.control.Label
        CheckBox_165                    matlab.ui.control.CheckBox
        CheckBox_164                    matlab.ui.control.CheckBox
        CheckBox_163                    matlab.ui.control.CheckBox
        MarkerLabel_54                  matlab.ui.control.Label
        CheckBox_162                    matlab.ui.control.CheckBox
        CheckBox_161                    matlab.ui.control.CheckBox
        CheckBox_160                    matlab.ui.control.CheckBox
        MarkerLabel_53                  matlab.ui.control.Label
        CheckBox_159                    matlab.ui.control.CheckBox
        CheckBox_158                    matlab.ui.control.CheckBox
        CheckBox_157                    matlab.ui.control.CheckBox
        MarkerLabel_52                  matlab.ui.control.Label
        CheckBox_156                    matlab.ui.control.CheckBox
        CheckBox_155                    matlab.ui.control.CheckBox
        CheckBox_154                    matlab.ui.control.CheckBox
        MarkerLabel_51                  matlab.ui.control.Label
        CheckBox_153                    matlab.ui.control.CheckBox
        CheckBox_152                    matlab.ui.control.CheckBox
        CheckBox_151                    matlab.ui.control.CheckBox
        MarkerLabel_50                  matlab.ui.control.Label
        CheckBox_150                    matlab.ui.control.CheckBox
        CheckBox_149                    matlab.ui.control.CheckBox
        CheckBox_148                    matlab.ui.control.CheckBox
        MarkerLabel_49                  matlab.ui.control.Label
        CheckBox_147                    matlab.ui.control.CheckBox
        CheckBox_146                    matlab.ui.control.CheckBox
        CheckBox_145                    matlab.ui.control.CheckBox
        MarkerLabel_48                  matlab.ui.control.Label
        CheckBox_144                    matlab.ui.control.CheckBox
        CheckBox_143                    matlab.ui.control.CheckBox
        CheckBox_142                    matlab.ui.control.CheckBox
        MarkerLabel_47                  matlab.ui.control.Label
        CheckBox_141                    matlab.ui.control.CheckBox
        CheckBox_140                    matlab.ui.control.CheckBox
        CheckBox_139                    matlab.ui.control.CheckBox
        MarkerLabel_46                  matlab.ui.control.Label
        CheckBox_138                    matlab.ui.control.CheckBox
        CheckBox_137                    matlab.ui.control.CheckBox
        CheckBox_136                    matlab.ui.control.CheckBox
        MarkerLabel_45                  matlab.ui.control.Label
        CheckBox_135                    matlab.ui.control.CheckBox
        CheckBox_134                    matlab.ui.control.CheckBox
        CheckBox_133                    matlab.ui.control.CheckBox
        MarkerLabel_44                  matlab.ui.control.Label
        CheckBox_132                    matlab.ui.control.CheckBox
        CheckBox_131                    matlab.ui.control.CheckBox
        CheckBox_130                    matlab.ui.control.CheckBox
        MarkerLabel_43                  matlab.ui.control.Label
        CheckBox_129                    matlab.ui.control.CheckBox
        CheckBox_128                    matlab.ui.control.CheckBox
        CheckBox_127                    matlab.ui.control.CheckBox
        MarkerLabel_42                  matlab.ui.control.Label
        CheckBox_126                    matlab.ui.control.CheckBox
        CheckBox_125                    matlab.ui.control.CheckBox
        CheckBox_124                    matlab.ui.control.CheckBox
        MarkerLabel_41                  matlab.ui.control.Label
        CheckBox_123                    matlab.ui.control.CheckBox
        CheckBox_122                    matlab.ui.control.CheckBox
        CheckBox_121                    matlab.ui.control.CheckBox
        ZLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        MarkerLabel_40                  matlab.ui.control.Label
        CheckBox_120                    matlab.ui.control.CheckBox
        CheckBox_119                    matlab.ui.control.CheckBox
        CheckBox_118                    matlab.ui.control.CheckBox
        MarkerLabel_39                  matlab.ui.control.Label
        CheckBox_117                    matlab.ui.control.CheckBox
        CheckBox_116                    matlab.ui.control.CheckBox
        CheckBox_115                    matlab.ui.control.CheckBox
        MarkerLabel_38                  matlab.ui.control.Label
        CheckBox_114                    matlab.ui.control.CheckBox
        CheckBox_113                    matlab.ui.control.CheckBox
        CheckBox_112                    matlab.ui.control.CheckBox
        MarkerLabel_37                  matlab.ui.control.Label
        CheckBox_111                    matlab.ui.control.CheckBox
        CheckBox_110                    matlab.ui.control.CheckBox
        CheckBox_109                    matlab.ui.control.CheckBox
        MarkerLabel_36                  matlab.ui.control.Label
        CheckBox_108                    matlab.ui.control.CheckBox
        CheckBox_107                    matlab.ui.control.CheckBox
        CheckBox_106                    matlab.ui.control.CheckBox
        MarkerLabel_35                  matlab.ui.control.Label
        CheckBox_105                    matlab.ui.control.CheckBox
        CheckBox_104                    matlab.ui.control.CheckBox
        CheckBox_103                    matlab.ui.control.CheckBox
        MarkerLabel_34                  matlab.ui.control.Label
        CheckBox_102                    matlab.ui.control.CheckBox
        CheckBox_101                    matlab.ui.control.CheckBox
        CheckBox_100                    matlab.ui.control.CheckBox
        MarkerLabel_33                  matlab.ui.control.Label
        CheckBox_99                     matlab.ui.control.CheckBox
        CheckBox_98                     matlab.ui.control.CheckBox
        CheckBox_97                     matlab.ui.control.CheckBox
        MarkerLabel_32                  matlab.ui.control.Label
        CheckBox_96                     matlab.ui.control.CheckBox
        CheckBox_95                     matlab.ui.control.CheckBox
        CheckBox_94                     matlab.ui.control.CheckBox
        MarkerLabel_31                  matlab.ui.control.Label
        CheckBox_93                     matlab.ui.control.CheckBox
        CheckBox_92                     matlab.ui.control.CheckBox
        CheckBox_91                     matlab.ui.control.CheckBox
        MarkerLabel_30                  matlab.ui.control.Label
        CheckBox_90                     matlab.ui.control.CheckBox
        CheckBox_89                     matlab.ui.control.CheckBox
        CheckBox_88                     matlab.ui.control.CheckBox
        MarkerLabel_29                  matlab.ui.control.Label
        CheckBox_87                     matlab.ui.control.CheckBox
        CheckBox_86                     matlab.ui.control.CheckBox
        CheckBox_85                     matlab.ui.control.CheckBox
        MarkerLabel_28                  matlab.ui.control.Label
        CheckBox_84                     matlab.ui.control.CheckBox
        CheckBox_83                     matlab.ui.control.CheckBox
        CheckBox_82                     matlab.ui.control.CheckBox
        MarkerLabel_27                  matlab.ui.control.Label
        CheckBox_81                     matlab.ui.control.CheckBox
        CheckBox_80                     matlab.ui.control.CheckBox
        CheckBox_79                     matlab.ui.control.CheckBox
        MarkerLabel_26                  matlab.ui.control.Label
        CheckBox_78                     matlab.ui.control.CheckBox
        CheckBox_77                     matlab.ui.control.CheckBox
        CheckBox_76                     matlab.ui.control.CheckBox
        MarkerLabel_25                  matlab.ui.control.Label
        CheckBox_75                     matlab.ui.control.CheckBox
        CheckBox_74                     matlab.ui.control.CheckBox
        CheckBox_73                     matlab.ui.control.CheckBox
        MarkerLabel_24                  matlab.ui.control.Label
        CheckBox_72                     matlab.ui.control.CheckBox
        CheckBox_71                     matlab.ui.control.CheckBox
        CheckBox_70                     matlab.ui.control.CheckBox
        MarkerLabel_23                  matlab.ui.control.Label
        CheckBox_69                     matlab.ui.control.CheckBox
        CheckBox_68                     matlab.ui.control.CheckBox
        CheckBox_67                     matlab.ui.control.CheckBox
        MarkerLabel_22                  matlab.ui.control.Label
        CheckBox_66                     matlab.ui.control.CheckBox
        CheckBox_65                     matlab.ui.control.CheckBox
        CheckBox_64                     matlab.ui.control.CheckBox
        MarkerLabel_21                  matlab.ui.control.Label
        CheckBox_63                     matlab.ui.control.CheckBox
        CheckBox_62                     matlab.ui.control.CheckBox
        CheckBox_61                     matlab.ui.control.CheckBox
        MarkerLabel_1                   matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        MarkerLabel_20                  matlab.ui.control.Label
        CheckBox_60                     matlab.ui.control.CheckBox
        CheckBox_59                     matlab.ui.control.CheckBox
        CheckBox_58                     matlab.ui.control.CheckBox
        MarkerLabel_19                  matlab.ui.control.Label
        CheckBox_57                     matlab.ui.control.CheckBox
        CheckBox_56                     matlab.ui.control.CheckBox
        CheckBox_55                     matlab.ui.control.CheckBox
        MarkerLabel_18                  matlab.ui.control.Label
        CheckBox_54                     matlab.ui.control.CheckBox
        CheckBox_53                     matlab.ui.control.CheckBox
        CheckBox_52                     matlab.ui.control.CheckBox
        MarkerLabel_17                  matlab.ui.control.Label
        CheckBox_51                     matlab.ui.control.CheckBox
        CheckBox_50                     matlab.ui.control.CheckBox
        CheckBox_49                     matlab.ui.control.CheckBox
        MarkerLabel_16                  matlab.ui.control.Label
        CheckBox_48                     matlab.ui.control.CheckBox
        CheckBox_47                     matlab.ui.control.CheckBox
        CheckBox_46                     matlab.ui.control.CheckBox
        MarkerLabel_15                  matlab.ui.control.Label
        CheckBox_45                     matlab.ui.control.CheckBox
        CheckBox_44                     matlab.ui.control.CheckBox
        CheckBox_43                     matlab.ui.control.CheckBox
        MarkerLabel_14                  matlab.ui.control.Label
        CheckBox_42                     matlab.ui.control.CheckBox
        CheckBox_41                     matlab.ui.control.CheckBox
        CheckBox_40                     matlab.ui.control.CheckBox
        MarkerLabel_13                  matlab.ui.control.Label
        CheckBox_39                     matlab.ui.control.CheckBox
        CheckBox_38                     matlab.ui.control.CheckBox
        CheckBox_37                     matlab.ui.control.CheckBox
        MarkerLabel_12                  matlab.ui.control.Label
        CheckBox_36                     matlab.ui.control.CheckBox
        CheckBox_35                     matlab.ui.control.CheckBox
        CheckBox_34                     matlab.ui.control.CheckBox
        MarkerLabel_11                  matlab.ui.control.Label
        CheckBox_33                     matlab.ui.control.CheckBox
        CheckBox_32                     matlab.ui.control.CheckBox
        CheckBox_31                     matlab.ui.control.CheckBox
        MarkerLabel_10                  matlab.ui.control.Label
        CheckBox_30                     matlab.ui.control.CheckBox
        CheckBox_29                     matlab.ui.control.CheckBox
        CheckBox_28                     matlab.ui.control.CheckBox
        MarkerLabel_9                   matlab.ui.control.Label
        CheckBox_27                     matlab.ui.control.CheckBox
        CheckBox_26                     matlab.ui.control.CheckBox
        CheckBox_25                     matlab.ui.control.CheckBox
        MarkerLabel_8                   matlab.ui.control.Label
        CheckBox_24                     matlab.ui.control.CheckBox
        CheckBox_23                     matlab.ui.control.CheckBox
        CheckBox_22                     matlab.ui.control.CheckBox
        MarkerLabel_7                   matlab.ui.control.Label
        CheckBox_21                     matlab.ui.control.CheckBox
        CheckBox_20                     matlab.ui.control.CheckBox
        CheckBox_19                     matlab.ui.control.CheckBox
        MarkerLabel_6                   matlab.ui.control.Label
        CheckBox_18                     matlab.ui.control.CheckBox
        CheckBox_17                     matlab.ui.control.CheckBox
        CheckBox_16                     matlab.ui.control.CheckBox
        MarkerLabel_5                   matlab.ui.control.Label
        CheckBox_15                     matlab.ui.control.CheckBox
        CheckBox_14                     matlab.ui.control.CheckBox
        CheckBox_13                     matlab.ui.control.CheckBox
        MarkerLabel_4                   matlab.ui.control.Label
        CheckBox_12                     matlab.ui.control.CheckBox
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        MarkerLabel_3                   matlab.ui.control.Label
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_8                      matlab.ui.control.CheckBox
        CheckBox_7                      matlab.ui.control.CheckBox
        MarkerLabel_2                   matlab.ui.control.Label
        CheckBox_6                      matlab.ui.control.CheckBox
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        CheckBox_1                      matlab.ui.control.CheckBox
        AxisofrotationTab               matlab.ui.container.Tab
        AxisofrotationLabel             matlab.ui.control.Label
        DeleteButton_14                 matlab.ui.control.Button
        AddButton_2                     matlab.ui.control.Button
        CheckBox_a36                    matlab.ui.control.CheckBox
        CheckBox_a35                    matlab.ui.control.CheckBox
        CheckBox_a34                    matlab.ui.control.CheckBox
        DropDown_12                     matlab.ui.control.DropDown
        DropDown_13Label_2              matlab.ui.control.Label
        CheckBox_a33                    matlab.ui.control.CheckBox
        CheckBox_a32                    matlab.ui.control.CheckBox
        CheckBox_a31                    matlab.ui.control.CheckBox
        DropDown_11                     matlab.ui.control.DropDown
        DropDown_12Label                matlab.ui.control.Label
        Label_a12                       matlab.ui.control.Label
        Label_a11                       matlab.ui.control.Label
        CheckBox_a30                    matlab.ui.control.CheckBox
        CheckBox_a29                    matlab.ui.control.CheckBox
        CheckBox_a28                    matlab.ui.control.CheckBox
        DropDown_10                     matlab.ui.control.DropDown
        DropDown_11Label_2              matlab.ui.control.Label
        CheckBox_a27                    matlab.ui.control.CheckBox
        CheckBox_a26                    matlab.ui.control.CheckBox
        CheckBox_a25                    matlab.ui.control.CheckBox
        DropDown_9                      matlab.ui.control.DropDown
        DropDown_10Label                matlab.ui.control.Label
        CheckBox_a24                    matlab.ui.control.CheckBox
        CheckBox_a23                    matlab.ui.control.CheckBox
        CheckBox_a22                    matlab.ui.control.CheckBox
        DropDown_8                      matlab.ui.control.DropDown
        DropDown_9Label_2               matlab.ui.control.Label
        CheckBox_a21                    matlab.ui.control.CheckBox
        CheckBox_a20                    matlab.ui.control.CheckBox
        CheckBox_a19                    matlab.ui.control.CheckBox
        DropDown_7                      matlab.ui.control.DropDown
        DropDown_8Label                 matlab.ui.control.Label
        Label_a10                       matlab.ui.control.Label
        Label_a9                        matlab.ui.control.Label
        Label_a8                        matlab.ui.control.Label
        Label_a7                        matlab.ui.control.Label
        CheckBox_a18                    matlab.ui.control.CheckBox
        CheckBox_a17                    matlab.ui.control.CheckBox
        CheckBox_a16                    matlab.ui.control.CheckBox
        DropDown_6                      matlab.ui.control.DropDown
        DropDown_7Label_2               matlab.ui.control.Label
        CheckBox_a15                    matlab.ui.control.CheckBox
        CheckBox_a14                    matlab.ui.control.CheckBox
        CheckBox_a13                    matlab.ui.control.CheckBox
        DropDown_5                      matlab.ui.control.DropDown
        DropDown_6Label                 matlab.ui.control.Label
        Label_a6                        matlab.ui.control.Label
        Label_a5                        matlab.ui.control.Label
        CheckBox_a12                    matlab.ui.control.CheckBox
        CheckBox_a11                    matlab.ui.control.CheckBox
        CheckBox_a10                    matlab.ui.control.CheckBox
        DropDown_4                      matlab.ui.control.DropDown
        DropDown_5Label_2               matlab.ui.control.Label
        CheckBox_a9                     matlab.ui.control.CheckBox
        CheckBox_a8                     matlab.ui.control.CheckBox
        CheckBox_a7                     matlab.ui.control.CheckBox
        DropDown_3                      matlab.ui.control.DropDown
        DropDown_4Label                 matlab.ui.control.Label
        Label_a4                        matlab.ui.control.Label
        Label_a3                        matlab.ui.control.Label
        CheckBox_a6                     matlab.ui.control.CheckBox
        CheckBox_a5                     matlab.ui.control.CheckBox
        CheckBox_a4                     matlab.ui.control.CheckBox
        DropDown_2                      matlab.ui.control.DropDown
        DropDown_3Label                 matlab.ui.control.Label
        Label_a2                        matlab.ui.control.Label
        CheckBox_a3                     matlab.ui.control.CheckBox
        CheckBox_a2                     matlab.ui.control.CheckBox
        CheckBox_a1                     matlab.ui.control.CheckBox
        ZLabel_4                        matlab.ui.control.Label
        YLabel_4                        matlab.ui.control.Label
        XLabel_4                        matlab.ui.control.Label
        Label_a1                        matlab.ui.control.Label
        DropDown_1                      matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        OKButton                        matlab.ui.control.Button
        ActivestepCheckBox              matlab.ui.control.CheckBox
    end


    properties (Access = private)
        CallingApp
        Human_model % Description
        Markers_set
        cpt_k
        cpt_a
        AnalysisParameters
        Scapactive
    end

    methods (Access = private)
    
        function ActuateApp(app,OldAnalysisParameters)
            % Active
            app.ActivestepCheckBox.Value = OldAnalysisParameters.CalibIK.Active;
            app.ActivestepCheckBoxValueChanged;
            % Frames
            app.SelectionmethodofframesDropDown.Value = func2str(OldAnalysisParameters.CalibIK.Frames.Method);
            app.NumberofframesEditField.Value = OldAnalysisParameters.CalibIK.Frames.NbFrames;
        end
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            % Active
            OldAnalysisParameters.CalibIK.Active = app.ActivestepCheckBox.Value;
            % Frames
            OldAnalysisParameters.CalibIK.Frames.Method = str2func(app.SelectionmethodofframesDropDown.Value);
            OldAnalysisParameters.CalibIK.Frames.NbFrames = app.NumberofframesEditField.Value;
            % Length
            for i=1:numel(app.Human_model)
                app.Human_model(i).calib_k_constraint = [];
            end
            for i= 1:app.cpt_k
                [~,~,num_solid1]=eval(['intersect(app.DropDown_' num2str(i) '_1.Value,{app.Human_model.name});']);
                [~,~,num_solid2]=eval(['intersect(app.DropDown_' num2str(i) '_2.Value,{app.Human_model.name});']);
                app.Human_model(num_solid1).calib_k_constraint = num_solid2;
            end
            % Markers
            cpt = 0;
            for i=1:numel(app.Markers_set)
                if app.Markers_set(i).exist
                    cpt=cpt+1;
                    for j=1:3
                        eval(['val = app.CheckBox_' num2str(3*(cpt-1)+j) '.Value;'])
                        if val
                            app.Markers_set(i).calib_dir{j,1} = 'On';
                        else
                            app.Markers_set(i).calib_dir{j,1} = 'Off';
                        end
                    end
                end
            end
            % Axis of rotation
            for i=1:numel(app.Human_model)
                app.Human_model(i).v = [];
            end
            II = eye(3);
            for i=1:app.cpt_a
                [~,~,num_solid]=eval(['intersect(app.DropDown_' num2str(i) '.Value,{app.Human_model.name});']);
                for j=1:3
                    eval(['val = app.CheckBox_a' num2str(3*(i-1)+j) '.Value;'])
                    if val
                        app.Human_model(num_solid).v = [app.Human_model(num_solid).v II(:,j)];
                    end              
                end
            end
            
            OldAnalysisParameters.CalibIK.Scapactive = app.Scapactive;

            % Scapulalocator parameters
            if app.Scapactive
                if app.SameascalibrationCheckBox.Value
                    OldAnalysisParameters.CalibIK.ScapFiles = OldAnalysisParameters.CalibIK.filename;
                else
                    OldAnalysisParameters.CalibIK.ScapFiles = app.FileswithstaticposesLabel.Value;
                end
            end


            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Human_model, Markers_set, OldAnalysisParameters)
            app.CallingApp = mainapp;
            app.Human_model = Human_model;
            app.Markers_set = Markers_set;
            % Initialisation de la liste des fonctions pour le choix des frames
            path_models = fileparts(which('GeometricalCalibration'));
            list_files = dir([path_models '/FrameChoice/*.m']); list_files = cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false);
            app.SelectionmethodofframesDropDown.Items = list_files; app.SelectionmethodofframesDropDown.ItemsData = list_files;
            % Actualisation des paramètres
            ActuateApp(app,OldAnalysisParameters);
            app.AnalysisParameters = OldAnalysisParameters;
            % Initialisation de k
            list_solid = find([app.Human_model.Visual]);
            app.cpt_k = 0;
            for i=1:numel(app.Human_model)
                if app.Human_model(i).calib_k_constraint
                    app.cpt_k = app.cpt_k + 1;
                    eval(['app.DropDown_' num2str(app.cpt_k) '_1.Items = {Human_model(list_solid).name};'])
                    eval(['app.DropDown_' num2str(app.cpt_k) '_1.Value = Human_model(i).name;'])
                    eval(['app.DropDown_' num2str(app.cpt_k) '_2.Items = {Human_model(list_solid).name};'])
                    eval(['app.DropDown_' num2str(app.cpt_k) '_2.Value = Human_model(Human_model(i).calib_k_constraint).name;'])
                end
            end
            for i=(app.cpt_k+1) : 12
                eval(['app.DropDown_' num2str(i) '_1.Items = {Human_model(list_solid).name};'])
                eval(['app.DropDown_' num2str(i) '_2.Items = {Human_model(list_solid).name};'])
                eval(['app.DropDown_' num2str(i) '_1.Visible = ''off'';']);
                eval(['app.LinkedtoLabel_' num2str(i) '.Visible = ''off'';']);
                eval(['app.DropDown_' num2str(i) '_2.Visible = ''off'';']);
            end
            % Initialisation de p
            cpt_p = 0;
            for i=1:numel(app.Markers_set)
                if app.Markers_set(i).exist
                    cpt_p = cpt_p + 1;
                    eval(['app.MarkerLabel_' num2str(cpt_p) '.Text = app.Markers_set(i).name;'])
                    for j=1:3
                        if strcmp(app.Markers_set(i).calib_dir(j,1),'On')
                            eval(['app.CheckBox_' num2str(3*(cpt_p-1)+j) '.Value = true;'])
                        end
                    end
                end
            end
            if cpt_p <= 40
                app.XLabel_3.Text = ''; app.YLabel_3.Text = ''; app.ZLabel_3.Text = '';
                if cpt_p <= 20
                    app.XLabel_2.Text = ''; app.YLabel_2.Text = ''; app.ZLabel_2.Text = '';  
                end
            end
            for i=(cpt_p+1):60
                eval(['app.MarkerLabel_' num2str(i) '.Text = '' '';'])
                eval(['app.CheckBox_' num2str(3*(i-1)+1) '.Visible = ''off'';'])
                eval(['app.CheckBox_' num2str(3*(i-1)+2) '.Visible = ''off'';'])
                eval(['app.CheckBox_' num2str(3*(i-1)+3) '.Visible = ''off'';'])
            end
            % Initialisation de alpha
            app.cpt_a = 0;
            list_solid = [];
            for i=1:numel(app.Human_model)
                if numel(app.Human_model(i).limit_alpha)
                    list_solid=[list_solid i];
                end
            end
            for i=1:numel(app.Human_model)
                if numel(app.Human_model(i).v)
                    app.cpt_a = app.cpt_a + 1;
                    eval(['app.DropDown_' num2str(app.cpt_a) '.Items = {app.Human_model(list_solid).name};'])
                    eval(['app.DropDown_' num2str(app.cpt_a) '.Value = app.Human_model(i).name;'])
                    eval(['app.Label_a' num2str(app.cpt_a) '.Text = num2str(app.Human_model(i).a'');'])
                    for j=1:size(app.Human_model(i).v,2)
                        if app.Human_model(i).v(:,j) == [1 0 0]'
                            eval(['app.CheckBox_a' num2str(3*(app.cpt_a-1)+1) '.Value = true;'])
                        elseif app.Human_model(i).v(:,j) == [0 1 0]'
                            eval(['app.CheckBox_a' num2str(3*(app.cpt_a-1)+2) '.Value = true;'])
                        elseif app.Human_model(i).v(:,j) == [0 0 1]'
                            eval(['app.CheckBox_a' num2str(3*(app.cpt_a-1)+3) '.Value = true;'])
                        end
                    end
                end
            end
            for i = (app.cpt_a+1) : 12
                eval(['app.DropDown_' num2str(i) '.Items = {app.Human_model(list_solid).name};'])
                eval(['app.DropDown_' num2str(i) '.Visible = ''off'';'])
                eval(['app.Label_a' num2str(i) '.Text = '''';'])
                for j=1:3
                    eval(['app.CheckBox_a' num2str(3*(i-1)+j) '.Visible = ''off'';'])
                end
            end
            if app.cpt_a == 0
                app.AxisofrotationLabel.Visible = 'off';
                app.XLabel_4.Text = ''; app.YLabel_4.Text = ''; app.ZLabel_4.Text = '';
            else
                app.AxisofrotationLabel.Visible = 'on';
                app.XLabel_4.Text = 'X'; app.YLabel_4.Text = 'Y'; app.ZLabel_4.Text = 'Z';
            end
          
            list_files_c3d=dir(OldAnalysisParameters.General.Extension);
            app.ListBox.Items = {list_files_c3d.name};

            load('ModelParameters.mat');
            
            if isfield(ModelParameters, 'Scapulalocator')   && ModelParameters.Scapulalocator.active
                app.Scapactive = 1;
                app.FileswithstaticposesLabel.Visible='on';
                app.SameascalibrationCheckBox.Visible='on';
                app.ScapulalocatorfilesforcalibrationLabel.Visible='on';
                app.ListBox.Visible='on';
                
            else
                app.Scapactive = 0;
                app.FileswithstaticposesLabel.Visible='off';
                app.SameascalibrationCheckBox.Visible='off';
                app.ScapulalocatorfilesforcalibrationLabel.Visible='off';
                app.ListBox.Visible='off';
                
            end


            if isfield(OldAnalysisParameters.CalibIK,'Scapactive') 
                if OldAnalysisParameters.CalibIK.Scapactive
                    if ischar(OldAnalysisParameters.CalibIK.ScapFiles)
                        app.SameascalibrationCheckBox.Value=1;
                    else
                        app.SameascalibrationCheckBox.Value=0;
                        app.FileswithstaticposesLabel.Value = OldAnalysisParameters.CalibIK.ScapFiles;
                    end
                end
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if app.cpt_k < 12
                app.cpt_k = app.cpt_k +1;
                eval(['app.DropDown_' num2str(app.cpt_k) '_1.Visible = ''on'';']);
                eval(['app.LinkedtoLabel_' num2str(app.cpt_k) '.Visible = ''on'';']);
                eval(['app.DropDown_' num2str(app.cpt_k) '_2.Visible = ''on'';']);
            end
        end

        % Button pushed function: DeleteButton_13
        function DeleteButton_13Pushed(app, event)
            if app.cpt_k
                eval(['app.DropDown_' num2str(app.cpt_k) '_1.Visible = ''off'';']);
                eval(['app.LinkedtoLabel_' num2str(app.cpt_k) '.Visible = ''off'';']);
                eval(['app.DropDown_' num2str(app.cpt_k) '_2.Visible = ''off'';']);
                app.cpt_k = app.cpt_k -1;
            end
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            if app.cpt_a < 12
                if app.cpt_a == 0
                    app.AxisofrotationLabel.Visible = 'on';
                    app.XLabel_4.Text = 'X'; app.YLabel_4.Text = 'Y'; app.ZLabel_4.Text = 'Z';
                end
                app.cpt_a = app.cpt_a + 1;
                eval(['app.DropDown_' num2str(app.cpt_a) '.Visible = ''on'';'])
                for j=1:3
                    eval(['app.CheckBox_a' num2str(3*(app.cpt_a-1)+j) '.Visible = ''on'';'])
                end
                app.DropDown_1ValueChanged;
            end
        end

        % Button pushed function: DeleteButton_14
        function DeleteButton_14Pushed(app, event)
            if app.cpt_a
                if app.cpt_a == 1
                    app.AxisofrotationLabel.Visible = 'off';
                    app.XLabel_4.Text = ''; app.YLabel_4.Text = ''; app.ZLabel_4.Text = '';
                end
                eval(['app.DropDown_' num2str(app.cpt_a) '.Items = {app.Human_model.name};'])
                eval(['app.DropDown_' num2str(app.cpt_a) '.Visible = ''off'';'])
                eval(['app.Label_a' num2str(app.cpt_a) '.Text = '''';'])
                for j=1:3
                    eval(['app.CheckBox_a' num2str(3*(app.cpt_a-1)+j) '.Visible = ''off'';'])
                end
                app.cpt_a = app.cpt_a - 1;              
            end
        end

        % Value changed function: DropDown_1, DropDown_10, 
        % DropDown_11, DropDown_12, DropDown_2, DropDown_3, 
        % DropDown_4, DropDown_5, DropDown_6, DropDown_7, 
        % DropDown_8, DropDown_9
        function DropDown_1ValueChanged(app, event)
            for i=1:app.cpt_a
                [~,~,num_solid]=eval(['intersect(app.DropDown_' num2str(i) '.Value,{app.Human_model.name});']);
                eval(['app.Label_a' num2str(i) '.Text = num2str(app.Human_model(num_solid).a'');'])
            end
        end

        % Close request function: GeometricalcalibrationUIFigure
        function GeometricalcalibrationUIFigureCloseRequest(app, event)
            EnableButton4(app.CallingApp);
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveParameters(app.CallingApp,app.AnalysisParameters,app.Human_model,app.Markers_set);
            app.GeometricalcalibrationUIFigureCloseRequest;
        end

        % Value changed function: ActivestepCheckBox
        function ActivestepCheckBoxValueChanged(app, event)
           if app.ActivestepCheckBox.Value
               app.TabGroup.Visible = 'on';
           else
               app.TabGroup.Visible = 'off';
           end
        end

        % Value changed function: SameascalibrationCheckBox
        function SameascalibrationCheckBoxValueChanged(app, event)
            value = app.SameascalibrationCheckBox.Value;
            if value
                app.FileswithstaticposesLabel.Visible='off';
                app.ListBox.Visible='off';
            else
                app.FileswithstaticposesLabel.Visible='on';
                app.ListBox.Visible='on';
            end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeometricalcalibrationUIFigure = uifigure('Visible', 'off');
            app.GeometricalcalibrationUIFigure.Name = 'Geometrical calibration';
            app.GeometricalcalibrationUIFigure.CloseRequestFcn = createCallbackFcn(app, @GeometricalcalibrationUIFigureCloseRequest, true);
            app.Panel = uipanel(app.GeometricalcalibrationUIFigure);
            app.ActivestepCheckBox = uicheckbox(app.Panel);
            app.ActivestepCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivestepCheckBoxValueChanged, true);
            app.ActivestepCheckBox.Text = '   Active step';
            app.ActivestepCheckBox.Value = true;
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.TabGroup = uitabgroup(app.GeometricalcalibrationUIFigure);
            app.FramesusedTab = uitab(app.TabGroup);
            app.FramesusedTab.Title = 'Frames used';
            app.SelectionmethodofframesDropDownLabel = uilabel(app.FramesusedTab);
            app.SelectionmethodofframesDropDownLabel.Text = 'Selection method of frames';
            app.SelectionmethodofframesDropDown = uidropdown(app.FramesusedTab);
            app.SelectionmethodofframesDropDown.Items = {'Options 1', 'Options 2'};
            app.SelectionmethodofframesDropDown.Value = 'Options 1';
            app.NumberofframesEditFieldLabel = uilabel(app.FramesusedTab);
            app.NumberofframesEditFieldLabel.Text = 'Number of frames';
            app.NumberofframesEditField = uieditfield(app.FramesusedTab, 'numeric');
            app.NumberofframesEditField.Limits = [0 Inf];
            app.NumberofframesEditField.Value = 30;
            app.ListBoxLabel = uilabel(app.FramesusedTab);
            app.ListBoxLabel.Text = '';
            app.ListBox = uilistbox(app.FramesusedTab);
            app.ListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Item 1'};
            app.ScapulalocatorfilesforcalibrationLabel = uilabel(app.FramesusedTab);
            app.ScapulalocatorfilesforcalibrationLabel.Text = 'Scapula locator files for calibration';
            app.SameascalibrationCheckBox = uicheckbox(app.FramesusedTab);
            app.SameascalibrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SameascalibrationCheckBoxValueChanged, true);
            app.SameascalibrationCheckBox.Text = 'Same as calibration';
            app.FileswithstaticposesLabel = uilabel(app.FramesusedTab);
            app.FileswithstaticposesLabel.Text = 'Files with static poses';
            app.BodylengthTab = uitab(app.TabGroup);
            app.BodylengthTab.Title = 'Body length';
            app.Label_2 = uilabel(app.BodylengthTab);
            app.Label_2.Text = '';
            app.DropDown_1_2 = uidropdown(app.BodylengthTab);
            app.LinkedtoLabel_1 = uilabel(app.BodylengthTab);
            app.LinkedtoLabel_1.Text = 'Linked to';
            app.AddButton = uibutton(app.BodylengthTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DropDown_1_1 = uidropdown(app.BodylengthTab);
            app.DropDown_2_1 = uidropdown(app.BodylengthTab);
            app.LinkedtoLabel_2 = uilabel(app.BodylengthTab);
            app.LinkedtoLabel_2.Text = 'Linked to';
            app.DropDown_5Label = uilabel(app.BodylengthTab);
            app.DropDown_5Label.Text = '';
            app.DropDown_2_2 = uidropdown(app.BodylengthTab);
            app.DropDown_3_1 = uidropdown(app.BodylengthTab);
            app.LinkedtoLabel_3 = uilabel(app.BodylengthTab);
            app.LinkedtoLabel_3.Text = 'Linked to';
            app.DropDown_7Label = uilabel(app.BodylengthTab);
            app.DropDown_7Label.Text = '';
            app.DropDown_3_2 = uidropdown(app.BodylengthTab);
            app.DropDown_4_1 = uidropdown(app.BodylengthTab);
            app.LinkedtoLabel_4 = uilabel(app.BodylengthTab);
            app.LinkedtoLabel_4.Text = 'Linked to';
            app.DropDown_9Label = uilabel(app.BodylengthTab);
            app.DropDown_9Label.Text = '';
            app.DropDown_4_2 = uidropdown(app.BodylengthTab);
            app.DropDown_5_1 = uidropdown(app.BodylengthTab);
            app.LinkedtoLabel_5 = uilabel(app.BodylengthTab);
            app.LinkedtoLabel_5.Text = 'Linked to';
            app.DropDown_11Label = uilabel(app.BodylengthTab);
            app.DropDown_11Label.Text = '';
            app.DropDown_5_2 = uidropdown(app.BodylengthTab);
            app.DropDown_6_1 = uidropdown(app.BodylengthTab);
            app.LinkedtoLabel_6 = uilabel(app.BodylengthTab);
            app.LinkedtoLabel_6.Text = 'Linked to';
            app.DropDown_13Label = uilabel(app.BodylengthTab);
            app.DropDown_13Label.Text = '';
            app.DropDown_6_2 = uidropdown(app.BodylengthTab);
            app.DropDown_7_1 = uidropdown(app.BodylengthTab);
            app.LinkedtoLabel_7 = uilabel(app.BodylengthTab);
            app.LinkedtoLabel_7.Text = 'Linked to';
            app.DropDown_15Label = uilabel(app.BodylengthTab);
            app.DropDown_15Label.Text = '';
            app.DropDown_7_2 = uidropdown(app.BodylengthTab);
            app.DropDown_8_1 = uidropdown(app.BodylengthTab);
            app.LinkedtoLabel_8 = uilabel(app.BodylengthTab);
            app.LinkedtoLabel_8.Text = 'Linked to';
            app.DropDown_17Label = uilabel(app.BodylengthTab);
            app.DropDown_17Label.Text = '';
            app.DropDown_8_2 = uidropdown(app.BodylengthTab);
            app.DropDown_9_1 = uidropdown(app.BodylengthTab);
            app.LinkedtoLabel_9 = uilabel(app.BodylengthTab);
            app.LinkedtoLabel_9.Text = 'Linked to';
            app.DropDown_19Label = uilabel(app.BodylengthTab);
            app.DropDown_19Label.Text = '';
            app.DropDown_9_2 = uidropdown(app.BodylengthTab);
            app.DropDown_10_1 = uidropdown(app.BodylengthTab);
            app.LinkedtoLabel_10 = uilabel(app.BodylengthTab);
            app.LinkedtoLabel_10.Text = 'Linked to';
            app.DropDown_21Label = uilabel(app.BodylengthTab);
            app.DropDown_21Label.Text = '';
            app.DropDown_10_2 = uidropdown(app.BodylengthTab);
            app.DropDown_11_1 = uidropdown(app.BodylengthTab);
            app.LinkedtoLabel_11 = uilabel(app.BodylengthTab);
            app.LinkedtoLabel_11.Text = 'Linked to';
            app.DropDown_23Label = uilabel(app.BodylengthTab);
            app.DropDown_23Label.Text = '';
            app.DropDown_11_2 = uidropdown(app.BodylengthTab);
            app.DropDown_12_1 = uidropdown(app.BodylengthTab);
            app.LinkedtoLabel_12 = uilabel(app.BodylengthTab);
            app.LinkedtoLabel_12.Text = 'Linked to';
            app.DropDown_25Label = uilabel(app.BodylengthTab);
            app.DropDown_25Label.Text = '';
            app.DropDown_12_2 = uidropdown(app.BodylengthTab);
            app.DeleteButton_13 = uibutton(app.BodylengthTab, 'push');
            app.DeleteButton_13.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_13Pushed, true);
            app.DeleteButton_13.Text = 'Delete';
            app.MarkerpositionTab = uitab(app.TabGroup);
            app.MarkerpositionTab.Title = 'Marker position';
            app.CheckBox_1 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_1.Text = '';
            app.CheckBox_2 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_6.Text = '';
            app.MarkerLabel_2 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_2.Text = 'Marker';
            app.CheckBox_7 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_8.Text = '';
            app.CheckBox_9 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_9.Text = '';
            app.MarkerLabel_3 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_3.Text = 'Marker';
            app.CheckBox_10 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_10.Text = '';
            app.CheckBox_11 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_11.Text = '';
            app.CheckBox_12 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_12.Text = '';
            app.MarkerLabel_4 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_4.Text = 'Marker';
            app.CheckBox_13 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_13.Text = '';
            app.CheckBox_14 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_14.Text = '';
            app.CheckBox_15 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_15.Text = '';
            app.MarkerLabel_5 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_5.Text = 'Marker';
            app.CheckBox_16 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_16.Text = '';
            app.CheckBox_17 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_17.Text = '';
            app.CheckBox_18 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_18.Text = '';
            app.MarkerLabel_6 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_6.Text = 'Marker';
            app.CheckBox_19 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_19.Text = '';
            app.CheckBox_20 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_20.Text = '';
            app.CheckBox_21 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_21.Text = '';
            app.MarkerLabel_7 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_7.Text = 'Marker';
            app.CheckBox_22 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_22.Text = '';
            app.CheckBox_23 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_23.Text = '';
            app.CheckBox_24 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_24.Text = '';
            app.MarkerLabel_8 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_8.Text = 'Marker';
            app.CheckBox_25 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_25.Text = '';
            app.CheckBox_26 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_26.Text = '';
            app.CheckBox_27 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_27.Text = '';
            app.MarkerLabel_9 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_9.Text = 'Marker';
            app.CheckBox_28 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_28.Text = '';
            app.CheckBox_29 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_29.Text = '';
            app.CheckBox_30 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_30.Text = '';
            app.MarkerLabel_10 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_10.Text = 'Marker';
            app.CheckBox_31 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_31.Text = '';
            app.CheckBox_32 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_32.Text = '';
            app.CheckBox_33 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_33.Text = '';
            app.MarkerLabel_11 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_11.Text = 'Marker';
            app.CheckBox_34 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_34.Text = '';
            app.CheckBox_35 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_35.Text = '';
            app.CheckBox_36 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_36.Text = '';
            app.MarkerLabel_12 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_12.Text = 'Marker';
            app.CheckBox_37 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_37.Text = '';
            app.CheckBox_38 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_38.Text = '';
            app.CheckBox_39 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_39.Text = '';
            app.MarkerLabel_13 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_13.Text = 'Marker';
            app.CheckBox_40 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_40.Text = '';
            app.CheckBox_41 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_41.Text = '';
            app.CheckBox_42 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_42.Text = '';
            app.MarkerLabel_14 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_14.Text = 'Marker';
            app.CheckBox_43 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_43.Text = '';
            app.CheckBox_44 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_44.Text = '';
            app.CheckBox_45 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_45.Text = '';
            app.MarkerLabel_15 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_15.Text = 'Marker';
            app.CheckBox_46 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_46.Text = '';
            app.CheckBox_47 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_47.Text = '';
            app.CheckBox_48 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_48.Text = '';
            app.MarkerLabel_16 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_16.Text = 'Marker';
            app.CheckBox_49 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_49.Text = '';
            app.CheckBox_50 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_50.Text = '';
            app.CheckBox_51 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_51.Text = '';
            app.MarkerLabel_17 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_17.Text = 'Marker';
            app.CheckBox_52 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_52.Text = '';
            app.CheckBox_53 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_53.Text = '';
            app.CheckBox_54 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_54.Text = '';
            app.MarkerLabel_18 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_18.Text = 'Marker';
            app.CheckBox_55 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_55.Text = '';
            app.CheckBox_56 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_56.Text = '';
            app.CheckBox_57 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_57.Text = '';
            app.MarkerLabel_19 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_19.Text = 'Marker';
            app.CheckBox_58 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_58.Text = '';
            app.CheckBox_59 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_59.Text = '';
            app.CheckBox_60 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_60.Text = '';
            app.MarkerLabel_20 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_20.Text = 'Marker';
            app.XLabel = uilabel(app.MarkerpositionTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.MarkerpositionTab);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.MarkerpositionTab);
            app.ZLabel.Text = 'Z';
            app.MarkerLabel_1 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_1.Text = 'Marker';
            app.CheckBox_61 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_61.Text = '';
            app.CheckBox_62 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_62.Text = '';
            app.CheckBox_63 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_63.Text = '';
            app.MarkerLabel_21 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_21.Text = 'Marker';
            app.CheckBox_64 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_64.Text = '';
            app.CheckBox_65 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_65.Text = '';
            app.CheckBox_66 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_66.Text = '';
            app.MarkerLabel_22 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_22.Text = 'Marker';
            app.CheckBox_67 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_67.Text = '';
            app.CheckBox_68 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_68.Text = '';
            app.CheckBox_69 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_69.Text = '';
            app.MarkerLabel_23 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_23.Text = 'Marker';
            app.CheckBox_70 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_70.Text = '';
            app.CheckBox_71 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_71.Text = '';
            app.CheckBox_72 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_72.Text = '';
            app.MarkerLabel_24 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_24.Text = 'Marker';
            app.CheckBox_73 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_73.Text = '';
            app.CheckBox_74 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_74.Text = '';
            app.CheckBox_75 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_75.Text = '';
            app.MarkerLabel_25 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_25.Text = 'Marker';
            app.CheckBox_76 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_76.Text = '';
            app.CheckBox_77 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_77.Text = '';
            app.CheckBox_78 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_78.Text = '';
            app.MarkerLabel_26 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_26.Text = 'Marker';
            app.CheckBox_79 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_79.Text = '';
            app.CheckBox_80 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_80.Text = '';
            app.CheckBox_81 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_81.Text = '';
            app.MarkerLabel_27 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_27.Text = 'Marker';
            app.CheckBox_82 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_82.Text = '';
            app.CheckBox_83 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_83.Text = '';
            app.CheckBox_84 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_84.Text = '';
            app.MarkerLabel_28 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_28.Text = 'Marker';
            app.CheckBox_85 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_85.Text = '';
            app.CheckBox_86 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_86.Text = '';
            app.CheckBox_87 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_87.Text = '';
            app.MarkerLabel_29 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_29.Text = 'Marker';
            app.CheckBox_88 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_88.Text = '';
            app.CheckBox_89 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_89.Text = '';
            app.CheckBox_90 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_90.Text = '';
            app.MarkerLabel_30 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_30.Text = 'Marker';
            app.CheckBox_91 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_91.Text = '';
            app.CheckBox_92 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_92.Text = '';
            app.CheckBox_93 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_93.Text = '';
            app.MarkerLabel_31 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_31.Text = 'Marker';
            app.CheckBox_94 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_94.Text = '';
            app.CheckBox_95 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_95.Text = '';
            app.CheckBox_96 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_96.Text = '';
            app.MarkerLabel_32 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_32.Text = 'Marker';
            app.CheckBox_97 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_97.Text = '';
            app.CheckBox_98 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_98.Text = '';
            app.CheckBox_99 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_99.Text = '';
            app.MarkerLabel_33 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_33.Text = 'Marker';
            app.CheckBox_100 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_100.Text = '';
            app.CheckBox_101 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_101.Text = '';
            app.CheckBox_102 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_102.Text = '';
            app.MarkerLabel_34 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_34.Text = 'Marker';
            app.CheckBox_103 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_103.Text = '';
            app.CheckBox_104 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_104.Text = '';
            app.CheckBox_105 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_105.Text = '';
            app.MarkerLabel_35 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_35.Text = 'Marker';
            app.CheckBox_106 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_106.Text = '';
            app.CheckBox_107 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_107.Text = '';
            app.CheckBox_108 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_108.Text = '';
            app.MarkerLabel_36 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_36.Text = 'Marker';
            app.CheckBox_109 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_109.Text = '';
            app.CheckBox_110 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_110.Text = '';
            app.CheckBox_111 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_111.Text = '';
            app.MarkerLabel_37 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_37.Text = 'Marker';
            app.CheckBox_112 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_112.Text = '';
            app.CheckBox_113 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_113.Text = '';
            app.CheckBox_114 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_114.Text = '';
            app.MarkerLabel_38 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_38.Text = 'Marker';
            app.CheckBox_115 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_115.Text = '';
            app.CheckBox_116 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_116.Text = '';
            app.CheckBox_117 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_117.Text = '';
            app.MarkerLabel_39 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_39.Text = 'Marker';
            app.CheckBox_118 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_118.Text = '';
            app.CheckBox_119 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_119.Text = '';
            app.CheckBox_120 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_120.Text = '';
            app.MarkerLabel_40 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_40.Text = 'Marker';
            app.XLabel_2 = uilabel(app.MarkerpositionTab);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.MarkerpositionTab);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_2 = uilabel(app.MarkerpositionTab);
            app.ZLabel_2.Text = 'Z';
            app.CheckBox_121 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_121.Text = '';
            app.CheckBox_122 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_122.Text = '';
            app.CheckBox_123 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_123.Text = '';
            app.MarkerLabel_41 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_41.Text = 'Marker';
            app.CheckBox_124 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_124.Text = '';
            app.CheckBox_125 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_125.Text = '';
            app.CheckBox_126 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_126.Text = '';
            app.MarkerLabel_42 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_42.Text = 'Marker';
            app.CheckBox_127 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_127.Text = '';
            app.CheckBox_128 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_128.Text = '';
            app.CheckBox_129 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_129.Text = '';
            app.MarkerLabel_43 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_43.Text = 'Marker';
            app.CheckBox_130 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_130.Text = '';
            app.CheckBox_131 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_131.Text = '';
            app.CheckBox_132 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_132.Text = '';
            app.MarkerLabel_44 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_44.Text = 'Marker';
            app.CheckBox_133 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_133.Text = '';
            app.CheckBox_134 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_134.Text = '';
            app.CheckBox_135 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_135.Text = '';
            app.MarkerLabel_45 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_45.Text = 'Marker';
            app.CheckBox_136 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_136.Text = '';
            app.CheckBox_137 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_137.Text = '';
            app.CheckBox_138 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_138.Text = '';
            app.MarkerLabel_46 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_46.Text = 'Marker';
            app.CheckBox_139 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_139.Text = '';
            app.CheckBox_140 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_140.Text = '';
            app.CheckBox_141 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_141.Text = '';
            app.MarkerLabel_47 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_47.Text = 'Marker';
            app.CheckBox_142 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_142.Text = '';
            app.CheckBox_143 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_143.Text = '';
            app.CheckBox_144 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_144.Text = '';
            app.MarkerLabel_48 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_48.Text = 'Marker';
            app.CheckBox_145 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_145.Text = '';
            app.CheckBox_146 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_146.Text = '';
            app.CheckBox_147 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_147.Text = '';
            app.MarkerLabel_49 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_49.Text = 'Marker';
            app.CheckBox_148 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_148.Text = '';
            app.CheckBox_149 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_149.Text = '';
            app.CheckBox_150 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_150.Text = '';
            app.MarkerLabel_50 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_50.Text = 'Marker';
            app.CheckBox_151 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_151.Text = '';
            app.CheckBox_152 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_152.Text = '';
            app.CheckBox_153 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_153.Text = '';
            app.MarkerLabel_51 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_51.Text = 'Marker';
            app.CheckBox_154 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_154.Text = '';
            app.CheckBox_155 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_155.Text = '';
            app.CheckBox_156 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_156.Text = '';
            app.MarkerLabel_52 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_52.Text = 'Marker';
            app.CheckBox_157 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_157.Text = '';
            app.CheckBox_158 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_158.Text = '';
            app.CheckBox_159 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_159.Text = '';
            app.MarkerLabel_53 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_53.Text = 'Marker';
            app.CheckBox_160 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_160.Text = '';
            app.CheckBox_161 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_161.Text = '';
            app.CheckBox_162 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_162.Text = '';
            app.MarkerLabel_54 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_54.Text = 'Marker';
            app.CheckBox_163 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_163.Text = '';
            app.CheckBox_164 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_164.Text = '';
            app.CheckBox_165 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_165.Text = '';
            app.MarkerLabel_55 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_55.Text = 'Marker';
            app.CheckBox_166 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_166.Text = '';
            app.CheckBox_167 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_167.Text = '';
            app.CheckBox_168 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_168.Text = '';
            app.MarkerLabel_56 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_56.Text = 'Marker';
            app.CheckBox_169 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_169.Text = '';
            app.CheckBox_170 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_170.Text = '';
            app.CheckBox_171 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_171.Text = '';
            app.MarkerLabel_57 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_57.Text = 'Marker';
            app.CheckBox_172 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_172.Text = '';
            app.CheckBox_173 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_173.Text = '';
            app.CheckBox_174 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_174.Text = '';
            app.MarkerLabel_58 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_58.Text = 'Marker';
            app.CheckBox_175 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_175.Text = '';
            app.CheckBox_176 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_176.Text = '';
            app.CheckBox_177 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_177.Text = '';
            app.MarkerLabel_59 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_59.Text = 'Marker';
            app.CheckBox_178 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_178.Text = '';
            app.CheckBox_179 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_179.Text = '';
            app.CheckBox_180 = uicheckbox(app.MarkerpositionTab);
            app.CheckBox_180.Text = '';
            app.MarkerLabel_60 = uilabel(app.MarkerpositionTab);
            app.MarkerLabel_60.Text = 'Marker';
            app.XLabel_3 = uilabel(app.MarkerpositionTab);
            app.XLabel_3.Text = 'X';
            app.YLabel_3 = uilabel(app.MarkerpositionTab);
            app.YLabel_3.Text = 'Y';
            app.ZLabel_3 = uilabel(app.MarkerpositionTab);
            app.ZLabel_3.Text = 'Z';
            app.AxisofrotationTab = uitab(app.TabGroup);
            app.AxisofrotationTab.Title = 'Axis of rotation';
            app.Label_3 = uilabel(app.AxisofrotationTab);
            app.Label_3.Text = '';
            app.DropDown_1 = uidropdown(app.AxisofrotationTab);
            app.DropDown_1.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.Label_a1 = uilabel(app.AxisofrotationTab);
            app.XLabel_4 = uilabel(app.AxisofrotationTab);
            app.XLabel_4.Text = 'X';
            app.YLabel_4 = uilabel(app.AxisofrotationTab);
            app.YLabel_4.Text = 'Y';
            app.ZLabel_4 = uilabel(app.AxisofrotationTab);
            app.ZLabel_4.Text = 'Z';
            app.CheckBox_a1 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a1.Text = '';
            app.CheckBox_a2 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a2.Text = '';
            app.CheckBox_a3 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a3.Text = '';
            app.Label_a2 = uilabel(app.AxisofrotationTab);
            app.DropDown_3Label = uilabel(app.AxisofrotationTab);
            app.DropDown_3Label.Text = '';
            app.DropDown_2 = uidropdown(app.AxisofrotationTab);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.CheckBox_a4 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a4.Text = '';
            app.CheckBox_a5 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a5.Text = '';
            app.CheckBox_a6 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a6.Text = '';
            app.Label_a3 = uilabel(app.AxisofrotationTab);
            app.Label_a4 = uilabel(app.AxisofrotationTab);
            app.DropDown_4Label = uilabel(app.AxisofrotationTab);
            app.DropDown_4Label.Text = '';
            app.DropDown_3 = uidropdown(app.AxisofrotationTab);
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.CheckBox_a7 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a7.Text = '';
            app.CheckBox_a8 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a8.Text = '';
            app.CheckBox_a9 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a9.Text = '';
            app.DropDown_5Label_2 = uilabel(app.AxisofrotationTab);
            app.DropDown_5Label_2.Text = '';
            app.DropDown_4 = uidropdown(app.AxisofrotationTab);
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.CheckBox_a10 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a10.Text = '';
            app.CheckBox_a11 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a11.Text = '';
            app.CheckBox_a12 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a12.Text = '';
            app.Label_a5 = uilabel(app.AxisofrotationTab);
            app.Label_a6 = uilabel(app.AxisofrotationTab);
            app.DropDown_6Label = uilabel(app.AxisofrotationTab);
            app.DropDown_6Label.Text = '';
            app.DropDown_5 = uidropdown(app.AxisofrotationTab);
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.CheckBox_a13 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a13.Text = '';
            app.CheckBox_a14 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a14.Text = '';
            app.CheckBox_a15 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a15.Text = '';
            app.DropDown_7Label_2 = uilabel(app.AxisofrotationTab);
            app.DropDown_7Label_2.Text = '';
            app.DropDown_6 = uidropdown(app.AxisofrotationTab);
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.CheckBox_a16 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a16.Text = '';
            app.CheckBox_a17 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a17.Text = '';
            app.CheckBox_a18 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a18.Text = '';
            app.Label_a7 = uilabel(app.AxisofrotationTab);
            app.Label_a8 = uilabel(app.AxisofrotationTab);
            app.Label_a9 = uilabel(app.AxisofrotationTab);
            app.Label_a10 = uilabel(app.AxisofrotationTab);
            app.DropDown_8Label = uilabel(app.AxisofrotationTab);
            app.DropDown_8Label.Text = '';
            app.DropDown_7 = uidropdown(app.AxisofrotationTab);
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.CheckBox_a19 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a19.Text = '';
            app.CheckBox_a20 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a20.Text = '';
            app.CheckBox_a21 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a21.Text = '';
            app.DropDown_9Label_2 = uilabel(app.AxisofrotationTab);
            app.DropDown_9Label_2.Text = '';
            app.DropDown_8 = uidropdown(app.AxisofrotationTab);
            app.DropDown_8.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.CheckBox_a22 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a22.Text = '';
            app.CheckBox_a23 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a23.Text = '';
            app.CheckBox_a24 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a24.Text = '';
            app.DropDown_10Label = uilabel(app.AxisofrotationTab);
            app.DropDown_10Label.Text = '';
            app.DropDown_9 = uidropdown(app.AxisofrotationTab);
            app.DropDown_9.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.CheckBox_a25 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a25.Text = '';
            app.CheckBox_a26 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a26.Text = '';
            app.CheckBox_a27 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a27.Text = '';
            app.DropDown_11Label_2 = uilabel(app.AxisofrotationTab);
            app.DropDown_11Label_2.Text = '';
            app.DropDown_10 = uidropdown(app.AxisofrotationTab);
            app.DropDown_10.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.CheckBox_a28 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a28.Text = '';
            app.CheckBox_a29 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a29.Text = '';
            app.CheckBox_a30 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a30.Text = '';
            app.Label_a11 = uilabel(app.AxisofrotationTab);
            app.Label_a12 = uilabel(app.AxisofrotationTab);
            app.DropDown_12Label = uilabel(app.AxisofrotationTab);
            app.DropDown_12Label.Text = '';
            app.DropDown_11 = uidropdown(app.AxisofrotationTab);
            app.DropDown_11.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.CheckBox_a31 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a31.Text = '';
            app.CheckBox_a32 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a32.Text = '';
            app.CheckBox_a33 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a33.Text = '';
            app.DropDown_13Label_2 = uilabel(app.AxisofrotationTab);
            app.DropDown_13Label_2.Text = '';
            app.DropDown_12 = uidropdown(app.AxisofrotationTab);
            app.DropDown_12.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.CheckBox_a34 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a34.Text = '';
            app.CheckBox_a35 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a35.Text = '';
            app.CheckBox_a36 = uicheckbox(app.AxisofrotationTab);
            app.CheckBox_a36.Text = '';
            app.AddButton_2 = uibutton(app.AxisofrotationTab, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.DeleteButton_14 = uibutton(app.AxisofrotationTab, 'push');
            app.DeleteButton_14.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_14Pushed, true);
            app.DeleteButton_14.Text = 'Delete';
            app.AxisofrotationLabel = uilabel(app.AxisofrotationTab);
            app.AxisofrotationLabel.Text = 'Axis of rotation';
            % Show the figure after all components are created
            app.GeometricalcalibrationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptionsCalibIK(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeometricalcalibrationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeometricalcalibrationUIFigure)
        end
    end
end