classdef OptionsExternalForces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExternalforcesoptionsUIFigure  matlab.ui.Figure
        SolidDropDown_4                matlab.ui.control.DropDown
        SolidDropDown_4Label           matlab.ui.control.Label
        SolidDropDown_3                matlab.ui.control.DropDown
        SolidDropDown_3Label           matlab.ui.control.Label
        SolidDropDown_2                matlab.ui.control.DropDown
        SolidDropDown_2Label           matlab.ui.control.Label
        SolidDropDown_1                matlab.ui.control.DropDown
        SolidDropDown_1Label           matlab.ui.control.Label
        MasskgEditField_4              matlab.ui.control.NumericEditField
        MasskgEditField_4Label         matlab.ui.control.Label
        MasskgEditField_3              matlab.ui.control.NumericEditField
        MasskgEditField_3Label         matlab.ui.control.Label
        MasskgEditField_2              matlab.ui.control.NumericEditField
        MasskgEditField_2Label         matlab.ui.control.Label
        MasskgEditField_1              matlab.ui.control.NumericEditField
        MasskgEditField_1Label         matlab.ui.control.Label
        Add_mass                       matlab.ui.control.Button
        DropDown_7                     matlab.ui.control.DropDown
        MethodtoextractdataLabel       matlab.ui.control.Label
        PlatformLabel_6                matlab.ui.control.Label
        DropDown_6                     matlab.ui.control.DropDown
        PlatformLabel_5                matlab.ui.control.Label
        DropDown_5                     matlab.ui.control.DropDown
        PlatformLabel_4                matlab.ui.control.Label
        DropDown_4                     matlab.ui.control.DropDown
        PlatformLabel_3                matlab.ui.control.Label
        DropDown_3                     matlab.ui.control.DropDown
        PlatformLabel_2                matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        PlatformLabel_1                matlab.ui.control.Label
        DropDown_1                     matlab.ui.control.DropDown
        CutofffrenquencyHzLabel        matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        ExternalforcesfilteringLabel   matlab.ui.control.Label
        CheckBox                       matlab.ui.control.CheckBox
        Panel                          matlab.ui.container.Panel
        OKButton                       matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp
        Human_model % Description
        Markers_set
        AnalysisParameters
        num_m
        num_mass
    end

    methods (Access = private)
    
        function ActuateApp(app,OldAnalysisParameters)
            app.CheckBox.Value = OldAnalysisParameters.ExternalForces.FilterActive;
            app.CheckBoxValueChanged;
            app.EditField.Value = OldAnalysisParameters.ExternalForces.FilterCutOff;
            app.DropDown_7.Value = func2str(OldAnalysisParameters.ExternalForces.Method);
            app.DropDown_7ValueChanged;
            for i=1:numel(OldAnalysisParameters.ExternalForces.Options)
                if strcmp(OldAnalysisParameters.ExternalForces.Options{i},'NoContact')
                    Solid = 'No contact';
                else
                    Solid = OldAnalysisParameters.ExternalForces.Options{i};
                end
                eval(['app.DropDown_' num2str(i) '.Value = Solid;'])
            end            
        end
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            OldAnalysisParameters.ExternalForces.FilterActive = app.CheckBox.Value;
            OldAnalysisParameters.ExternalForces.FilterCutOff = app.EditField.Value;
            OldAnalysisParameters.ExternalForces.Method = str2func(app.DropDown_7.Value);
            app.DropDown_7ValueChanged;
            OldAnalysisParameters.ExternalForces.Options = {};
            for i=1:app.num_m
                Solid = eval(['app.DropDown_' num2str(i) '.Value']);
                if strcmp(Solid,'No contact')
                    Solid = 'NoContact';
                end 
                OldAnalysisParameters.ExternalForces.Options = [OldAnalysisParameters.ExternalForces.Options;...
                    Solid];
            end
            
             OldAnalysisParameters.ExternalForces.Mass ={};
            for i=1:app.num_mass
                OldAnalysisParameters.ExternalForces.Mass = [OldAnalysisParameters.Prediction.ContactPoint  ...
                    eval(['app.MasskgEditField_' num2str(i) '.Value'])  ; eval(['app.SolidDropDown_' num2str(i) '.Value'])  ];
            end
            OldAnalysisParameters.ExternalForces.ContactPoint = unique(OldAnalysisParameters.Prediction.ContactPoint);
            % Actuate
            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Human_model, Markers_set, OldAnalysisParameters)
            app.CallingApp = mainapp;
            app.Human_model = Human_model;
            app.Markers_set = Markers_set;
            app.num_m = 0;
            % Initialisation des méthodes disponibles
            path_models = fileparts(which('ExternalForcesComputation'));
            list_files = dir([path_models '/FromExperiments/*.m']); list_files = cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false);
            app.DropDown_7.Items = list_files; app.DropDown_7.ItemsData = list_files;
            % Actualisation des paramètres
            app.AnalysisParameters = OldAnalysisParameters;
            app.num_mass = 0;
            ActuateApp(app,OldAnalysisParameters);
        end

        % Close request function: ExternalforcesoptionsUIFigure
        function ExternalforcesoptionsUIFigureCloseRequest(app, event)
            EnableButton8(app.CallingApp);
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveParameters(app.CallingApp,app.AnalysisParameters,app.Human_model,app.Markers_set);
            app.ExternalforcesoptionsUIFigureCloseRequest;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if app.CheckBox.Value
                app.CutofffrenquencyHzLabel.Visible = 'on';
                app.EditField.Visible = 'on';
            else
                app.CutofffrenquencyHzLabel.Visible = 'off';
                app.EditField.Visible = 'off';
            end 
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged(app, event)
            if strcmp(app.DropDown_7.Value,'DataInC3D') || strcmp(app.DropDown_7.Value,'DataInC3D_Manutention')
                % Nombre de plateformes
                h = btkReadAcquisition(app.AnalysisParameters.filename{1});
                [Forceplates] = btkGetForcePlatforms(h);
                app.num_m = numel(Forceplates);
                for i=1:app.num_m
                    eval(['app.PlatformLabel_' num2str(i) '.Visible=''on'';'])
                    eval(['app.DropDown_' num2str(i) '.Visible=''on'';'])
                end
                for i=(app.num_m+1) : 6
                    eval(['app.PlatformLabel_' num2str(i) '.Visible=''off'';'])
                    eval(['app.DropDown_' num2str(i) '.Visible=''off'';'])
                end
                for i=1:4
                    eval(['app.MasskgEditField_' num2str(i) '.Visible = ''off'';'])
                    eval(['app.SolidDropDown_' num2str(i) '.Visible = ''off'';'])
                    eval(['app.MasskgEditField_' num2str(i) 'Label.Visible = ''off'';'])
                    eval(['app.SolidDropDown_' num2str(i) 'Label.Visible = ''off'';'])
                end
            else
                for i=1:6
                    eval(['app.PlatformLabel_' num2str(i) '.Visible = ''off'';'])
                    eval(['app.DropDown_' num2str(i) '.Visible = ''off'';'])
                end
            end
            for i=1:6
                eval(['app.PlatformLabel_' num2str(i) '.Text=''Platform ' num2str(i) ''';'])
                eval(['app.DropDown_' num2str(i) '.Items=[''No contact'' {app.Human_model.name}];'])
            end
            
            if strcmp(app.DropDown_7.Value,'MassLoad') 
                app.Add_mass.Visible = 'on';
                for i=1:4
                    eval(['app.SolidDropDown_' num2str(i) '.Items=[{app.Human_model.name}];'])
                end
            else
                app.Add_mass.Visible = 'off';
            end
        end

        % Button pushed function: Add_mass
        function Add_massButtonPushed(app, event)
            if app.num_mass <4
                app.num_mass = app.num_mass + 1;
                eval(['app.MasskgEditField_' num2str(app.num_mass) '.Visible = ''on'';'])
                eval(['app.SolidDropDown_' num2str(app.num_mass) '.Visible = ''on'';'])  
                eval(['app.MasskgEditField_' num2str(app.num_mass) 'Label.Visible = ''on'';'])
                eval(['app.SolidDropDown_' num2str(app.num_mass) 'Label.Visible = ''on'';'])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExternalforcesoptionsUIFigure = uifigure('Visible', 'off');
            app.ExternalforcesoptionsUIFigure.Name = 'External forces options';
            app.ExternalforcesoptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExternalforcesoptionsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.ExternalforcesoptionsUIFigure);
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CheckBox = uicheckbox(app.ExternalforcesoptionsUIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.ExternalforcesfilteringLabel = uilabel(app.ExternalforcesoptionsUIFigure);
            app.ExternalforcesfilteringLabel.Text = 'External forces filtering';
            app.EditField = uieditfield(app.ExternalforcesoptionsUIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.Value = 5;
            app.CutofffrenquencyHzLabel = uilabel(app.ExternalforcesoptionsUIFigure);
            app.CutofffrenquencyHzLabel.Text = 'Cut-off frenquency (Hz)';
            app.DropDown_1 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_1 = uilabel(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_1.Text = 'Platform';
            app.DropDown_2 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_2 = uilabel(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_2.Text = 'Platform';
            app.DropDown_3 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_3 = uilabel(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_3.Text = 'Platform';
            app.DropDown_4 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_4 = uilabel(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_4.Text = 'Platform';
            app.DropDown_5 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_5 = uilabel(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_5.Text = 'Platform';
            app.DropDown_6 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_6 = uilabel(app.ExternalforcesoptionsUIFigure);
            app.PlatformLabel_6.Text = 'Platform';
            app.MethodtoextractdataLabel = uilabel(app.ExternalforcesoptionsUIFigure);
            app.MethodtoextractdataLabel.Text = 'Method to extract data';
            app.DropDown_7 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged, true);
            app.Add_mass = uibutton(app.ExternalforcesoptionsUIFigure, 'push');
            app.Add_mass.ButtonPushedFcn = createCallbackFcn(app, @Add_massButtonPushed, true);
            app.Add_mass.Text = {'Add Mass'; ''};
            app.MasskgEditField_1Label = uilabel(app.ExternalforcesoptionsUIFigure);
            app.MasskgEditField_1Label.Text = 'Mass (kg)';
            app.MasskgEditField_1 = uieditfield(app.ExternalforcesoptionsUIFigure, 'numeric');
            app.MasskgEditField_2Label = uilabel(app.ExternalforcesoptionsUIFigure);
            app.MasskgEditField_2Label.Text = 'Mass (kg)';
            app.MasskgEditField_2 = uieditfield(app.ExternalforcesoptionsUIFigure, 'numeric');
            app.MasskgEditField_3Label = uilabel(app.ExternalforcesoptionsUIFigure);
            app.MasskgEditField_3Label.Text = 'Mass (kg)';
            app.MasskgEditField_3 = uieditfield(app.ExternalforcesoptionsUIFigure, 'numeric');
            app.MasskgEditField_4Label = uilabel(app.ExternalforcesoptionsUIFigure);
            app.MasskgEditField_4Label.Text = 'Mass (kg)';
            app.MasskgEditField_4 = uieditfield(app.ExternalforcesoptionsUIFigure, 'numeric');
            app.SolidDropDown_1Label = uilabel(app.ExternalforcesoptionsUIFigure);
            app.SolidDropDown_1Label.Text = 'Solid';
            app.SolidDropDown_1 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.SolidDropDown_2Label = uilabel(app.ExternalforcesoptionsUIFigure);
            app.SolidDropDown_2Label.Text = 'Solid';
            app.SolidDropDown_2 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.SolidDropDown_3Label = uilabel(app.ExternalforcesoptionsUIFigure);
            app.SolidDropDown_3Label.Text = 'Solid';
            app.SolidDropDown_3 = uidropdown(app.ExternalforcesoptionsUIFigure);
            app.SolidDropDown_4Label = uilabel(app.ExternalforcesoptionsUIFigure);
            app.SolidDropDown_4Label.Text = 'Solid';
            app.SolidDropDown_4 = uidropdown(app.ExternalforcesoptionsUIFigure);
            % Show the figure after all components are created
            app.ExternalforcesoptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptionsExternalForces(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExternalforcesoptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExternalforcesoptionsUIFigure)
        end
    end
end