classdef OptionsGeneral < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GeneraloptionsUIFigure         matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        OKButton                       matlab.ui.control.Button
        CheckBox                       matlab.ui.control.CheckBox
        MarkerspositionfilteringLabel  matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        CutofffrenquencyHzLabel        matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        TypeofinputdataLabel           matlab.ui.control.Label
        MethodDropDown_2               matlab.ui.control.DropDown
        VSKfileLabel                   matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp
        Human_model % Description
        Markers_set
        AnalysisParameters
    end

    methods (Access = private)
    
        function ActuateApp(app,OldAnalysisParameters)
            app.CheckBox.Value = OldAnalysisParameters.General.FilterActive;
            app.CheckBoxValueChanged;
            app.EditField.Value = OldAnalysisParameters.General.FilterCutOff;
            app.MethodDropDown.Value = func2str(OldAnalysisParameters.General.InputData);
            app.MethodDropDown_2.Value = intersect(OldAnalysisParameters.General.InputDataOptions,app.MethodDropDown_2.Items);
            app.MethodDropDownValueChanged;
        end
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            OldAnalysisParameters.General.FilterActive = app.CheckBox.Value;
            OldAnalysisParameters.General.FilterCutOff = app.EditField.Value;
            OldAnalysisParameters.General.InputData = str2func(app.MethodDropDown.Value);
            OldAnalysisParameters.General.InputDataOptions = app.MethodDropDown_2.Value;
            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Human_model, Markers_set, OldAnalysisParameters)
            app.CallingApp = mainapp;
            app.Human_model = Human_model;
            app.Markers_set = Markers_set;
            % Liste des input data disponibles
            path_models = fileparts(which('Get_real_markers'));
            list_files = dir([path_models '/InputDataImport/*.m']); list_files = cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false);
            app.MethodDropDown.Items = list_files; app.MethodDropDown.ItemsData = list_files;
            list_files_vsk=dir('*.vsk');
            app.MethodDropDown_2.Items = {list_files_vsk.name};            
            % Actualisation des paramètres
            ActuateApp(app,OldAnalysisParameters);
            app.AnalysisParameters = OldAnalysisParameters;
        end

        % Close request function: GeneraloptionsUIFigure
        function GeneraloptionsUIFigureCloseRequest(app, event)
            ParametersButton1(app.CallingApp);
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveParameters(app.CallingApp,app.AnalysisParameters,app.Human_model,app.Markers_set);
            app.GeneraloptionsUIFigureCloseRequest;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if app.CheckBox.Value
                app.CutofffrenquencyHzLabel.Visible = 'on';
                app.EditField.Visible = 'on';
            else
                app.CutofffrenquencyHzLabel.Visible = 'off';
                app.EditField.Visible = 'off';
            end           
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            if strcmp(app.MethodDropDown.Value,'C3dRawData')
                app.VSKfileLabel.Visible = 'on';
                app.MethodDropDown_2.Visible = 'on';
            else
                app.VSKfileLabel.Visible = 'off';
                app.MethodDropDown_2.Visible = 'off';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GeneraloptionsUIFigure = uifigure;
            app.GeneraloptionsUIFigure.Name = 'General options';
            app.GeneraloptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @GeneraloptionsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.GeneraloptionsUIFigure);
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CheckBox = uicheckbox(app.GeneraloptionsUIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.MarkerspositionfilteringLabel = uilabel(app.GeneraloptionsUIFigure);
            app.MarkerspositionfilteringLabel.Text = 'Markers position filtering';
            app.EditField = uieditfield(app.GeneraloptionsUIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.Value = 10;
            app.CutofffrenquencyHzLabel = uilabel(app.GeneraloptionsUIFigure);
            app.CutofffrenquencyHzLabel.Text = 'Cut-off frenquency (Hz)';
            app.MethodDropDown = uidropdown(app.GeneraloptionsUIFigure);
            app.MethodDropDown.Items = {'Options 1', 'Options 2'};
            app.MethodDropDown.ItemsData = '123';
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = '1';
            app.TypeofinputdataLabel = uilabel(app.GeneraloptionsUIFigure);
            app.TypeofinputdataLabel.Text = 'Type of input data';
            app.MethodDropDown_2 = uidropdown(app.GeneraloptionsUIFigure);
            app.MethodDropDown_2.Items = {'Options 1', 'Options 2'};
            app.MethodDropDown_2.ItemsData = '123';
            app.MethodDropDown_2.Value = '1';
            app.VSKfileLabel = uilabel(app.GeneraloptionsUIFigure);
            app.VSKfileLabel.Text = 'VSK file';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OptionsGeneral(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GeneraloptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GeneraloptionsUIFigure)
        end
    end
end