classdef OptionsID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InversedynamicsoptionsUIFigure  matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        ActivestepCheckBox              matlab.ui.control.CheckBox
        OKButton                        matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp
        Human_model % Description
        Markers_set
        AnalysisParameters
    end

    methods (Access = private)
    
        function ActuateApp(app,OldAnalysisParameters)
            % Active
            app.ActivestepCheckBox.Value = OldAnalysisParameters.ID.Active;
            app.ActivestepCheckBoxValueChanged;
        end
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            % Active
            OldAnalysisParameters.ID.Active = app.ActivestepCheckBox.Value;
            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Human_model, Markers_set, OldAnalysisParameters)
            app.CallingApp = mainapp;
            app.Human_model = Human_model;
            app.Markers_set = Markers_set;
            % Actualisation des paramètres
            ActuateApp(app,OldAnalysisParameters);
            app.AnalysisParameters = OldAnalysisParameters;
        end

        % Close request function: InversedynamicsoptionsUIFigure
        function InversedynamicsoptionsUIFigureCloseRequest(app, event)
            EnableButton2(app.CallingApp);
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveParameters(app.CallingApp,app.AnalysisParameters,app.Human_model,app.Markers_set);
            app.InversedynamicsoptionsUIFigureCloseRequest;
        end

        % Value changed function: ActivestepCheckBox
        function ActivestepCheckBoxValueChanged(app, event)
          if app.ActivestepCheckBox.Value
               % actif
           else
               % inactif
           end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InversedynamicsoptionsUIFigure = uifigure;
            app.InversedynamicsoptionsUIFigure.Name = 'Inverse dynamics options';
            app.InversedynamicsoptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @InversedynamicsoptionsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.InversedynamicsoptionsUIFigure);
            app.ActivestepCheckBox = uicheckbox(app.Panel);
            app.ActivestepCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivestepCheckBoxValueChanged, true);
            app.ActivestepCheckBox.Text = '   Active step';
            app.ActivestepCheckBox.Value = true;
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OptionsID(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InversedynamicsoptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InversedynamicsoptionsUIFigure)
        end
    end
end