classdef OptionsIK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InversekinematicsoptionsUIFigure  matlab.ui.Figure
        MarkerLabel_56                  matlab.ui.control.Label
        MarkerLabel_55                  matlab.ui.control.Label
        MarkerLabel_54                  matlab.ui.control.Label
        MarkerEditField_56              matlab.ui.control.NumericEditField
        MarkerEditField_55              matlab.ui.control.NumericEditField
        MarkerEditField_54              matlab.ui.control.NumericEditField
        MarkerLabel_53                  matlab.ui.control.Label
        MarkerLabel_52                  matlab.ui.control.Label
        MarkerLabel_51                  matlab.ui.control.Label
        MarkerLabel_50                  matlab.ui.control.Label
        MarkerLabel_49                  matlab.ui.control.Label
        MarkerLabel_48                  matlab.ui.control.Label
        MarkerLabel_47                  matlab.ui.control.Label
        MarkerLabel_46                  matlab.ui.control.Label
        MarkerLabel_45                  matlab.ui.control.Label
        MarkerLabel_44                  matlab.ui.control.Label
        MarkerLabel_43                  matlab.ui.control.Label
        MarkerEditField_53              matlab.ui.control.NumericEditField
        MarkerEditField_52              matlab.ui.control.NumericEditField
        MarkerEditField_51              matlab.ui.control.NumericEditField
        MarkerEditField_50              matlab.ui.control.NumericEditField
        MarkerEditField_49              matlab.ui.control.NumericEditField
        MarkerEditField_48              matlab.ui.control.NumericEditField
        MarkerEditField_47              matlab.ui.control.NumericEditField
        MarkerEditField_46              matlab.ui.control.NumericEditField
        MarkerEditField_45              matlab.ui.control.NumericEditField
        MarkerEditField_44              matlab.ui.control.NumericEditField
        MarkerEditField_43              matlab.ui.control.NumericEditField
        MarkerLabel_42                  matlab.ui.control.Label
        MarkerLabel_41                  matlab.ui.control.Label
        MarkerLabel_40                  matlab.ui.control.Label
        MarkerEditField_42              matlab.ui.control.NumericEditField
        MarkerEditField_41              matlab.ui.control.NumericEditField
        MarkerEditField_40              matlab.ui.control.NumericEditField
        MarkerLabel_39                  matlab.ui.control.Label
        MarkerLabel_38                  matlab.ui.control.Label
        MarkerLabel_37                  matlab.ui.control.Label
        MarkerLabel_36                  matlab.ui.control.Label
        MarkerLabel_35                  matlab.ui.control.Label
        MarkerLabel_34                  matlab.ui.control.Label
        MarkerLabel_33                  matlab.ui.control.Label
        MarkerLabel_32                  matlab.ui.control.Label
        MarkerLabel_31                  matlab.ui.control.Label
        MarkerLabel_30                  matlab.ui.control.Label
        MarkerLabel_29                  matlab.ui.control.Label
        MarkerEditField_39              matlab.ui.control.NumericEditField
        MarkerEditField_38              matlab.ui.control.NumericEditField
        MarkerEditField_37              matlab.ui.control.NumericEditField
        MarkerEditField_36              matlab.ui.control.NumericEditField
        MarkerEditField_35              matlab.ui.control.NumericEditField
        MarkerEditField_34              matlab.ui.control.NumericEditField
        MarkerEditField_33              matlab.ui.control.NumericEditField
        MarkerEditField_32              matlab.ui.control.NumericEditField
        MarkerEditField_31              matlab.ui.control.NumericEditField
        MarkerEditField_30              matlab.ui.control.NumericEditField
        MarkerEditField_29              matlab.ui.control.NumericEditField
        MarkerLabel_28                  matlab.ui.control.Label
        MarkerLabel_27                  matlab.ui.control.Label
        MarkerLabel_26                  matlab.ui.control.Label
        MarkerEditField_28              matlab.ui.control.NumericEditField
        MarkerEditField_27              matlab.ui.control.NumericEditField
        MarkerEditField_26              matlab.ui.control.NumericEditField
        MarkerLabel_25                  matlab.ui.control.Label
        MarkerLabel_24                  matlab.ui.control.Label
        MarkerLabel_23                  matlab.ui.control.Label
        MarkerLabel_22                  matlab.ui.control.Label
        MarkerLabel_21                  matlab.ui.control.Label
        MarkerLabel_20                  matlab.ui.control.Label
        MarkerLabel_19                  matlab.ui.control.Label
        MarkerLabel_18                  matlab.ui.control.Label
        MarkerLabel_17                  matlab.ui.control.Label
        MarkerLabel_16                  matlab.ui.control.Label
        MarkerLabel_15                  matlab.ui.control.Label
        MarkerEditField_25              matlab.ui.control.NumericEditField
        MarkerEditField_24              matlab.ui.control.NumericEditField
        MarkerEditField_23              matlab.ui.control.NumericEditField
        MarkerEditField_22              matlab.ui.control.NumericEditField
        MarkerEditField_21              matlab.ui.control.NumericEditField
        MarkerEditField_20              matlab.ui.control.NumericEditField
        MarkerEditField_19              matlab.ui.control.NumericEditField
        MarkerEditField_18              matlab.ui.control.NumericEditField
        MarkerEditField_17              matlab.ui.control.NumericEditField
        MarkerEditField_16              matlab.ui.control.NumericEditField
        MarkerEditField_15              matlab.ui.control.NumericEditField
        MarkerLabel_14                  matlab.ui.control.Label
        MarkerLabel_13                  matlab.ui.control.Label
        MarkerLabel_12                  matlab.ui.control.Label
        MarkerEditField_14              matlab.ui.control.NumericEditField
        MarkerEditField_13              matlab.ui.control.NumericEditField
        MarkerEditField_12              matlab.ui.control.NumericEditField
        MarkerLabel_11                  matlab.ui.control.Label
        MarkerLabel_10                  matlab.ui.control.Label
        MarkerLabel_9                   matlab.ui.control.Label
        MarkerLabel_8                   matlab.ui.control.Label
        MarkerLabel_7                   matlab.ui.control.Label
        MarkerLabel_6                   matlab.ui.control.Label
        MarkerLabel_5                   matlab.ui.control.Label
        MarkerLabel_4                   matlab.ui.control.Label
        MarkerLabel_3                   matlab.ui.control.Label
        MarkerLabel_2                   matlab.ui.control.Label
        MarkerLabel_1                   matlab.ui.control.Label
        MarkerEditField_11              matlab.ui.control.NumericEditField
        MarkerEditField_10              matlab.ui.control.NumericEditField
        MarkerEditField_9               matlab.ui.control.NumericEditField
        MarkerEditField_8               matlab.ui.control.NumericEditField
        MarkerEditField_7               matlab.ui.control.NumericEditField
        MarkerEditField_6               matlab.ui.control.NumericEditField
        MarkerEditField_5               matlab.ui.control.NumericEditField
        MarkerEditField_4               matlab.ui.control.NumericEditField
        MarkerEditField_3               matlab.ui.control.NumericEditField
        MarkerEditField_2               matlab.ui.control.NumericEditField
        MarkerEditField_1               matlab.ui.control.NumericEditField
        MarkerweigthsLabel              matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        CutofffrenquencyHzLabel         matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        JointcoordinatesfilteringLabel  matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        MethodLabel                     matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        OKButton                        matlab.ui.control.Button
        ActivestepCheckBox              matlab.ui.control.CheckBox
    end


    properties (Access = private)
        CallingApp
        Human_model % Description
        Markers_set
        AnalysisParameters
    end

    methods (Access = private)
    
        function ActuateApp(app,OldAnalysisParameters)
            % Active
            app.ActivestepCheckBox.Value = OldAnalysisParameters.IK.Active;
            % Method
            app.MethodDropDown.Value = num2str(OldAnalysisParameters.IK.Method);
            % Filtre
            app.CheckBox.Value = OldAnalysisParameters.IK.FilterActive;
            app.EditField.Value = OldAnalysisParameters.IK.FilterCutOff;
            app.CheckBoxValueChanged
            % Active
            app.ActivestepCheckBoxValueChanged;
            
            % Weights
            cpt_p = 0;
            if isfield(OldAnalysisParameters.IK,'weights')
                for i=1:numel(app.Markers_set)
                    if app.Markers_set(i).exist && cpt_p < length(OldAnalysisParameters.IK.weights)
                        cpt_p = cpt_p + 1;
                        eval(['app.MarkerEditField_' num2str(cpt_p) '.Value = OldAnalysisParameters.IK.weights(' num2str(cpt_p) ');']);
                    end
                end
            end
            
        end
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            % Active
            OldAnalysisParameters.IK.Active = app.ActivestepCheckBox.Value;
            % Frames
            OldAnalysisParameters.IK.Method = str2double(app.MethodDropDown.Value);
            % Filtre
            OldAnalysisParameters.IK.FilterActive = app.CheckBox.Value;
            OldAnalysisParameters.IK.FilterCutOff = app.EditField.Value;
            % Weights
            cpt_p= 0;
            for i=1:numel(app.Markers_set)
                if app.Markers_set(i).exist
                    cpt_p = cpt_p + 1;
                    eval(['OldAnalysisParameters.IK.weights(' num2str(cpt_p) ')= app.MarkerEditField_' num2str(cpt_p) '.Value;']);
                end
            end
            
            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Human_model, Markers_set, OldAnalysisParameters)
            app.CallingApp = mainapp;
            app.Human_model = Human_model;
            app.Markers_set = Markers_set;
            app.MethodDropDown.ItemsData = {'1' '2'};
            app.MethodDropDown.Items = {'Optimization','Levenberg-Marquardt'};
            
            cpt_p = 0;
            for i=1:numel(app.Markers_set)
                if app.Markers_set(i).exist
                    cpt_p = cpt_p + 1;
                    eval(['app.MarkerLabel_' num2str(cpt_p) '.Text = app.Markers_set(i).name;'])
                    eval(['app.MarkerEditField_' num2str(cpt_p) '.Visible = ''on'';'])
                end
            end
            for i=(cpt_p+1):56
                eval(['app.MarkerLabel_' num2str(i) '.Visible = ''off'';'])
                eval(['app.MarkerEditField_' num2str(i) '.Visible = ''off'';'])
            end
            
            % Actualisation des paramètres
            ActuateApp(app,OldAnalysisParameters);
            app.AnalysisParameters = OldAnalysisParameters;
        end

        % Close request function: InversekinematicsoptionsUIFigure
        function InversekinematicsoptionsUIFigureCloseRequest(app, event)
            EnableButton1(app.CallingApp);
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveParameters(app.CallingApp,app.AnalysisParameters,app.Human_model,app.Markers_set);
            app.InversekinematicsoptionsUIFigureCloseRequest;
        end

        % Value changed function: ActivestepCheckBox
        function ActivestepCheckBoxValueChanged(app, event)
          if app.ActivestepCheckBox.Value
               app.MethodDropDown.Visible = 'on';
               app.MethodLabel.Text = 'Method';
               app.CheckBox.Visible = 'on';
               app.JointcoordinatesfilteringLabel.Text = 'Joint coordinates filtering';
               app.CutofffrenquencyHzLabel.Text = 'Cut-off frequency (Hz)';
               app.CheckBoxValueChanged;
               app.EditField.Visible = 'on';
               app.CheckBox.Value= false;
               
               app.MarkerweigthsLabel.Visible= 'on';
               cpt_p = 0;
                for i=1:numel(app.Markers_set)
                    if app.Markers_set(i).exist
                        cpt_p = cpt_p + 1;
                        eval(['app.MarkerLabel_' num2str(cpt_p) '.Visible = ''on'';'])
                        eval(['app.MarkerEditField_' num2str(cpt_p) '.Visible = ''on'';'])
                    end
                end
               
           else
               app.MethodDropDown.Visible = 'off';
               app.MethodLabel.Text = '';
               app.CheckBox.Visible = 'off';
               app.JointcoordinatesfilteringLabel.Text = '';
               app.CutofffrenquencyHzLabel.Text = '';
               app.EditField.Visible = 'off';
               
               app.MarkerweigthsLabel.Visible= 'off';
               cpt_p = 0;
                for i=1:numel(app.Markers_set)
                    if app.Markers_set(i).exist
                        cpt_p = cpt_p + 1;
                        eval(['app.MarkerLabel_' num2str(cpt_p) '.Visible = ''off'';'])
                        eval(['app.MarkerEditField_' num2str(cpt_p) '.Visible = ''off'';'])
                    end
                end
           end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            if app.CheckBox.Value
                app.CutofffrenquencyHzLabel.Visible = 'on';
                app.EditField.Visible = 'on';
            else
                app.CutofffrenquencyHzLabel.Visible = 'off';
                app.EditField.Visible = 'off';
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InversekinematicsoptionsUIFigure = uifigure('Visible', 'off');
            app.InversekinematicsoptionsUIFigure.Name = 'Inverse kinematics options';
            app.InversekinematicsoptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @InversekinematicsoptionsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.InversekinematicsoptionsUIFigure);
            app.ActivestepCheckBox = uicheckbox(app.Panel);
            app.ActivestepCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivestepCheckBoxValueChanged, true);
            app.ActivestepCheckBox.Text = '   Active step';
            app.ActivestepCheckBox.Value = true;
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.MethodLabel = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MethodLabel.Text = 'Method';
            app.CheckBox = uicheckbox(app.InversekinematicsoptionsUIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.JointcoordinatesfilteringLabel = uilabel(app.InversekinematicsoptionsUIFigure);
            app.JointcoordinatesfilteringLabel.Text = 'Joint coordinates filtering';
            app.EditField = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.Value = 5;
            app.CutofffrenquencyHzLabel = uilabel(app.InversekinematicsoptionsUIFigure);
            app.CutofffrenquencyHzLabel.Text = 'Cut-off frenquency (Hz)';
            app.MethodDropDown = uidropdown(app.InversekinematicsoptionsUIFigure);
            app.MethodDropDown.Items = {'Optimization', 'Levenberg-Marquardt'};
            app.MethodDropDown.Value = 'Levenberg-Marquardt';
            app.MarkerweigthsLabel = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerweigthsLabel.Text = 'Marker weigths';
            app.MarkerEditField_1 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_1.Value = 1;
            app.MarkerEditField_2 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_2.Value = 1;
            app.MarkerEditField_3 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_3.Value = 1;
            app.MarkerEditField_4 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_4.Value = 1;
            app.MarkerEditField_5 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_5.Value = 1;
            app.MarkerEditField_6 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_6.Value = 1;
            app.MarkerEditField_7 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_7.Value = 1;
            app.MarkerEditField_8 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_8.Value = 1;
            app.MarkerEditField_9 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_9.Value = 1;
            app.MarkerEditField_10 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_10.Value = 1;
            app.MarkerEditField_11 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_11.Value = 1;
            app.MarkerLabel_1 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_1.Text = 'Marker';
            app.MarkerLabel_2 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_2.Text = 'Marker';
            app.MarkerLabel_3 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_3.Text = 'Marker';
            app.MarkerLabel_4 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_4.Text = 'Marker';
            app.MarkerLabel_5 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_5.Text = 'Marker';
            app.MarkerLabel_6 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_6.Text = 'Marker';
            app.MarkerLabel_7 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_7.Text = 'Marker';
            app.MarkerLabel_8 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_8.Text = 'Marker';
            app.MarkerLabel_9 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_9.Text = 'Marker';
            app.MarkerLabel_10 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_10.Text = 'Marker';
            app.MarkerLabel_11 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_11.Text = 'Marker';
            app.MarkerEditField_12 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_12.Value = 1;
            app.MarkerEditField_13 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_13.Value = 1;
            app.MarkerEditField_14 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_14.Value = 1;
            app.MarkerLabel_12 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_12.Text = 'Marker';
            app.MarkerLabel_13 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_13.Text = 'Marker';
            app.MarkerLabel_14 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_14.Text = 'Marker';
            app.MarkerEditField_15 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_15.Value = 1;
            app.MarkerEditField_16 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_16.Value = 1;
            app.MarkerEditField_17 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_17.Value = 1;
            app.MarkerEditField_18 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_18.Value = 1;
            app.MarkerEditField_19 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_19.Value = 1;
            app.MarkerEditField_20 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_20.Value = 1;
            app.MarkerEditField_21 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_21.Value = 1;
            app.MarkerEditField_22 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_22.Value = 1;
            app.MarkerEditField_23 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_23.Value = 1;
            app.MarkerEditField_24 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_24.Value = 1;
            app.MarkerEditField_25 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_25.Value = 1;
            app.MarkerLabel_15 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_15.Text = 'Marker';
            app.MarkerLabel_16 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_16.Text = 'Marker';
            app.MarkerLabel_17 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_17.Text = 'Marker';
            app.MarkerLabel_18 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_18.Text = 'Marker';
            app.MarkerLabel_19 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_19.Text = 'Marker';
            app.MarkerLabel_20 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_20.Text = 'Marker';
            app.MarkerLabel_21 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_21.Text = 'Marker';
            app.MarkerLabel_22 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_22.Text = 'Marker';
            app.MarkerLabel_23 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_23.Text = 'Marker';
            app.MarkerLabel_24 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_24.Text = 'Marker';
            app.MarkerLabel_25 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_25.Text = 'Marker';
            app.MarkerEditField_26 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_26.Value = 1;
            app.MarkerEditField_27 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_27.Value = 1;
            app.MarkerEditField_28 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_28.Value = 1;
            app.MarkerLabel_26 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_26.Text = 'Marker';
            app.MarkerLabel_27 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_27.Text = 'Marker';
            app.MarkerLabel_28 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_28.Text = 'Marker';
            app.MarkerEditField_29 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_29.Value = 1;
            app.MarkerEditField_30 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_30.Value = 1;
            app.MarkerEditField_31 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_31.Value = 1;
            app.MarkerEditField_32 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_32.Value = 1;
            app.MarkerEditField_33 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_33.Value = 1;
            app.MarkerEditField_34 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_34.Value = 1;
            app.MarkerEditField_35 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_35.Value = 1;
            app.MarkerEditField_36 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_36.Value = 1;
            app.MarkerEditField_37 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_37.Value = 1;
            app.MarkerEditField_38 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_38.Value = 1;
            app.MarkerEditField_39 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_39.Value = 1;
            app.MarkerLabel_29 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_29.Text = 'Marker';
            app.MarkerLabel_30 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_30.Text = 'Marker';
            app.MarkerLabel_31 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_31.Text = 'Marker';
            app.MarkerLabel_32 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_32.Text = 'Marker';
            app.MarkerLabel_33 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_33.Text = 'Marker';
            app.MarkerLabel_34 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_34.Text = 'Marker';
            app.MarkerLabel_35 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_35.Text = 'Marker';
            app.MarkerLabel_36 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_36.Text = 'Marker';
            app.MarkerLabel_37 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_37.Text = 'Marker';
            app.MarkerLabel_38 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_38.Text = 'Marker';
            app.MarkerLabel_39 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_39.Text = 'Marker';
            app.MarkerEditField_40 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_40.Value = 1;
            app.MarkerEditField_41 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_41.Value = 1;
            app.MarkerEditField_42 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_42.Value = 1;
            app.MarkerLabel_40 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_40.Text = 'Marker';
            app.MarkerLabel_41 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_41.Text = 'Marker';
            app.MarkerLabel_42 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_42.Text = 'Marker';
            app.MarkerEditField_43 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_43.Value = 1;
            app.MarkerEditField_44 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_44.Value = 1;
            app.MarkerEditField_45 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_45.Value = 1;
            app.MarkerEditField_46 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_46.Value = 1;
            app.MarkerEditField_47 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_47.Value = 1;
            app.MarkerEditField_48 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_48.Value = 1;
            app.MarkerEditField_49 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_49.Value = 1;
            app.MarkerEditField_50 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_50.Value = 1;
            app.MarkerEditField_51 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_51.Value = 1;
            app.MarkerEditField_52 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_52.Value = 1;
            app.MarkerEditField_53 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_53.Value = 1;
            app.MarkerLabel_43 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_43.Text = 'Marker';
            app.MarkerLabel_44 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_44.Text = 'Marker';
            app.MarkerLabel_45 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_45.Text = 'Marker';
            app.MarkerLabel_46 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_46.Text = 'Marker';
            app.MarkerLabel_47 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_47.Text = 'Marker';
            app.MarkerLabel_48 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_48.Text = 'Marker';
            app.MarkerLabel_49 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_49.Text = 'Marker';
            app.MarkerLabel_50 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_50.Text = 'Marker';
            app.MarkerLabel_51 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_51.Text = 'Marker';
            app.MarkerLabel_52 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_52.Text = 'Marker';
            app.MarkerLabel_53 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_53.Text = 'Marker';
            app.MarkerEditField_54 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_54.Value = 1;
            app.MarkerEditField_55 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_55.Value = 1;
            app.MarkerEditField_56 = uieditfield(app.InversekinematicsoptionsUIFigure, 'numeric');
            app.MarkerEditField_56.Value = 1;
            app.MarkerLabel_54 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_54.Text = 'Marker';
            app.MarkerLabel_55 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_55.Text = 'Marker';
            app.MarkerLabel_56 = uilabel(app.InversekinematicsoptionsUIFigure);
            app.MarkerLabel_56.Text = 'Marker';
            % Show the figure after all components are created
            app.InversekinematicsoptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptionsIK(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InversekinematicsoptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InversekinematicsoptionsUIFigure)
        end
    end
end