classdef OptionsMuscles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MuscleforcesestimationoptionsUIFigure  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        ActivestepCheckBox        matlab.ui.control.CheckBox
        OKButton                  matlab.ui.control.Button
        MethodDropDown            matlab.ui.control.DropDown
        MethodLabel               matlab.ui.control.Label
        CostfunctionLabel         matlab.ui.control.Label
        MethodDropDown_2          matlab.ui.control.DropDown
        DatabasedensityLabel      matlab.ui.control.Label
        EditField                 matlab.ui.control.NumericEditField
        EditField_2               matlab.ui.control.NumericEditField
        k1Label                   matlab.ui.control.Label
        k2Label                   matlab.ui.control.Label
        OrderLabel                matlab.ui.control.Label
        EditField_3               matlab.ui.control.NumericEditField
        MuscleactivationDropDown  matlab.ui.control.DropDown
        MusclemodelLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp
        Human_model % Description
        Markers_set
        AnalysisParameters
    end

    methods (Access = private)
    
        function ActuateApp(app,OldAnalysisParameters)
            % Active
            app.ActivestepCheckBox.Value = OldAnalysisParameters.Muscles.Active;
            % Method
            app.MethodDropDown.Value = num2str(OldAnalysisParameters.Muscles.Method);
            app.MethodDropDown_2.Value = func2str(OldAnalysisParameters.Muscles.Costfunction);
            app.MethodDropDownValueChanged;
            app.MethodDropDown_2ValueChanged;
            app.EditField_3.Value = OldAnalysisParameters.Muscles.CostfunctionOptions;
            if isfield(OldAnalysisParameters.Muscles, 'MuscleModel')
                Musclemodel = func2str(OldAnalysisParameters.Muscles.MuscleModel);   
                app.MuscleactivationDropDown.Value = Musclemodel(1:end-11);
            end
            % Density
            app.EditField.Value = OldAnalysisParameters.Muscles.DatabaseDensity(1);
            app.EditField_2.Value = OldAnalysisParameters.Muscles.DatabaseDensity(2);
            % Active
            app.ActivestepCheckBoxValueChanged;
        end
        
        function NewAnalysisParameters = ActuateParameters(app,OldAnalysisParameters)
            % Active
            OldAnalysisParameters.Muscles.Active = app.ActivestepCheckBox.Value;
            % Method
            OldAnalysisParameters.Muscles.Method = str2double(app.MethodDropDown.Value);
            OldAnalysisParameters.Muscles.Costfunction = str2func(app.MethodDropDown_2.Value);
            OldAnalysisParameters.Muscles.CostfunctionOptions = app.EditField_3.Value;
            OldAnalysisParameters.Muscles.MuscleModel = str2func([app.MuscleactivationDropDown.Value 'MuscleModel']);
            % Density
            OldAnalysisParameters.Muscles.DatabaseDensity(1) = app.EditField.Value;
            OldAnalysisParameters.Muscles.DatabaseDensity(2) = app.EditField_2.Value;
            % Actuate
            NewAnalysisParameters = OldAnalysisParameters;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Human_model, Markers_set, OldAnalysisParameters)
            app.CallingApp = mainapp;
            app.Human_model = Human_model;
            app.Markers_set = Markers_set;
            app.MethodDropDown.ItemsData = {'1' '2'};
            % Liste des fonctions de cout
            path_models = fileparts(which('MuscleForcesComputation'));
            list_files = dir([path_models '/CostFunction/*.m']); list_files = cellfun(@(x) x(1:end-2),{list_files.name},'UniformOutput',false);
            app.MethodDropDown_2.Items = list_files; app.MethodDropDown_2.ItemsData = list_files;
            % Actualisation des paramètres
            ActuateApp(app,OldAnalysisParameters);
            app.AnalysisParameters = OldAnalysisParameters;
        end

        % Close request function: 
        % MuscleforcesestimationoptionsUIFigure
        function MuscleforcesestimationoptionsUIFigureCloseRequest(app, event)
            EnableButton3(app.CallingApp);
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.AnalysisParameters = ActuateParameters(app,app.AnalysisParameters);
            SaveParameters(app.CallingApp,app.AnalysisParameters,app.Human_model,app.Markers_set);
            app.MuscleforcesestimationoptionsUIFigureCloseRequest;
        end

        % Value changed function: ActivestepCheckBox
        function ActivestepCheckBoxValueChanged(app, event)
          if app.ActivestepCheckBox.Value
               app.MethodDropDown.Visible = 'on';
               app.MethodDropDown_2.Visible = 'on';
               app.MethodLabel.Visible = 'on';
               app.CostfunctionLabel.Visible = 'on';
               app.DatabasedensityLabel.Visible = 'on';
               app.k1Label.Visible = 'on';
               app.k2Label.Visible = 'on';
               app.EditField.Visible = 'on';
               app.EditField_2.Visible = 'on';
               app.OrderLabel.Visible = 'on';
               app.EditField_3.Visible = 'on';
               app.MethodDropDownValueChanged;
               app.MethodDropDown_2ValueChanged;
               app.MuscleactivationDropDown.Visible = 'on';
               app.MusclemodelLabel.Visible = 'on';
          else
               app.MethodDropDown.Visible = 'off';
               app.MethodDropDown_2.Visible = 'off';
               app.MethodLabel.Visible = 'off';
               app.CostfunctionLabel.Visible = 'off';
               app.DatabasedensityLabel.Visible = 'off';
               app.k1Label.Visible = 'off';
               app.k2Label.Visible = 'off';
               app.EditField.Visible = 'off';
               app.EditField_2.Visible = 'off';
               app.OrderLabel.Visible = 'off';
               app.EditField_3.Visible = 'off';
               app.MuscleactivationDropDown.Visible = 'off';
               app.MusclemodelLabel.Visible = 'off';
          end
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            if str2double(app.MethodDropDown.Value) == 2
                app.DatabasedensityLabel.Visible = 'on';
                app.EditField.Visible = 'on';
                app.k1Label.Visible = 'on';
                app.EditField_2.Visible = 'on';
                app.k2Label.Visible='on';
                app.MuscleactivationDropDown.Visible = 'off';
                app.MusclemodelLabel.Visible = 'off';
            else
                app.DatabasedensityLabel.Visible = 'off';
                app.EditField.Visible = 'off';
                app.k1Label.Visible = 'off';
                app.EditField_2.Visible = 'off';
                app.k2Label.Visible='off';
                app.MuscleactivationDropDown.Visible = 'on';
                app.MusclemodelLabel.Visible = 'on';
            end
        end

        % Value changed function: MethodDropDown_2
        function MethodDropDown_2ValueChanged(app, event)
            if strcmp(app.MethodDropDown_2.Value,'PolynomialFunction')
                app.OrderLabel.Visible = 'on';
                app.EditField_3.Visible = 'on';
            else
                app.OrderLabel.Visible = 'off';
                app.EditField_3.Visible = 'off';
            end            
        end

        % Value changed function: MuscleactivationDropDown
        function MuscleactivationDropDownValueChanged(app, event)
            value = app.MuscleactivationDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MuscleforcesestimationoptionsUIFigure = uifigure('Visible', 'off');
            app.MuscleforcesestimationoptionsUIFigure.Name = 'Muscle forces estimation options';
            app.MuscleforcesestimationoptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @MuscleforcesestimationoptionsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.MuscleforcesestimationoptionsUIFigure);
            app.ActivestepCheckBox = uicheckbox(app.Panel);
            app.ActivestepCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivestepCheckBoxValueChanged, true);
            app.ActivestepCheckBox.Text = '   Active step';
            app.ActivestepCheckBox.Value = true;
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.MethodDropDown = uidropdown(app.MuscleforcesestimationoptionsUIFigure);
            app.MethodDropDown.Items = {'Optimization problem', 'MusIC method'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Optimization problem';
            app.MethodLabel = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.MethodLabel.Text = 'Method';
            app.CostfunctionLabel = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.CostfunctionLabel.Text = 'Cost function';
            app.MethodDropDown_2 = uidropdown(app.MuscleforcesestimationoptionsUIFigure);
            app.MethodDropDown_2.Items = {'1', '2', '3'};
            app.MethodDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MethodDropDown_2ValueChanged, true);
            app.MethodDropDown_2.Value = '3';
            app.DatabasedensityLabel = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.DatabasedensityLabel.Text = 'Database density';
            app.EditField = uieditfield(app.MuscleforcesestimationoptionsUIFigure, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField_2 = uieditfield(app.MuscleforcesestimationoptionsUIFigure, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.k1Label = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.k1Label.Text = 'k1';
            app.k2Label = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.k2Label.Text = 'k2';
            app.OrderLabel = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.OrderLabel.Text = 'Order';
            app.EditField_3 = uieditfield(app.MuscleforcesestimationoptionsUIFigure, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.ValueDisplayFormat = '%.0f';
            app.MuscleactivationDropDown = uidropdown(app.MuscleforcesestimationoptionsUIFigure);
            app.MuscleactivationDropDown.Items = {'Simple', 'ForceLength', 'ForceLengthVelocity'};
            app.MuscleactivationDropDown.ValueChangedFcn = createCallbackFcn(app, @MuscleactivationDropDownValueChanged, true);
            app.MuscleactivationDropDown.Value = 'Simple';
            app.MusclemodelLabel = uilabel(app.MuscleforcesestimationoptionsUIFigure);
            app.MusclemodelLabel.Text = 'Muscle model';
            % Show the figure after all components are created
            app.MuscleforcesestimationoptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptionsMuscles(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MuscleforcesestimationoptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MuscleforcesestimationoptionsUIFigure)
        end
    end
end